classdef USS_aplikacia < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayoutMain                  matlab.ui.container.GridLayout
        RootTabGroup                    matlab.ui.container.TabGroup
        MenuMainTab                     matlab.ui.container.Tab
        GridLayoutMenu                  matlab.ui.container.GridLayout
        FiltreButton                    matlab.ui.control.Button
        ShannonButton                   matlab.ui.control.Button
        LinkoveKodyButton               matlab.ui.control.Button
        AdamPetrovi2023Label            matlab.ui.control.Label
        PRSButton                       matlab.ui.control.Button
        MenuNavodButton                 matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        imgMenuHTML9                    matlab.ui.control.HTML
        imgMenuHTML8                    matlab.ui.control.HTML
        imgMenuHTML7                    matlab.ui.control.HTML
        imgMenuHTML6                    matlab.ui.control.HTML
        imgMenuHTML5                    matlab.ui.control.HTML
        imgMenuHTML4                    matlab.ui.control.HTML
        imgMenuHTML3                    matlab.ui.control.HTML
        imgMenuHTML2                    matlab.ui.control.HTML
        imgMenuHTML1                    matlab.ui.control.HTML
        FourierovradButton              matlab.ui.control.Button
        FurTrans                        matlab.ui.control.Button
        DiskrtnaFourierovtransformciaButton  matlab.ui.control.Button
        KorelaciaAkonvoluciaButton      matlab.ui.control.Button
        MenuMatlabPrikazyButton         matlab.ui.control.Button
        MenuSignalyButton               matlab.ui.control.Button
        MenuNapis                       matlab.ui.control.Label
        MenuKomplexneButton             matlab.ui.control.Button
        StochastickeSignalyButton       matlab.ui.control.Button
        CreditsTab                      matlab.ui.container.Tab
        GridLayout34                    matlab.ui.container.GridLayout
        KonzultantprceLabel             matlab.ui.control.Label
        VedciprceLabel                  matlab.ui.control.Label
        AutorLabel                      matlab.ui.control.Label
        BakalrskaprcaLabel              matlab.ui.control.Label
        profIngMilanDadoPhDLabel        matlab.ui.control.Label
        docIngPatrikKamencayPhDLabel    matlab.ui.control.Label
        AdamPetroviLabel                matlab.ui.control.Label
        Image34                         matlab.ui.control.Image
        Image33                         matlab.ui.control.Image
        MenuNapis_2                     matlab.ui.control.Label
        NavodMainTab                    matlab.ui.container.Tab
        GridLayout29                    matlab.ui.container.GridLayout
        HTML2                           matlab.ui.control.HTML
        MATLABprikazyMainTab            matlab.ui.container.Tab
        GridLayout11                    matlab.ui.container.GridLayout
        ZakladneFunkcieHTML             matlab.ui.control.HTML
        KomplexneMainTab                matlab.ui.container.Tab
        GridKomCisla                    matlab.ui.container.GridLayout
        KomplexneTabGroup               matlab.ui.container.TabGroup
        KomplexneTeoriaTab              matlab.ui.container.Tab
        GridLayoutKomCislaTeoria        matlab.ui.container.GridLayout
        KomplexneTeoriaHMTL             matlab.ui.control.HTML
        TeoreticktestTab                matlab.ui.container.Tab
        GridLayout38                    matlab.ui.container.GridLayout
        KomplSubmitButton               matlab.ui.control.Button
        KomplRestartButton              matlab.ui.control.Button
        KomplexO4Panel                  matlab.ui.container.Panel
        GridLayout39_4                  matlab.ui.container.GridLayout
        sinleftvarphirightfracejvarphiejvarphi2Label_2  matlab.ui.control.Label
        sinleftvarphirightfracejvarphiejvarphi2jLabel_2  matlab.ui.control.Label
        sinleftvarphirightfracejvarphiejvarphi2jLabel  matlab.ui.control.Label
        sinleftvarphirightfracejvarphiejvarphi2Label  matlab.ui.control.Label
        GoniometrickfunkciasinleftvarphirightjerovnLabel  matlab.ui.control.Label
        DCheckBox_4                     matlab.ui.control.CheckBox
        CCheckBox_4                     matlab.ui.control.CheckBox
        BCheckBox_4                     matlab.ui.control.CheckBox
        ACheckBox_4                     matlab.ui.control.CheckBox
        KomplexO3Panel                  matlab.ui.container.Panel
        GridLayout39_3                  matlab.ui.container.GridLayout
        ejvarphisinleftvarphirightjcosleftvarphirightLabel  matlab.ui.control.Label
        ejvarphicosleftvarphirightjsinleftvarphirightLabel_2  matlab.ui.control.Label
        ejvarphicosleftvarphirightsinleftvarphirightLabel  matlab.ui.control.Label
        ejvarphicosleftvarphirightjsinleftvarphirightLabel  matlab.ui.control.Label
        AkjesprvnyzpisEulerovhovzahuLabel  matlab.ui.control.Label
        DCheckBox_3                     matlab.ui.control.CheckBox
        CCheckBox_3                     matlab.ui.control.CheckBox
        BCheckBox_3                     matlab.ui.control.CheckBox
        ACheckBox_3                     matlab.ui.control.CheckBox
        KomplexO2Panel                  matlab.ui.container.Panel
        GridLayout39_2                  matlab.ui.container.GridLayout
        DiskrtnytvarLabel_2             matlab.ui.control.Label
        AlgebricktvarLabel_3            matlab.ui.control.Label
        ExponencilnytvarLabel_3         matlab.ui.control.Label
        GoniometricktvarLabel_3         matlab.ui.control.Label
        Vakomtvarejezapsankomplexnslozsqrt2efracpi4Label  matlab.ui.control.Label
        DCheckBox_2                     matlab.ui.control.CheckBox
        CCheckBox_2                     matlab.ui.control.CheckBox
        BCheckBox_2                     matlab.ui.control.CheckBox
        ACheckBox_2                     matlab.ui.control.CheckBox
        KomplexO1Panel                  matlab.ui.container.Panel
        GridLayout39                    matlab.ui.container.GridLayout
        Label_16                        matlab.ui.control.Label
        jLabel_3                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Komplexnsloi2respj2jerovnLabel  matlab.ui.control.Label
        DCheckBox                       matlab.ui.control.CheckBox
        CCheckBox                       matlab.ui.control.CheckBox
        BCheckBox                       matlab.ui.control.CheckBox
        ACheckBox                       matlab.ui.control.CheckBox
        KomplexneVyresienePrikladyTab   matlab.ui.container.Tab
        GridLayoutKomCislaVysPriklad    matlab.ui.container.GridLayout
        KomplexneVyriesenePrikladyHTML  matlab.ui.control.HTML
        KomplexnePrikladyTab            matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        Hyperlink                       matlab.ui.control.Hyperlink
        Image26_7                       matlab.ui.control.Image
        KomplexnePrikladyButtonGroup1   matlab.ui.container.ButtonGroup
        Vypotajtezsqrt1sqrt3jLabel      matlab.ui.control.Label
        DButton_14                      matlab.ui.control.RadioButton
        CButton_8                       matlab.ui.control.RadioButton
        BButton_8                       matlab.ui.control.RadioButton
        Image2_32                       matlab.ui.control.Image
        Image2_31                       matlab.ui.control.Image
        Image2_30                       matlab.ui.control.Image
        Image2_29                       matlab.ui.control.Image
        AButton_8                       matlab.ui.control.RadioButton
        DefaultButton_8                 matlab.ui.control.RadioButton
        KomplexnePrikladyButtonGroup2   matlab.ui.container.ButtonGroup
        Naptekomplexnslozsqrt3jvgoniometrickomtvareLabel  matlab.ui.control.Label
        DButton_13                      matlab.ui.control.RadioButton
        CButton_7                       matlab.ui.control.RadioButton
        BButton_7                       matlab.ui.control.RadioButton
        Image2_28                       matlab.ui.control.Image
        Image2_27                       matlab.ui.control.Image
        Image2_26                       matlab.ui.control.Image
        Image2_25                       matlab.ui.control.Image
        AButton_7                       matlab.ui.control.RadioButton
        DefaultButton_7                 matlab.ui.control.RadioButton
        KomplexneExp2AlgEditField       matlab.ui.control.EditField
        vsledokEditField_2Label         matlab.ui.control.Label
        Image26_4                       matlab.ui.control.Image
        KomplexneMocninaEditField       matlab.ui.control.EditField
        vsledokEditFieldLabel           matlab.ui.control.Label
        Image26_3                       matlab.ui.control.Image
        KomplexneGon2AlgVysledok        matlab.ui.control.EditField
        vsledokLabel                    matlab.ui.control.Label
        Image26_2                       matlab.ui.control.Image
        KomplexneSucetEditField         matlab.ui.control.EditField
        setEditFieldLabel               matlab.ui.control.Label
        KomplexnePodielEditField        matlab.ui.control.EditField
        podielEditFieldLabel            matlab.ui.control.Label
        KomplexneRozdielEditField       matlab.ui.control.EditField
        rozdielEditFieldLabel           matlab.ui.control.Label
        KomplexneSucinEditField         matlab.ui.control.EditField
        suinEditFieldLabel              matlab.ui.control.Label
        PraktickprkladyTab              matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        SignalyDalsiePrikladyHyperlink_2  matlab.ui.control.Hyperlink
        Hyperlink2                      matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel  matlab.ui.control.Label
        KomplexneRestartPrikladyButton  matlab.ui.control.Button
        KomplexneShowAnswerButton       matlab.ui.control.Button
        KomplexneCodeAreaHTML           matlab.ui.control.HTML
        MATLABCODEEDITORLabel_13        matlab.ui.control.Label
        KomplexneRunCodeButton          matlab.ui.control.Button
        KomplexneKonzolaPanel           matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        KomplexneKonzolaTextArea        matlab.ui.control.TextArea
        KomplexnePraktickeUlohyHTML     matlab.ui.control.HTML
        KomplexneUIAxes                 matlab.ui.control.UIAxes
        SignalyMainTab                  matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        SignalyTabGroup                 matlab.ui.container.TabGroup
        SignalyTeoriaTab                matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        SignalyTeoriaHTML               matlab.ui.control.HTML
        SignalyInteraktivnaUkazkaTab    matlab.ui.container.Tab
        GridLayout10_2                  matlab.ui.container.GridLayout
        VlastnostFourierovejtransformcieLabel_2  matlab.ui.control.Label
        SignalyPeriodaSlider            matlab.ui.control.Slider
        Label_9                         matlab.ui.control.Label
        SignalyFazaSlider               matlab.ui.control.Slider
        FzaSliderLabel                  matlab.ui.control.Label
        SignalyAmplitudaSlider          matlab.ui.control.Slider
        AmplitdaSliderLabel             matlab.ui.control.Label
        SignalyInterktivnaUkazkaUIAxes  matlab.ui.control.UIAxes
        TeoreticktestTab_2              matlab.ui.container.Tab
        GridLayout38_2                  matlab.ui.container.GridLayout
        KomplSubmitButton_2             matlab.ui.control.Button
        KomplRestartButton_2            matlab.ui.control.Button
        KomplexO4Panel_2                matlab.ui.container.Panel
        GridLayout39_8                  matlab.ui.container.GridLayout
        vzorkovanieakvantovanieLabel_2  matlab.ui.control.Label
        diskrtnaFurierovtransformciaLabel_2  matlab.ui.control.Label
        FourierovradLabel               matlab.ui.control.Label
        neuritintegrlLabel_2            matlab.ui.control.Label
        PrevpoetspektradiskrtnehosignlusapouvaLabel  matlab.ui.control.Label
        DCheckBox_8                     matlab.ui.control.CheckBox
        CCheckBox_8                     matlab.ui.control.CheckBox
        BCheckBox_8                     matlab.ui.control.CheckBox
        ACheckBox_8                     matlab.ui.control.CheckBox
        KomplexO3Panel_2                matlab.ui.container.Panel
        GridLayout39_7                  matlab.ui.container.GridLayout
        harmonickLabel_8                matlab.ui.control.Label
        kontatnanekoneneirokLabel_8     matlab.ui.control.Label
        diskrtneLabel_9                 matlab.ui.control.Label
        stochastickLabel_2              matlab.ui.control.Label
        FrekvennspektrumDiracovhoimpulzujeLabel  matlab.ui.control.Label
        DCheckBox_7                     matlab.ui.control.CheckBox
        CCheckBox_7                     matlab.ui.control.CheckBox
        BCheckBox_7                     matlab.ui.control.CheckBox
        ACheckBox_7                     matlab.ui.control.CheckBox
        KomplexO2Panel_2                matlab.ui.container.Panel
        GridLayout39_6                  matlab.ui.container.GridLayout
        neharmonickLabel_3              matlab.ui.control.Label
        kontatnanekoneneirokLabel_9     matlab.ui.control.Label
        diskrtneLabel_10                matlab.ui.control.Label
        spojitLabel_8                   matlab.ui.control.Label
        FrekvennspektrumperiodickhosignlujeLabel  matlab.ui.control.Label
        DCheckBox_6                     matlab.ui.control.CheckBox
        CCheckBox_6                     matlab.ui.control.CheckBox
        BCheckBox_6                     matlab.ui.control.CheckBox
        ACheckBox_6                     matlab.ui.control.CheckBox
        KomplexO1Panel_2                matlab.ui.container.Panel
        GridLayout39_5                  matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_2  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_3  matlab.ui.control.Label
        preichopissavyuvasadatatistickchparametrovLabel  matlab.ui.control.Label
        svyjadrenkonenoupostupnosouDiracovchimpulzovLabel  matlab.ui.control.Label
        SignlyspojitvasespecificktmeLabel  matlab.ui.control.Label
        DCheckBox_5                     matlab.ui.control.CheckBox
        CCheckBox_5                     matlab.ui.control.CheckBox
        BCheckBox_5                     matlab.ui.control.CheckBox
        ACheckBox_5                     matlab.ui.control.CheckBox
        Ulohy1Tab                       matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        Hyperlink2_2                    matlab.ui.control.Hyperlink
        TextArea                        matlab.ui.control.TextArea
        Label_10                        matlab.ui.control.Label
        SignalyDalsiePrikladyHyperlink  matlab.ui.control.Hyperlink
        SignalyRestartButton            matlab.ui.control.Button
        SignalyShowanswersButton        matlab.ui.control.Button
        SignalyRunCodeU4Button          matlab.ui.control.Button
        SignalyRunCodeU3Button          matlab.ui.control.Button
        SignalyRunCodeU2Button          matlab.ui.control.Button
        SignalyCodeAreaU3HTML           matlab.ui.control.HTML
        SignalyCodeAreaU4HTML           matlab.ui.control.HTML
        SignalyZadanieU4HTML            matlab.ui.control.HTML
        SignalyZadanieU3HTML            matlab.ui.control.HTML
        SignalyCodeAreaU2HTML           matlab.ui.control.HTML
        SignalyZadanieU2HTML            matlab.ui.control.HTML
        SignalyRunCodeU1Button          matlab.ui.control.Button
        SignalyZadanieU1HTML            matlab.ui.control.HTML
        SignalyCodeAreaU1HTML           matlab.ui.control.HTML
        FurRadMainTab                   matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        FurRadTabGroup                  matlab.ui.container.TabGroup
        TeoriaTab                       matlab.ui.container.Tab
        GridLayout13                    matlab.ui.container.GridLayout
        FurRadTeoriaVideoHMTL           matlab.ui.control.HTML
        FurRadInteraktivnaUkazkaTab     matlab.ui.container.Tab
        GridLayout10_3                  matlab.ui.container.GridLayout
        FurRadInteraktivnaUkazkaSlider  matlab.ui.control.Slider
        CatrvaniaLabel_2                matlab.ui.control.Label
        FurRadInteraktivnaUkazkaSpektrumUIAxes  matlab.ui.control.UIAxes
        FurRadInteraktivnaUkazkaFurRadUIAxes  matlab.ui.control.UIAxes
        FurRadInteraktivnaUkazkaSamZlozkyUIAxes  matlab.ui.control.UIAxes
        TeoreticktestTab_3              matlab.ui.container.Tab
        GridLayout38_3                  matlab.ui.container.GridLayout
        KomplSubmitButton_3             matlab.ui.control.Button
        KomplRestartButton_3            matlab.ui.control.Button
        KomplexO4Panel_3                matlab.ui.container.Panel
        GridLayout39_12                 matlab.ui.container.GridLayout
        kosnusovlenyLabel_2             matlab.ui.control.Label
        snusovlenyakosnusovlenyLabel_2  matlab.ui.control.Label
        jednosmernzlokuLabel            matlab.ui.control.Label
        snusovlenyLabel_2               matlab.ui.control.Label
        FourierovradpreneprneperiodickfunkcieobsahujelenLabel  matlab.ui.control.Label
        DCheckBox_12                    matlab.ui.control.CheckBox
        CCheckBox_12                    matlab.ui.control.CheckBox
        BCheckBox_12                    matlab.ui.control.CheckBox
        ACheckBox_12                    matlab.ui.control.CheckBox
        KomplexO3Panel_3                matlab.ui.container.Panel
        GridLayout39_11                 matlab.ui.container.GridLayout
        frac1Tint_0TxlefttrightdtLabel  matlab.ui.control.Label
        frac1Tint_0TxlefttrightsinleftnomegatrightdtLabel  matlab.ui.control.Label
        frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel_2  matlab.ui.control.Label
        frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel  matlab.ui.control.Label
        SprvnyzpisvzorcaprevpoetjednosmernejzlokyjeLabel  matlab.ui.control.Label
        DCheckBox_11                    matlab.ui.control.CheckBox
        CCheckBox_11                    matlab.ui.control.CheckBox
        BCheckBox_11                    matlab.ui.control.CheckBox
        ACheckBox_11                    matlab.ui.control.CheckBox
        KomplexO2Panel_3                matlab.ui.container.Panel
        GridLayout39_10                 matlab.ui.container.GridLayout
        neharmonickLabel_4              matlab.ui.control.Label
        kontatnanekoneneirokLabel_10    matlab.ui.control.Label
        diskrtneLabel_11                matlab.ui.control.Label
        spojitLabel_9                   matlab.ui.control.Label
        FrekvennspektrumperiodickhosignlujeLabel_2  matlab.ui.control.Label
        DCheckBox_10                    matlab.ui.control.CheckBox
        CCheckBox_10                    matlab.ui.control.CheckBox
        BCheckBox_10                    matlab.ui.control.CheckBox
        ACheckBox_10                    matlab.ui.control.CheckBox
        KomplexO1Panel_3                matlab.ui.container.Panel
        GridLayout39_9                  matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_3  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_4  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_3  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_3  matlab.ui.control.Label
        AkjesprvnyzpistrigonometrickhoFourierovhoraduLabel  matlab.ui.control.Label
        DCheckBox_9                     matlab.ui.control.CheckBox
        CCheckBox_9                     matlab.ui.control.CheckBox
        BCheckBox_9                     matlab.ui.control.CheckBox
        ACheckBox_9                     matlab.ui.control.CheckBox
        RiesenyprikladTab               matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        CTRLSPACEukepremennafunkcieLabel_2  matlab.ui.control.Label
        FurRadRunCodeRiesenyPrikladButton  matlab.ui.control.Button
        MATLABCODEEDITORLabel_2         matlab.ui.control.Label
        FurRadRiesenyPrikladCodeAreaHTML  matlab.ui.control.HTML
        FurRadRiesenyPrikaldHTML        matlab.ui.control.HTML
        FurRadRiesenyPrikladUIAxes      matlab.ui.control.UIAxes
        PrkladTab                       matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        Hyperlink2_3                    matlab.ui.control.Hyperlink
        FurRadDalsiePrikladyHyperlink   matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_3  matlab.ui.control.Label
        FurRadRestartButton             matlab.ui.control.Button
        FurRadShowAnswerButton          matlab.ui.control.Button
        FurRadRunCodeUlohaButton        matlab.ui.control.Button
        MATLABCODEEDITORLabel_3         matlab.ui.control.Label
        FurRadUlohaCodeAreaHTML         matlab.ui.control.HTML
        FurRadPanelKonzola              matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        FurRadKonzolaTextArea           matlab.ui.control.TextArea
        FurRadAnswerLabel               matlab.ui.control.Label
        FurRadZadanieUlohyHTML          matlab.ui.control.HTML
        FurRadUlohaUIAxes               matlab.ui.control.UIAxes
        FurTransMainTab                 matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        FurTransTabGroup                matlab.ui.container.TabGroup
        FurTransTeoriaTab               matlab.ui.container.Tab
        GridLayout19                    matlab.ui.container.GridLayout
        FurTransTeoriaHTML              matlab.ui.control.HTML
        FurTransInteraktivnaUkazkaTab   matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        Label_13                        matlab.ui.control.Label
        TrojuholnkovimpulzButton        matlab.ui.control.Button
        GaussovimpulzButton             matlab.ui.control.Button
        FTinteraktivnaObldznikButton    matlab.ui.control.Button
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        VlastnostFourierovejtransformcieLabel  matlab.ui.control.Label
        FurTransInteraktivnaUkazkaLabel  matlab.ui.control.Label
        FurTransInteraktivnaUkazkaSlider  matlab.ui.control.Slider
        FurTransInteraktivnaUkazkaSignalUIAxes  matlab.ui.control.UIAxes
        FurTransInteraktivnaUkazkaSpektrumUIAxes  matlab.ui.control.UIAxes
        TeoreticktestTab_4              matlab.ui.container.Tab
        GridLayout38_4                  matlab.ui.container.GridLayout
        KomplSubmitButton_4             matlab.ui.control.Button
        KomplRestartButton_4            matlab.ui.control.Button
        KomplexO4Panel_4                matlab.ui.container.Panel
        GridLayout39_16                 matlab.ui.container.GridLayout
        spojitLabel_11                  matlab.ui.control.Label
        rovnakLabel_2                   matlab.ui.control.Label
        irieLabel_2                     matlab.ui.control.Label
        uieLabel_2                      matlab.ui.control.Label
        mjesignlimpulzirvasovejoblastitmjejehospektrumLabel  matlab.ui.control.Label
        DCheckBox_16                    matlab.ui.control.CheckBox
        CCheckBox_16                    matlab.ui.control.CheckBox
        BCheckBox_16                    matlab.ui.control.CheckBox
        ACheckBox_16                    matlab.ui.control.CheckBox
        KomplexO3Panel_4                matlab.ui.container.Panel
        GridLayout39_15                 matlab.ui.container.GridLayout
        frac2Tint_0TxlefttrightsinleftnomegatrightdtLabel  matlab.ui.control.Label
        frac12piint_inftyinftyFleftomegarightejomegatdomegaLabel  matlab.ui.control.Label
        int_inftyinftyflefttrightejomegatdtLabel  matlab.ui.control.Label
        int_inftyinftyfleftomegarightejomegatdtLabel  matlab.ui.control.Label
        AkjesprvnyzpisvzahupreinverznFourierovtransformciuLabel  matlab.ui.control.Label
        DCheckBox_15                    matlab.ui.control.CheckBox
        CCheckBox_15                    matlab.ui.control.CheckBox
        BCheckBox_15                    matlab.ui.control.CheckBox
        ACheckBox_15                    matlab.ui.control.CheckBox
        KomplexO2Panel_4                matlab.ui.container.Panel
        GridLayout39_14                 matlab.ui.container.GridLayout
        neharmonickLabel_5              matlab.ui.control.Label
        kontatnanekoneneirokLabel_11    matlab.ui.control.Label
        diskrtneLabel_12                matlab.ui.control.Label
        spojitLabel_10                  matlab.ui.control.Label
        FrekvennspektrumneperiodickchsignlovjeLabel  matlab.ui.control.Label
        DCheckBox_14                    matlab.ui.control.CheckBox
        CCheckBox_14                    matlab.ui.control.CheckBox
        BCheckBox_14                    matlab.ui.control.CheckBox
        ACheckBox_14                    matlab.ui.control.CheckBox
        KomplexO1Panel_4                matlab.ui.container.Panel
        GridLayout39_13                 matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_4  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_5  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_4  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_4  matlab.ui.control.Label
        AkjesprvnyzpisvzahuprepriamuFourierovtransformciuLabel  matlab.ui.control.Label
        DCheckBox_13                    matlab.ui.control.CheckBox
        CCheckBox_13                    matlab.ui.control.CheckBox
        BCheckBox_13                    matlab.ui.control.CheckBox
        ACheckBox_13                    matlab.ui.control.CheckBox
        RieenprkladTab                  matlab.ui.container.Tab
        GridLayout20                    matlab.ui.container.GridLayout
        CTRLSPACEukepremennafunkcieLabel_4  matlab.ui.control.Label
        FurTransRunCodeRiesenyPrikladButton  matlab.ui.control.Button
        MATLABCODEEDITORLabel_4         matlab.ui.control.Label
        FurTransRiesenyPrikladCodeAreaHTML  matlab.ui.control.HTML
        FurTransRiesenyPrikladHTML      matlab.ui.control.HTML
        PrkladTab_3                     matlab.ui.container.Tab
        GridLayout16_2                  matlab.ui.container.GridLayout
        Hyperlink2_4                    matlab.ui.control.Hyperlink
        FurTransDalsiePrikladyHyperlink  matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_5  matlab.ui.control.Label
        FurTransRestartButton           matlab.ui.control.Button
        FurTransShowAnswerButton        matlab.ui.control.Button
        FurTransRunCodeUlohaButton      matlab.ui.control.Button
        MATLABCODEEDITORLabel_6         matlab.ui.control.Label
        FurTransUlohaCodeAreaHTML       matlab.ui.control.HTML
        FurTransPanelKonzola            matlab.ui.container.Panel
        GridLayout17_2                  matlab.ui.container.GridLayout
        FurTransKonzolaTextArea         matlab.ui.control.TextArea
        FurTransAnswerLabel             matlab.ui.control.Label
        FurTrransZadanieUlohyHTML       matlab.ui.control.HTML
        DFTMainTab                      matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        DFTTabGroup                     matlab.ui.container.TabGroup
        DFTTeoriaTab                    matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        DFTTeoriaHTML                   matlab.ui.control.HTML
        DFTVideoUkazkaTab_2             matlab.ui.container.Tab
        GridLayout28_2                  matlab.ui.container.GridLayout
        HTML_2                          matlab.ui.control.HTML
        DFTVideoUkazkaTab               matlab.ui.container.Tab
        GridLayout28                    matlab.ui.container.GridLayout
        HTML                            matlab.ui.control.HTML
        InteraktivnaukkaTab             matlab.ui.container.Tab
        GridLayout35                    matlab.ui.container.GridLayout
        f_mathrmvz2cdotf_mLabel_3       matlab.ui.control.Label
        f_mathrmvz2cdotf_mLabel_2       matlab.ui.control.Label
        f_mathrmvz2cdotf_mLabel         matlab.ui.control.Label
        RunButton_3                     matlab.ui.control.Button
        RunButton_2                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        ShanonNyquistovpodmienkaLabel   matlab.ui.control.Label
        VzorkovanySignalUIAxes          matlab.ui.control.UIAxes
        RekonstukciaSignaluUIAxes       matlab.ui.control.UIAxes
        TeoreticktestTab_5              matlab.ui.container.Tab
        GridLayout38_5                  matlab.ui.container.GridLayout
        KomplSubmitButton_5             matlab.ui.control.Button
        KomplRestartButton_5            matlab.ui.control.Button
        KomplexO4Panel_5                matlab.ui.container.Panel
        GridLayout39_20                 matlab.ui.container.GridLayout
        kontatnLabel_2                  matlab.ui.control.Label
        frekvenneneohranienLabel_2      matlab.ui.control.Label
        diskrtneLabel_13                matlab.ui.control.Label
        spojitLabel_14                  matlab.ui.control.Label
        FrekvennspektrumdiskrtnychsignlovjeLabel  matlab.ui.control.Label
        DCheckBox_20                    matlab.ui.control.CheckBox
        CCheckBox_20                    matlab.ui.control.CheckBox
        BCheckBox_20                    matlab.ui.control.CheckBox
        ACheckBox_20                    matlab.ui.control.CheckBox
        KomplexO3Panel_5                matlab.ui.container.Panel
        GridLayout39_19                 matlab.ui.container.GridLayout
        sum_n0N1xleftnrightemathrmjknLabel  matlab.ui.control.Label
        frac1Nsum_n0N1XleftkrightejomegaNLabel  matlab.ui.control.Label
        frac1Nsum_n0N1Xleftkrightejfrac2piNnkLabel  matlab.ui.control.Label
        sum_n0N1xleftnrightejfrac2piNnkLabel  matlab.ui.control.Label
        AkjesprvnyzpisvzahupreinverznDFTLabel  matlab.ui.control.Label
        DCheckBox_19                    matlab.ui.control.CheckBox
        CCheckBox_19                    matlab.ui.control.CheckBox
        BCheckBox_19                    matlab.ui.control.CheckBox
        ACheckBox_19                    matlab.ui.control.CheckBox
        KomplexO2Panel_5                matlab.ui.container.Panel
        GridLayout39_18                 matlab.ui.container.GridLayout
        f_mathrmvzge2f_mLabel           matlab.ui.control.Label
        f_mathrmvzf_mLabel              matlab.ui.control.Label
        f_mathrmvzle2f_mLabel           matlab.ui.control.Label
        f_mathrmvz2f_mLabel             matlab.ui.control.Label
        NyquistovpodmienkaprivzorkovanhovoreLabel  matlab.ui.control.Label
        DCheckBox_18                    matlab.ui.control.CheckBox
        CCheckBox_18                    matlab.ui.control.CheckBox
        BCheckBox_18                    matlab.ui.control.CheckBox
        ACheckBox_18                    matlab.ui.control.CheckBox
        KomplexO1Panel_5                matlab.ui.container.Panel
        GridLayout39_17                 matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_5  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_6  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_5  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_5  matlab.ui.control.Label
        AkjesprvnyzpisvzahuprepriamuDFTLabel  matlab.ui.control.Label
        DCheckBox_17                    matlab.ui.control.CheckBox
        CCheckBox_17                    matlab.ui.control.CheckBox
        BCheckBox_17                    matlab.ui.control.CheckBox
        ACheckBox_17                    matlab.ui.control.CheckBox
        DFTRiesenyPrikladTab            matlab.ui.container.Tab
        GridLayout20_2                  matlab.ui.container.GridLayout
        CTRLSPACEukepremennafunkcieLabel_6  matlab.ui.control.Label
        DFTRiesenyPrikaldPanelKonzola   matlab.ui.container.Panel
        GridLayout17_3                  matlab.ui.container.GridLayout
        DFTRiesenyPrikladKonzolaTextArea  matlab.ui.control.TextArea
        DFTCodeRiesenyPrikladButton     matlab.ui.control.Button
        MATLABCODEEDITORLabel_7         matlab.ui.control.Label
        DFTRiesenyPrikladCodeAreaHTML   matlab.ui.control.HTML
        DFTRiesenyPrikladHTML           matlab.ui.control.HTML
        DFTPrikladTab                   matlab.ui.container.Tab
        GridLayout16_3                  matlab.ui.container.GridLayout
        Hyperlink2_5                    matlab.ui.control.Hyperlink
        DFTDalsiePrikladyHyperlink      matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_8  matlab.ui.control.Label
        DFTRestartButton                matlab.ui.control.Button
        DFTShowAnswerButton             matlab.ui.control.Button
        DFTRunCodeUlohaButton           matlab.ui.control.Button
        MATLABCODEEDITORLabel_8         matlab.ui.control.Label
        DFTUlohaCodeAreaHTML            matlab.ui.control.HTML
        DFTPanelKonzola                 matlab.ui.container.Panel
        GridLayout17_4                  matlab.ui.container.GridLayout
        DFTKonzolaTextArea              matlab.ui.control.TextArea
        DFTZadanieUlohyHTML             matlab.ui.control.HTML
        KonvKorMainTab                  matlab.ui.container.Tab
        GridLayout21_2                  matlab.ui.container.GridLayout
        KonvKorTabGroup                 matlab.ui.container.TabGroup
        KonvKorTeoriaTab                matlab.ui.container.Tab
        GridLayout22_2                  matlab.ui.container.GridLayout
        KonvKorTeoriaHTML               matlab.ui.control.HTML
        KonvKorVideoTab                 matlab.ui.container.Tab
        GridLayout23                    matlab.ui.container.GridLayout
        KonvKorVideo1HTML               matlab.ui.control.HTML
        KonvKorVideo2HTML               matlab.ui.control.HTML
        KonvKorVideoTab_2               matlab.ui.container.Tab
        GridLayout23_2                  matlab.ui.container.GridLayout
        KonvKorVideo1HTML_2             matlab.ui.control.HTML
        KonvKorVideo2HTML_2             matlab.ui.control.HTML
        KonvKorVideoTab_3               matlab.ui.container.Tab
        GridLayout23_3                  matlab.ui.container.GridLayout
        HTML8                           matlab.ui.control.HTML
        HTML7                           matlab.ui.control.HTML
        TeoreticktestTab_6              matlab.ui.container.Tab
        GridLayout38_6                  matlab.ui.container.GridLayout
        KomplSubmitButton_6             matlab.ui.control.Button
        KomplRestartButton_6            matlab.ui.control.Button
        KomplexO4Panel_6                matlab.ui.container.Panel
        GridLayout39_24                 matlab.ui.container.GridLayout
        int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel_2  matlab.ui.control.Label
        frekvenneneohranienLabel_3      matlab.ui.control.Label
        diskrtneLabel_14                matlab.ui.control.Label
        spojitLabel_15                  matlab.ui.control.Label
        AkjesprvnyzpisvzahuprediskrtnukrovkorelciuLabel  matlab.ui.control.Label
        DCheckBox_24                    matlab.ui.control.CheckBox
        CCheckBox_24                    matlab.ui.control.CheckBox
        BCheckBox_24                    matlab.ui.control.CheckBox
        ACheckBox_24                    matlab.ui.control.CheckBox
        KomplexO3Panel_6                matlab.ui.container.Panel
        GridLayout39_23                 matlab.ui.container.GridLayout
        D_yD_xD_hLabel_2                matlab.ui.control.Label
        D_yD_xD_h1Label_2               matlab.ui.control.Label
        D_yD_xD_hLabel                  matlab.ui.control.Label
        D_yD_xD_h1Label                 matlab.ui.control.Label
        VsledndkadiskrtnejkonvolciejedanvzahomLabel  matlab.ui.control.Label
        DCheckBox_23                    matlab.ui.control.CheckBox
        CCheckBox_23                    matlab.ui.control.CheckBox
        BCheckBox_23                    matlab.ui.control.CheckBox
        ACheckBox_23                    matlab.ui.control.CheckBox
        KomplexO2Panel_6                matlab.ui.container.Panel
        GridLayout39_22                 matlab.ui.container.GridLayout
        int_inftyinftyxlefttaurightcdotyleftttaurightdtauLabel  matlab.ui.control.Label
        int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel  matlab.ui.control.Label
        int_inftyinftyxlefttrightcdotyleftttaurightdtauLabel  matlab.ui.control.Label
        int_inftyinftyxlefttrightcdotxleftttaurightdtauLabel  matlab.ui.control.Label
        AkjesprvnyzpisvzahuprespojitautokorelanfunkciuLabel  matlab.ui.control.Label
        DCheckBox_22                    matlab.ui.control.CheckBox
        CCheckBox_22                    matlab.ui.control.CheckBox
        BCheckBox_22                    matlab.ui.control.CheckBox
        ACheckBox_22                    matlab.ui.control.CheckBox
        KomplexO1Panel_6                matlab.ui.container.Panel
        GridLayout39_21                 matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_6  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_7  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_6  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_6  matlab.ui.control.Label
        AkjesprvnyzpisvzahuprediskrtnukonvolciuLabel  matlab.ui.control.Label
        DCheckBox_21                    matlab.ui.control.CheckBox
        CCheckBox_21                    matlab.ui.control.CheckBox
        BCheckBox_21                    matlab.ui.control.CheckBox
        ACheckBox_21                    matlab.ui.control.CheckBox
        KonvKorRiesenyPrikladTab        matlab.ui.container.Tab
        GridLayout20_3                  matlab.ui.container.GridLayout
        CTRLSPACEukepremennafunkcieLabel_9  matlab.ui.control.Label
        KonvKorRiesenyPrikaldPanelKonzola  matlab.ui.container.Panel
        GridLayout17_5                  matlab.ui.container.GridLayout
        KonvKorRiesenyPrikladKonzolaTextArea  matlab.ui.control.TextArea
        KonvKorCodeRiesenyPrikladButton  matlab.ui.control.Button
        MATLABCODEEDITORLabel_9         matlab.ui.control.Label
        KonvKorRiesenyPrikladCodeAreaHTML  matlab.ui.control.HTML
        KonvKorRiesenyPrikladHTML       matlab.ui.control.HTML
        KonvKorPrikladKovnTab           matlab.ui.container.Tab
        GridLayout16_4                  matlab.ui.container.GridLayout
        Hyperlink2_6                    matlab.ui.control.Hyperlink
        KonvKorKonvDalsiePrikladyHyperlink  matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_10  matlab.ui.control.Label
        KonvKorRestartButtonKonv        matlab.ui.control.Button
        KonvKorShowAnswerButtonKonv     matlab.ui.control.Button
        KonvKorRunCodeUlohaButtonKonv   matlab.ui.control.Button
        MATLABCODEEDITORLabel_10        matlab.ui.control.Label
        KonvKorUlohaCodeAreaHTMLKonv    matlab.ui.control.HTML
        KonvKorPanelKonzolaKonv         matlab.ui.container.Panel
        GridLayout17_6                  matlab.ui.container.GridLayout
        KonvKorKonzolaTextAreaKonv      matlab.ui.control.TextArea
        KonvKorZadanieUlohyHTMLKonv     matlab.ui.control.HTML
        KonvKorPrikladKovnTab_2         matlab.ui.container.Tab
        GridLayout16_6                  matlab.ui.container.GridLayout
        Hyperlink2_7                    matlab.ui.control.Hyperlink
        KonvKorKorDalsiePrikladyHyperlink  matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_11  matlab.ui.control.Label
        KonvKorRestartButtonKor         matlab.ui.control.Button
        KonvKorShowAnswerButtonKor      matlab.ui.control.Button
        KonvKorRunCodeUlohaButtonKor    matlab.ui.control.Button
        MATLABCODEEDITORLabel_12        matlab.ui.control.Label
        KonvKorUlohaCodeAreaHTMLKor     matlab.ui.control.HTML
        KonvKorPanelKonzolaKor          matlab.ui.container.Panel
        GridLayout17_8                  matlab.ui.container.GridLayout
        KonvKorKonzolaTextAreaKor       matlab.ui.control.TextArea
        KonvKorZadanieUlohyHTMLKor      matlab.ui.control.HTML
        StochSignalyMainTab             matlab.ui.container.Tab
        GridLayout26                    matlab.ui.container.GridLayout
        StochSignalyTabGroup            matlab.ui.container.TabGroup
        StochSignalyTeoriaTab           matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        StochSignalyTeoriaHTML          matlab.ui.control.HTML
        StochSignalyInteraktivnaUkazka2Tab  matlab.ui.container.Tab
        GridLayout10_5                  matlab.ui.container.GridLayout
        Label1_4                        matlab.ui.control.Label
        RozptylT2Label_2                matlab.ui.control.Label
        tatistickparametreLabel_2       matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaOdchylkaT2Label_2  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaRozptylT2Label_2  matlab.ui.control.Label
        StrednahodnotaT2Label_2         matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label_2  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaOdchylkaT1Label_2  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaRozptylT1Label_2  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label_2  matlab.ui.control.Label
        Label1_3                        matlab.ui.control.Label
        RozptylT1Label_2                matlab.ui.control.Label
        StrednahodnotaT1Label_2         matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaT2Slider_2  matlab.ui.control.Slider
        T2SliderLabel_2                 matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaT1Slider_2  matlab.ui.control.Slider
        T1SliderLabel_2                 matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaSignalUIAxes_2  matlab.ui.control.UIAxes
        StochSignalyInteraktivnaUkazkaHustota1UIAxes_2  matlab.ui.control.UIAxes
        StochSignalyInteraktivnaUkazkaTab  matlab.ui.container.Tab
        GridLayout10_4                  matlab.ui.container.GridLayout
        KedzmenmeTnestacionrnyLabel     matlab.ui.control.Label
        StochSignalySirkaSlider         matlab.ui.control.Slider
        irkaT12Label                    matlab.ui.control.Label
        Label1_2                        matlab.ui.control.Label
        RozptylT2Label                  matlab.ui.control.Label
        tatistickparametreLabel         matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaOdchylkaT2Label  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaRozptylT2Label  matlab.ui.control.Label
        StrednahodnotaT2Label           matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaOdchylkaT1Label  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaRozptylT1Label  matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label  matlab.ui.control.Label
        Label1                          matlab.ui.control.Label
        RozptylT1Label                  matlab.ui.control.Label
        StrednahodnotaT1Label           matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaT2Slider  matlab.ui.control.Slider
        T2SliderLabel                   matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaT1Slider  matlab.ui.control.Slider
        T1SliderLabel                   matlab.ui.control.Label
        StochSignalyInteraktivnaUkazkaSignalUIAxes  matlab.ui.control.UIAxes
        StochSignalyInteraktivnaUkazkaHustota1UIAxes  matlab.ui.control.UIAxes
        TeoreticktestTab_7              matlab.ui.container.Tab
        GridLayout38_7                  matlab.ui.container.GridLayout
        KomplSubmitButton_7             matlab.ui.control.Button
        KomplRestartButton_7            matlab.ui.control.Button
        KomplexO4Panel_7                matlab.ui.container.Panel
        GridLayout39_28                 matlab.ui.container.GridLayout
        vznikkolziamipohybujcichsanbojovLabel  matlab.ui.control.Label
        niejeplochLabel                 matlab.ui.control.Label
        jeplocharovnomernerozloenvcelomspektreLabel  matlab.ui.control.Label
        pozostvazkrtkychimpulzovLabel   matlab.ui.control.Label
        ojepravdaovkonovomspektrebielehoumuLabel  matlab.ui.control.Label
        DCheckBox_28                    matlab.ui.control.CheckBox
        CCheckBox_28                    matlab.ui.control.CheckBox
        BCheckBox_28                    matlab.ui.control.CheckBox
        ACheckBox_28                    matlab.ui.control.CheckBox
        KomplexO3Panel_7                matlab.ui.container.Panel
        GridLayout39_27                 matlab.ui.container.GridLayout
        sum_n0N1xleftnrightemathrmjknLabel_2  matlab.ui.control.Label
        lim_Ttoinftyfrac12Tint_TTslefttrightdtLabel  matlab.ui.control.Label
        lim_Ttoinftyfrac12Tint_TTleftoversetslefttrightright2dtLabel  matlab.ui.control.Label
        lim_Ttoinftyfrac12Tint_TTsleftttaurightdtLabel  matlab.ui.control.Label
        asovstrednhodnotajedanvzahomLabel  matlab.ui.control.Label
        DCheckBox_27                    matlab.ui.control.CheckBox
        CCheckBox_27                    matlab.ui.control.CheckBox
        BCheckBox_27                    matlab.ui.control.CheckBox
        ACheckBox_27                    matlab.ui.control.CheckBox
        KomplexO2Panel_7                matlab.ui.container.Panel
        GridLayout39_26                 matlab.ui.container.GridLayout
        preopissanevyuvasbortatistickchparametrovLabel  matlab.ui.control.Label
        tatistickparametresameniasasomLabel  matlab.ui.control.Label
        tatistickparametresanemeniasasomLabel  matlab.ui.control.Label
        majurittendenciurozvojavaseLabel  matlab.ui.control.Label
        StacionrnestochasticksignlyspecificktmeLabel  matlab.ui.control.Label
        DCheckBox_26                    matlab.ui.control.CheckBox
        CCheckBox_26                    matlab.ui.control.CheckBox
        BCheckBox_26                    matlab.ui.control.CheckBox
        ACheckBox_26                    matlab.ui.control.CheckBox
        KomplexO1Panel_7                matlab.ui.container.Panel
        GridLayout39_25                 matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_7  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_8  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_7  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_7  matlab.ui.control.Label
        ojepravdaostochastickchsignlochLabel  matlab.ui.control.Label
        DCheckBox_25                    matlab.ui.control.CheckBox
        CCheckBox_25                    matlab.ui.control.CheckBox
        BCheckBox_25                    matlab.ui.control.CheckBox
        ACheckBox_25                    matlab.ui.control.CheckBox
        RieenprkladTab_2                matlab.ui.container.Tab
        GridLayout16_7                  matlab.ui.container.GridLayout
        CTRLSPACEukepremennafunkcieLabel_12  matlab.ui.control.Label
        StochSignalyRiesenyRunCodeUlohaButton  matlab.ui.control.Button
        MATLABCODEEDITORLabel_14        matlab.ui.control.Label
        StochSignalyRiesenyCodeAreaHTML  matlab.ui.control.HTML
        StochSignalyRiesenyPanelKonzola  matlab.ui.container.Panel
        GridLayout17_9                  matlab.ui.container.GridLayout
        StochSignalyRiesenyKonzolaTextArea  matlab.ui.control.TextArea
        StochSignalyRiesenyZadanieUlohyHTML  matlab.ui.control.HTML
        StochSignalyRiesenyUIAxes       matlab.ui.control.UIAxes
        PrkladTab_5                     matlab.ui.container.Tab
        GridLayout16_8                  matlab.ui.container.GridLayout
        Hyperlink2_8                    matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_13  matlab.ui.control.Label
        StochSignalyNeriesenyRestartButton  matlab.ui.control.Button
        StochSignalyNeriesenyShowAnswerButton  matlab.ui.control.Button
        StochSignalyNeriesenyRunCodeUlohaButton  matlab.ui.control.Button
        MATLABCODEEDITORLabel_15        matlab.ui.control.Label
        StochSignalyNeriesenyCodeAreaHTML  matlab.ui.control.HTML
        StochSignalyNeriesenyPanelKonzola  matlab.ui.container.Panel
        GridLayout17_10                 matlab.ui.container.GridLayout
        StochSignalyNeriesenyKonzolaTextArea  matlab.ui.control.TextArea
        StochSignalyNeriesenyZadanieUlohyHTML  matlab.ui.control.HTML
        StochSignalyNeriesenyUIAxes     matlab.ui.control.UIAxes
        PRSMainTab                      matlab.ui.container.Tab
        GridLayout30                    matlab.ui.container.GridLayout
        PRSTabGroup                     matlab.ui.container.TabGroup
        PRSTeoria                       matlab.ui.container.Tab
        GridLayout31                    matlab.ui.container.GridLayout
        HTML6                           matlab.ui.control.HTML
        PRSUkazka                       matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        asovpriebehUPNRZlinkovkdLabel   matlab.ui.control.Label
        GenerovaniepseudonhodnejpostupnostiPanel  matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        PRSGenDoPrava                   matlab.ui.control.Button
        PRSGenDoLava                    matlab.ui.control.Button
        PRSGenImage                     matlab.ui.control.Image
        PRSGenUIImage                   matlab.ui.control.Image
        PRSGenUIAxes                    matlab.ui.control.UIAxes
        UkkameraniachybovostikanlaTab   matlab.ui.container.Tab
        GridLayout33                    matlab.ui.container.GridLayout
        asovpriebehUPNRZlinkovkdLabel_2  matlab.ui.control.Label
        BERLabel                        matlab.ui.control.Label
        BERNeNtbLabel                   matlab.ui.control.Label
        NtbLabel                        matlab.ui.control.Label
        NeLabel                         matlab.ui.control.Label
        PRSKanalRunSimulationButton     matlab.ui.control.Button
        PRSKanalImage                   matlab.ui.control.Image
        PRSVysKanalUIAxes               matlab.ui.control.UIAxes
        PRSPrijKanalUIAxes              matlab.ui.control.UIAxes
        TeoreticktestTab_8              matlab.ui.container.Tab
        GridLayout38_8                  matlab.ui.container.GridLayout
        KomplSubmitButton_8             matlab.ui.control.Button
        KomplRestartButton_8            matlab.ui.control.Button
        KomplexO4Panel_8                matlab.ui.container.Panel
        GridLayout39_32                 matlab.ui.container.GridLayout
        operciouXORLabel_3              matlab.ui.control.Label
        operciouNOTLabel_3              matlab.ui.control.Label
        operciouORLabel_3               matlab.ui.control.Label
        operciouANDLabel_3              matlab.ui.control.Label
        SptnovzobnfunkciagenertoraPNpostupnostijetvorenLabel  matlab.ui.control.Label
        DCheckBox_32                    matlab.ui.control.CheckBox
        CCheckBox_32                    matlab.ui.control.CheckBox
        BCheckBox_32                    matlab.ui.control.CheckBox
        ACheckBox_32                    matlab.ui.control.CheckBox
        KomplexO3Panel_8                matlab.ui.container.Panel
        GridLayout39_31                 matlab.ui.container.GridLayout
        sum_n0N1xleftnrightemathrmjknLabel_3  matlab.ui.control.Label
        fracdkapseudonhodnejpostupnostipoetchybneprijatchbitovLabel  matlab.ui.control.Label
        fracpoetcelkovoprijatchbitovpoetchybneprijatchbitovLabel  matlab.ui.control.Label
        fracpoetchybneprijatchbitovpoetcelkovoprijatchbitovLabel  matlab.ui.control.Label
        BERBitErrorRatejemonvypotaakoLabel  matlab.ui.control.Label
        DCheckBox_31                    matlab.ui.control.CheckBox
        CCheckBox_31                    matlab.ui.control.CheckBox
        BCheckBox_31                    matlab.ui.control.CheckBox
        ACheckBox_31                    matlab.ui.control.CheckBox
        KomplexO2Panel_8                matlab.ui.container.Panel
        GridLayout39_30                 matlab.ui.container.GridLayout
        exponencilnyLabel_3             matlab.ui.control.Label
        tvorcovLabel_3                  matlab.ui.control.Label
        obdnikovLabel_3                 matlab.ui.control.Label
        trojuholnkovLabel_3             matlab.ui.control.Label
        AktvarmautokorelanfunkciapseudonhodnejpostupnostiLabel  matlab.ui.control.Label
        DCheckBox_30                    matlab.ui.control.CheckBox
        CCheckBox_30                    matlab.ui.control.CheckBox
        BCheckBox_30                    matlab.ui.control.CheckBox
        ACheckBox_30                    matlab.ui.control.CheckBox
        KomplexO1Panel_8                matlab.ui.container.Panel
        GridLayout39_29                 matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_8  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_9  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_8  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_8  matlab.ui.control.Label
        MaximlnadkapseudonhodnejpostupnostijedanvzahomLabel  matlab.ui.control.Label
        DCheckBox_29                    matlab.ui.control.CheckBox
        CCheckBox_29                    matlab.ui.control.CheckBox
        BCheckBox_29                    matlab.ui.control.CheckBox
        ACheckBox_29                    matlab.ui.control.CheckBox
        LinkoveKodyMainTab              matlab.ui.container.Tab
        GridLayout30_2                  matlab.ui.container.GridLayout
        PRSTabGroup_2                   matlab.ui.container.TabGroup
        PRSTeoria_2                     matlab.ui.container.Tab
        GridLayout31_2                  matlab.ui.container.GridLayout
        HTML6_2                         matlab.ui.control.HTML
        UkkyLinkovchkdovTab             matlab.ui.container.Tab
        GridLayout36                    matlab.ui.container.GridLayout
        LinkoveKodyChybLabel            matlab.ui.control.Label
        ManchesterButton                matlab.ui.control.Button
        AMI_RZButton                    matlab.ui.control.Button
        AMI_NRZButton                   matlab.ui.control.Button
        BP_RZButton                     matlab.ui.control.Button
        UP_RZButton                     matlab.ui.control.Button
        BP_NRZButton                    matlab.ui.control.Button
        UP_NRZButton                    matlab.ui.control.Button
        LinkoveKodyBitovePoleEditField  matlab.ui.control.EditField
        ZadajtebitovpostupnosEditFieldLabel  matlab.ui.control.Label
        LinkoveKodyUkazkyUIAxes         matlab.ui.control.UIAxes
        TeoreticktestTab_9              matlab.ui.container.Tab
        GridLayout38_9                  matlab.ui.container.GridLayout
        KomplSubmitButton_9             matlab.ui.control.Button
        KomplRestartButton_9            matlab.ui.control.Button
        KomplexO4Panel_9                matlab.ui.container.Panel
        GridLayout39_36                 matlab.ui.container.GridLayout
        zmenarovnevdynastvanazaiatkubitovhointervaluLabel  matlab.ui.control.Label
        zmenarovnevdynastvaprizmenefzysignluLabel  matlab.ui.control.Label
        nedochdzakzmenerovneLabel       matlab.ui.control.Label
        zmenarovnevdynastvanakoncibitovhointervaluLabel  matlab.ui.control.Label
        VetkyvariantyfzovhokdovaniasavyznaujtmeLabel  matlab.ui.control.Label
        DCheckBox_36                    matlab.ui.control.CheckBox
        CCheckBox_36                    matlab.ui.control.CheckBox
        BCheckBox_36                    matlab.ui.control.CheckBox
        ACheckBox_36                    matlab.ui.control.CheckBox
        KomplexO3Panel_9                matlab.ui.container.Panel
        GridLayout39_35                 matlab.ui.container.GridLayout
        sum_n0N1xleftnrightemathrmjknLabel_4  matlab.ui.control.Label
        niejemonodvodisynchronizansignlLabel  matlab.ui.control.Label
        monosodvodisynchronizansignlzfrekvennhospektraLabel  matlab.ui.control.Label
        monosodvodisynchronizansignlpriamozlinkovhokduLabel  matlab.ui.control.Label
        PodporasynchronizcievlinkovchkdochznamenLabel  matlab.ui.control.Label
        DCheckBox_35                    matlab.ui.control.CheckBox
        CCheckBox_35                    matlab.ui.control.CheckBox
        BCheckBox_35                    matlab.ui.control.CheckBox
        ACheckBox_35                    matlab.ui.control.CheckBox
        KomplexO2Panel_9                matlab.ui.container.Panel
        GridLayout39_34                 matlab.ui.container.GridLayout
        rovnevetkchsignlovchprvkovnemajrovnakpolarituLabel  matlab.ui.control.Label
        priraujdvojkovmsymbolomtrojkovsignlLabel  matlab.ui.control.Label
        rovnesignlovchprvkovmajodlinpolarituLabel  matlab.ui.control.Label
        rovnevetkchsignlovchprvkovmajrovnakpolarituLabel  matlab.ui.control.Label
        ojepravdaounipolrnchlinkovchkdochLabel  matlab.ui.control.Label
        DCheckBox_34                    matlab.ui.control.CheckBox
        CCheckBox_34                    matlab.ui.control.CheckBox
        BCheckBox_34                    matlab.ui.control.CheckBox
        ACheckBox_34                    matlab.ui.control.CheckBox
        KomplexO1Panel_9                matlab.ui.container.Panel
        GridLayout39_33                 matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_9  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_10  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_9  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_9  matlab.ui.control.Label
        SkratkaNRZcharakterizujelinkovkdyLabel  matlab.ui.control.Label
        DCheckBox_33                    matlab.ui.control.CheckBox
        CCheckBox_33                    matlab.ui.control.CheckBox
        BCheckBox_33                    matlab.ui.control.CheckBox
        ACheckBox_33                    matlab.ui.control.CheckBox
        ShanonHartleyMainTab            matlab.ui.container.Tab
        GridLayout30_3                  matlab.ui.container.GridLayout
        PRSTabGroup_3                   matlab.ui.container.TabGroup
        PRSTeoria_3                     matlab.ui.container.Tab
        GridLayout31_3                  matlab.ui.container.GridLayout
        HTML6_3                         matlab.ui.control.HTML
        TeoreticktestTab_10             matlab.ui.container.Tab
        GridLayout38_10                 matlab.ui.container.GridLayout
        KomplSubmitButton_10            matlab.ui.control.Button
        KomplRestartButton_10           matlab.ui.control.Button
        KomplexO4Panel_10               matlab.ui.container.Panel
        GridLayout39_40                 matlab.ui.container.GridLayout
        bitsLabel_7                     matlab.ui.control.Label
        bitsLabel_6                     matlab.ui.control.Label
        bitsLabel_5                     matlab.ui.control.Label
        bitsLabel_4                     matlab.ui.control.Label
        Akbudekapacitakanlaakvkonsignluje0dBWvkonumuje10dBmaB10kHzLabel  matlab.ui.control.Label
        DCheckBox_40                    matlab.ui.control.CheckBox
        CCheckBox_40                    matlab.ui.control.CheckBox
        BCheckBox_40                    matlab.ui.control.CheckBox
        ACheckBox_40                    matlab.ui.control.CheckBox
        KomplexO3Panel_10               matlab.ui.container.Panel
        GridLayout39_39                 matlab.ui.container.GridLayout
        sum_n0N1xleftnrightemathrmjknLabel_5  matlab.ui.control.Label
        bitsLabel_3                     matlab.ui.control.Label
        bitsLabel_2                     matlab.ui.control.Label
        bitsLabel                       matlab.ui.control.Label
        AkbudekapacitakanlaakSNR30dBaB50kHzLabel  matlab.ui.control.Label
        DCheckBox_39                    matlab.ui.control.CheckBox
        CCheckBox_39                    matlab.ui.control.CheckBox
        BCheckBox_39                    matlab.ui.control.CheckBox
        ACheckBox_39                    matlab.ui.control.CheckBox
        KomplexO2Panel_10               matlab.ui.container.Panel
        GridLayout39_38                 matlab.ui.container.GridLayout
        dBLabel_3                       matlab.ui.control.Label
        dBLabel_2                       matlab.ui.control.Label
        dBmLabel                        matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        AkbudeSNRvdecibelochakvkonsignalje30dBmavkonumuje40dBmLabel  matlab.ui.control.Label
        DCheckBox_38                    matlab.ui.control.CheckBox
        CCheckBox_38                    matlab.ui.control.CheckBox
        BCheckBox_38                    matlab.ui.control.CheckBox
        ACheckBox_38                    matlab.ui.control.CheckBox
        KomplexO1Panel_10               matlab.ui.container.Panel
        GridLayout39_37                 matlab.ui.container.GridLayout
        sreprezentovansboromkonenchhodntLabel_10  matlab.ui.control.Label
        vasenadobdajnekonenpoethodntLabel_11  matlab.ui.control.Label
        preichopissavyuvasadtatistickchparametrovLabel_10  matlab.ui.control.Label
        jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_10  matlab.ui.control.Label
        AkjesprvnyzapisvzahuprevpoetkapacitykanlaLabel  matlab.ui.control.Label
        DCheckBox_37                    matlab.ui.control.CheckBox
        CCheckBox_37                    matlab.ui.control.CheckBox
        BCheckBox_37                    matlab.ui.control.CheckBox
        ACheckBox_37                    matlab.ui.control.CheckBox
        ShannonPrikladTab               matlab.ui.container.Tab
        GridLayout16_9                  matlab.ui.container.GridLayout
        Hyperlink2_9                    matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_14  matlab.ui.control.Label
        ShannonRunCodeUlohaButtonRiesena  matlab.ui.control.Button
        MATLABCODEEDITORLabel_16        matlab.ui.control.Label
        ShannonCodeAreaHTMLRieseny      matlab.ui.control.HTML
        ShannonPanelKonzolaRieseny      matlab.ui.container.Panel
        GridLayout17_11                 matlab.ui.container.GridLayout
        ShannonKonzolaTextAreaRieseny   matlab.ui.control.TextArea
        KonvKorZadanieUlohyHTMLKonv_2   matlab.ui.control.HTML
        KonvKorPrikladKovnTab_4         matlab.ui.container.Tab
        GridLayout16_10                 matlab.ui.container.GridLayout
        Hyperlink2_10                   matlab.ui.control.Hyperlink
        CTRLSPACEukepremennafunkcieLabel_15  matlab.ui.control.Label
        KonvKorRestartButtonKonv_3      matlab.ui.control.Button
        KonvKorShowAnswerButtonKonv_3   matlab.ui.control.Button
        KonvKorRunCodeUlohaButtonKonv_3  matlab.ui.control.Button
        MATLABCODEEDITORLabel_17        matlab.ui.control.Label
        ShannonCodeAreaHTMLNerieseny    matlab.ui.control.HTML
        KonvKorPanelKonzolaKonv_3       matlab.ui.container.Panel
        GridLayout17_12                 matlab.ui.container.GridLayout
        ShannonKonzolaTextAreaNerieseny  matlab.ui.control.TextArea
        KonvKorZadanieUlohyHTMLKonv_3   matlab.ui.control.HTML
        FiltreMainTab                   matlab.ui.container.Tab
        GridLayout30_4                  matlab.ui.container.GridLayout
        PRSTabGroup_4                   matlab.ui.container.TabGroup
        PRSTeoria_4                     matlab.ui.container.Tab
        GridLayout31_4                  matlab.ui.container.GridLayout
        HTML6_4                         matlab.ui.control.HTML
        KonvKorPrikladKovnTab_5         matlab.ui.container.Tab
        GridLayout16_12                 matlab.ui.container.GridLayout
        Hyperlink2_13                   matlab.ui.control.Hyperlink
        Hyperlink2_12                   matlab.ui.control.Hyperlink
        KonvKorRunCodeUlohaButtonKonv_4  matlab.ui.control.Button
        MATLABCODEEDITORLabel_19        matlab.ui.control.Label
        FiltreCodeAreaHTML              matlab.ui.control.HTML
        KonvKorPanelKonzolaKonv_4       matlab.ui.container.Panel
        GridLayout17_14                 matlab.ui.container.GridLayout
        FiltreKonzolaTextArea           matlab.ui.control.TextArea
        KonvKorZadanieUlohyHTMLKonv_5   matlab.ui.control.HTML
    end

    %% GUI "Atributy" tzv. properties
    properties (Access = private)
        prepravka = []; % pomocna premena
        codePrepravka = ""; % asi neni treba
        stacionarnySignalValues; % pre stoch aby nemuselo stale načitavať
        nonStacionarnySignalValues;% to iste aby zbytočne nezatažovalo
        
        rectImpulseBool = true;
        triangleImpulseBool = false;
        gaussImpulseBool = false;
        PRSpocitadlo = 0;
    end

    %% GUI Metody
    methods (Access = private)
        %% runCodeFunction
        % tato metoda použije metodu na formatovanie kodu a pomcou funkcie
        % evalc spusti kod. Ak je kod chybne napisany pomocou vynimiek
        % zachytim chybu a vypišem ju do text arei. Ak spusteny kod
        % nevyuživa grafické vykreslenie dat tak data sa vypišu do textoveho
        % pola(text area) cize ako keby sa vypisali do konzoly.
        function runCode(app,code,textArea,UIAxesString)

            %ak UIAxes nie je "" tak ho upraviš na plotovanie
            if UIAxesString ~=""
                formatedCode = formatCodeForPlot(app,code,UIAxesString);
            else
                %iba pretypuješ a vratiš
                formatedCode = string(code);
            end

            %pomocou vynimky ziskam error alebo result ktory by vypisalo do konzole
            try
                result = evalc(formatedCode); % vracia vypis do konzole
                result = ignoreWarning(app,result);
                %result = convertCharsToStrings(result);% pretypujem na string lebo evalc mi vracia pole charov
                error = "";
            catch ME
                error = ME.message; % ME je matlab objekt ktory ma informacie o errore(chybe)
                result = "";
            end
            % MOŽNO NIEČO INE BUDE
            %ak je text area prazdna a nastane error vypiše ho msgBoxu a
            %vrati inak aj je error prazdny returne nic aby skončil tato
            %funkcia
            if textArea == "" && error ~=""
                msgbox(error,"Error","error","modal");
                return
            elseif textArea =="" && error == ""
                return
            end

            %bude vypisovat do textArei
            if error == ""
                textArea.FontColor = "Black"; % vysledok bude mat ciernu farbu
                textArea.Value = result;

            else
                textArea.FontColor = "Red"; % error bude mat červenu farbu
                textArea.Value = error;
            end

        end

        %% ignoreWarning method
        %tato metoda mi bude ignorovat warning v konzole
        function result = ignoreWarning(app,code)
            if contains(code,"Warning")
                %code = replaceBetween(code,"[","]","");
                code = strsplit(code,newline);
                result = "";
                for i = code
                    if contains(i,"Warning")
                        break
                    else
                        result = result + newline + i;
                    end
                end
            else
                result = code;
            end
        end

        %% formatCodeForPlot method
        %tato metoda mi naformatuje code tak aby bolo možne plotovať,
        %nastavovat nazov osi a pod. Pretože plot() berie ako prvy argument
        %container kam bude vykreslovat najdem tieto klučove slova a
        %doplnim tam Axes kam to bude plotovat. Berie ako vstup Kod a Axes
        %kam to bude plotovat.

        function formatedCode = formatCodeForPlot(app,code,UIAxeString)
            %pretypujem na string lebo vracia pole charov
            code = string(code); %% musi byt

            % Ak je prazdne vycisti UIAxes, tym že code ktorý vrati bude
            % cla(UIAxes,"reset")
            if code == ""
                formatedCode = "cla("+UIAxeString+",'reset')";
                return
            end

            %Vycistim UIAxes pre novy plot
            code = "cla("+UIAxeString+",'reset')"+ newline + code;

            % keyWords: klučove slovakde treba pridať UIAxes a koncia "()"
            % treba pridať ak chyba
            keyWords = ["plot(","xlim(","ylim(","xlabel(","ylabel(",...
                "title(","line(","text(","stairs(","stem(","hold(","grid(",...
                "xline(","yline("];
            % onOffKeyWords: tie ktore končia on ale off treba pridať (app.VideoAxes,"on"/"off")
            onOffKeyWords = ["hold on","hold off",...
                "grid on","grid off"];
            %hold(app.UIAXEs,"on")

            %rozdelim pri newline do pola stringov aby som mohol s nimi pracovať
            code = strsplit(code,newline);

            %pozicie KeyWords -> vracia boolean pole
            keyWordsPos = contains(code,keyWords);

            % kvoli optimalazicii ak sa tam nechadzaju klučové slova tak
            % nebudu zbytočne bežat 2 for cyckle
            %ak to pole nie je prazdny string tak potom sa spusti
            if code(keyWordsPos) ~= ""

                findedKeyWords = code(keyWordsPos); % uložim do pola najdene slová
                pocitadlo = 1; %vytvorim si pocitadlo

                for i = 1:length(findedKeyWords) % prechadzam pole najdenych slov
                    charToString = convertStringsToChars(findedKeyWords(i)); % premeniam ich na char
                    for j = 1:length(charToString) % prechadzam pole tychto charov
                        if charToString(j) == "(" % ak najde "("
                            novyString = charToString(1:j)+UIAxeString+","+charToString(j+1:end); % vložim "Axes" do daneho slova
                            findedKeyWords(pocitadlo) = novyString; % nasledne nahradim toto slovo za slovo povodne
                            pocitadlo = pocitadlo+1; % a pridam do pocitadla aby som mohol ist na dalšie slovo
                            break %prerušim cyklus pretože dalšiu zatvorku nechcem nahradit a ani nemožem pretože xlabel(f(w))
                        end
                    end
                end
                %code = replace(code,code(stringPos),findedWords);
                %nasledne potrebujem nahradit slova za nové slova v povodnom poli
                code(keyWordsPos) = findedKeyWords;
            end

            %pozicie OnOffKeyWords -> vracia boolean pole
            OnOffKeyWordsPos = contains(code,onOffKeyWords);

            %najdem OnOffKeyWords
            findedOnOffKeyWords = code(OnOffKeyWordsPos);
            %ak nie je prazdne vykona inak nic optimalizacia
            if findedOnOffKeyWords ~= ""
                for i = 1:length(findedOnOffKeyWords) % cyklus prejdeme najdene slova
                    strip = strsplit(findedOnOffKeyWords(i)," "); % rozdelime na medzere " "
                    newWord = strip(1)+"("+UIAxeString+",'"+strip(2)+"')"; % rozdelene upravime pridame UIAxes
                    findedOnOffKeyWords(i) = newWord; %nahradime za nove upravene
                end
                % nasledne vymenime pozicie kde sme nasli klucove slova za nove
                code(OnOffKeyWordsPos) = findedOnOffKeyWords;
            end

            % spajam do jedneho stringu na newline "\n" matlab neberie
            % a vraciam
            formatedCode = strjoin(code,newline);
            %disp(formatedCode)
        end

        %% restartTestAnswers method
        %tato metoda mi restartne odpovede testovych otazok
        %poleSelectedButtons zaškrtnute buttony
        % resultLabel je object Labelu ktory nastavi na prazdny string
        function restartTestAnswers(app,poleSelectedButtons,resultLabel)
            for i = poleSelectedButtons
                i.Value = 0;
                i.FontColor = "black";
            end
            resultLabel.Text = "";
        end


        %% checkTestAnswer
        %poleSelectedButtons su zastiknute buttony kde ich Text kontroluje s
        %odpovedami a vracia mi odpoved v %  a nastavuje resultLabel ktory
        %vypisuje hodnotu v %
        function checkTestAnswer(app,poleSelectedButtons, poleAnswers, resultLabel)

            pocitadlo  = 0; % pocitadlo spravnych odpovedi

            for i = 1:length(poleSelectedButtons)
                if poleSelectedButtons(i).Text == poleAnswers(i)
                    pocitadlo = pocitadlo+1;
                    poleSelectedButtons(i).FontColor = "green";
                elseif poleSelectedButtons(i).Text ~= poleAnswers(i)
                    poleSelectedButtons(i).FontColor = "red";
                end
            end

            resultLabel.Text = "" +(pocitadlo/length(poleAnswers))*100 + "%" ;
        end

        %% checkTextField method
        %EditField skontroluje spravnu odpoveď
        %input text bude z actionlisteneru ValueChanged - > sem
        % posible answers je pole moznych odpovedi a textField je object
        % kde budeme menit farbu ( referencia
        function checkTextField(app,inputText,polePossibleAnswers,textField)
            %pomocna boolean
            najdeneBool = 0;

            % odstranit medzery
            inputText = erase(inputText," ");
            inputText = convertCharsToStrings(inputText);

            for i = 1:length(polePossibleAnswers)
                if polePossibleAnswers(i) == inputText
                    textField.FontColor = "green";
                    najdeneBool = 1;
                end
            end

            % ak nie je najdena da na cervene že je zle
            if najdeneBool == 0
                textField.FontColor = "red";
            end

        end

        %% checkRadioAnswers method
        % len zafarbi ak je spravne na zeleno a % a old button da naspat na
        % povodnu farbu cize %čiernu
        function checkRadioAnswers(app,oldButton,newButton,spravnaOdpoved)
            oldButton.FontColor = "black";
            if newButton.Text == spravnaOdpoved
                newButton.FontColor = "green";
            else
                newButton.FontColor = "red";
            end
        end

        %% getCodeFromHMTL
        % tato metoda mi nacita kod z HTML codeArei

        function runCodeFromHTML(app,HMTLObject,TextArea,UIAxes)
            % posiela event do JavaScriptu
            HMTLObject.sendEventToHTMLSource("sendData");
            % nasledne prijma event z JavaScriptu a odkazuje na funkciu
            % getText
            HMTLObject.HTMLEventReceivedFcn = @getText;
            %getText funkcia ktora ma event z ktorého ziskame data -> kod vo
            %vo forme stringu
            function getText(~,event)
                stringCode = event.HTMLEventData;
                %nasledne volal runCode ktora pusti dany kód
                runCode(app,stringCode,TextArea,UIAxes);
                %disp(stringCode)
            end

        end

        %% readCodeFromFile %%
        %nacita hodnoty zo subory
        % path => cesta k suboru 
        % format =%f %s ci je to string alebo float, integer atd
        function textFromFile = readFromFile(app,path,format)
            try
                file = fopen(path,"r");
                textFromFile = fscanf(file,format);
                fclose(file);
            catch ME
                %nech vypíše 
                % a da hodnotu 
                disp(ME.message)
                if format == "%f"
                    textFromFile = 0;
                else
                    textFromFile = "ans = error while reading file";
                end
            end

        end

        %% plotUpdateSin metoda
        % bude vykreslovať/ updatovať sinusový signal
        function plotUpdateSin(app,A,fi,T)
            cla(app.SignalyInterktivnaUkazkaUIAxes);
            app.SignalyInterktivnaUkazkaUIAxes.XLabel.String = "t";
            app.SignalyInterktivnaUkazkaUIAxes.YLabel.String = "x(t)";
            app.SignalyInterktivnaUkazkaUIAxes.Title.String = "Sinusový signál";
            %dlzka signalu v t oblasti
            %vsetko zaohruhlujem na cele cisla
            t_end = 6;
            %app.SignalyCasTrvaniaSlider.Value = t_end;
            %casovy vektor
            t = linspace(-6*pi,(t_end*pi),1000);
            
            %amplituda
            A = round(A);
            app.SignalyAmplitudaSlider.Value = A;
            %perioda
            T = round(T);
            app.SignalyPeriodaSlider.Value = T;
            %samotny signalň
            %prepocet na radiany lebo to blbne
            fi = deg2rad(fi);
            signal = A*sin((T)*t+fi);
            
            %vykreslnie
            plot(app.SignalyInterktivnaUkazkaUIAxes,t(500:1000),signal(500:1000),"LineWidth",2,"Color","#0072BD")
            hold(app.SignalyInterktivnaUkazkaUIAxes,"on");
            plot(app.SignalyInterktivnaUkazkaUIAxes,t(1:500),signal(1:500),"LineWidth",2,"LineStyle","--","Color","#0072BD");
            %limity a opisy osi
            xline(app.SignalyInterktivnaUkazkaUIAxes,0,"LineWidth",1.2)
            yline(app.SignalyInterktivnaUkazkaUIAxes,0,"LineWidth",1.2)
            app.SignalyInterktivnaUkazkaUIAxes.XLim = [-6*pi,(t_end*pi)];
            app.SignalyInterktivnaUkazkaUIAxes.YLim = [-A-1,A+1];
            app.SignalyInterktivnaUkazkaUIAxes.YTick = [-A:A];
            app.SignalyInterktivnaUkazkaUIAxes.YTickLabel = {-A:A};
            app.SignalyInterktivnaUkazkaUIAxes.XTick = [-6*pi:pi:(t_end*pi)];
            %pomocka pomocu javy "DYNAMICKE POLE"/"ARRAYLIST"
            x =  java.util.ArrayList;
            for i = -6:t_end
                if i == 0
                    x.add("0");
                else
                    x.add(i+"π");
                end
            end
            app.SignalyInterktivnaUkazkaUIAxes.XTickLabel = string(toArray(x));
        end
        %% plotUpdateRect method
        %vykresli a bude updatovať rect imuplse a jeho spektrum

        function plotUpdateFTtimeScaling(app,hodnota)
            % referencie objektov pre skretene pisania
            casUIAxes = app.FurTransInteraktivnaUkazkaSignalUIAxes;
            freqUIAxes = app.FurTransInteraktivnaUkazkaSpektrumUIAxes;
            
            %vzorec
            vzorec = "";

            %premenne
            %casovy vektor
            t = linspace(-5,5,1000);
            %frekvecny vektor
            w = linspace(-8*pi,8*pi,1000);
            %defaultny bude obdlznik
            if app.rectImpulseBool
                %ak obldlznik
                xt = rectpuls(t,3*hodnota);
                Xw = 3*hodnota*(sin(3*hodnota*w)./((3*hodnota)*w));
                ylim(freqUIAxes,"auto");
                
                freqUIAxes.XLim = [-8*pi,8*pi];
                %app.FurTransVzorec.Text = "$$X\left(\omega \right)=\frac{\sin \left(x\right)}{x}$$";
                vzorec = "$$X\left(\omega \right)=\frac{\sin \left(x\right)}{x}$$";

            elseif app.triangleImpulseBool
                %ak trouhlonik
                xt = tripuls(t,3*hodnota);
                Xw = 3*hodnota*((sin(3*hodnota*w)./((3*hodnota)*w)).^2);
                freqUIAxes.YLim = [-0.1,max(Xw)+0.2];
                freqUIAxes.XLim = [-17,17];
                %app.FurTransVzorec.Text = "$$X\left(\omega \right)=\frac{\sin^2 \left(x\right)}{x}$$";
                vzorec = "$$X\left(\omega \right)=\frac{\sin^2 \left(x\right)}{x}$$";

            elseif app.gaussImpulseBool
                %ak gauss
                [~,~,xt] = gauspuls(2.*t,1/(3*hodnota));
                [~,~,Xw] = gauspuls(t,3*hodnota);
                %ylim(freqUIAxes,"auto");
                freqUIAxes.XLim = [-8*pi,8*pi]; 
                freqUIAxes.YLim = [-0.01,max(Xw)+0.1]; 
                %app.FurTransVzorec.Text = "$$X\left(\omega \right)=\frac{1}{\sigma \sqrt{2\pi }}\cdot e^{\frac{{\left(x-\mu \right)}^2 }{2\sigma^2 }}$$";
                vzorec = "$$X\left(\omega \right)=\frac{1}{\sigma \sqrt{2\pi }}\cdot e^{\frac{{\left(x-\mu \right)}^2 }{2\sigma^2 }}$$";
            end


            % rectimpulse casova oblast
            %xt = rectpuls(t,3*hodnota);
            plot(casUIAxes,t,xt,"LineWidth",2);

            % rectimpulse frekvencna oblast
            %Xw = 3*hodnota*(sin(3*hodnota*w)./((3*hodnota)*w));
            plot(freqUIAxes,w,Xw,"LineWidth",2,"Color","#D95319");
            legend(freqUIAxes,vzorec,Interpreter="latex")
            

        end

        %% plotUpdateFR method
        %vykresli a bude update interaktivnu ukazku FR
        % pocetHarmonickyh ziskavam -> slider
        function plotUpdateFR(app,pocetHarmonickych)
            
            % referencie objektov pre skretene pisania
            samZlozkyUIAxes = app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes;
            furRadUIAxes = app.FurRadInteraktivnaUkazkaFurRadUIAxes;
            spektrumUIAxes = app.FurRadInteraktivnaUkazkaSpektrumUIAxes;
            %vycitenie samostatných zloziek
            cla(samZlozkyUIAxes,"reset")
            cla(furRadUIAxes,"reset")
            %iba neparne n t.j 2n-1 aby som tam mohol mat aj 1
            %pretoze je 1:25 limity slideru takze
            zaohrulene = round(pocetHarmonickych);
            pocetHarmonickych = 2*zaohrulene-1;
            %disp(pocetHarmonickych)
            % nastavime slider
            app.FurRadInteraktivnaUkazkaSlider.Value = zaohrulene;
            % premenne
            fs = 500;
            duration = 2.2*pi;
            duration1 = 2*pi;
            N = fs*duration1;
            t = -2.2*pi:1/fs:duration-1/fs;
            t1 = 0:1/fs:duration1-1/fs;
            xt = 0;
            xt1 = 0;
            A = 5;
            % len hodnoty aby som ich vedel vykreslit
            array = 1:2:pocetHarmonickych;
            %oznacenia
            title(samZlozkyUIAxes,"Samostatné zložky");
            samZlozkyUIAxes.YLabel.String = "f(t)";
            samZlozkyUIAxes.XLim = [-2.2*pi,2.2*pi];
            samZlozkyUIAxes.XTick = [-6.28,-3.14,0,3.14,6.28];
            samZlozkyUIAxes.XTickLabel = {"-2\pi","-\pi","0","\pi","2\pi"};
            samZlozkyUIAxes.YTick = [];
            samZlozkyUIAxes.YLimitMethod = "padded";


            %for cyklus pre FR
            for i = 1:2:pocetHarmonickych
                %scitanie sinusovych clenov
                xt = xt + A*(4/(pi*i))*sin(i*t);
                xt1 = xt1 + A*(4/(pi*i))*sin(i*t1);
                %vykreslenie samostatných zložke
                samotnaZlozka = A*(4/(pi*i))*sin(i*t);
                plot(samZlozkyUIAxes,t,samotnaZlozka);
                hold(samZlozkyUIAxes,"on")
                title(samZlozkyUIAxes,"Samostatné zložky");
                samZlozkyUIAxes.YLabel.String = "f(t)";
                samZlozkyUIAxes.XLabel.String = "t";
                samZlozkyUIAxes.XLabel.FontWeight = "bold";
                samZlozkyUIAxes.YLabel.FontWeight = "bold";
                samZlozkyUIAxes.XLim = [-2.2*pi,2.2*pi];
                samZlozkyUIAxes.XTick = [-6.28,-3.14,0,3.14,6.28];
                samZlozkyUIAxes.XTickLabel = {"-2\pi","-\pi","0","\pi","2\pi"};
                samZlozkyUIAxes.YTick = [];
                yline(samZlozkyUIAxes,0,"Color","k");
                xline(samZlozkyUIAxes,0,"Color","k");

            end
            %vykrelsnie FR
            plot(furRadUIAxes,t,xt,"LineWidth",2);
            hold(furRadUIAxes,"on");
            plot(furRadUIAxes,t,A*square(t),"--","LineWidth",2,"Color","#D95319")
            furRadUIAxes.XTick = [-6.28,-3.14,0,3.14,6.28];
            furRadUIAxes.XTickLabels = {"-2\pi","-\pi","0","\pi","2\pi"};
            furRadUIAxes.XLim = [-2.2*pi,2.2*pi];
            furRadUIAxes.YLimitMethod = "padded";
            furRadUIAxes.YLabel.String = "x(t)";
            furRadUIAxes.XLabel.String = "t";
            furRadUIAxes.YLabel.FontWeight = "bold";
            furRadUIAxes.XLabel.FontWeight = "bold";
            furRadUIAxes.Title.String = "Fourierov Rád";
            yline(furRadUIAxes,0,"Color","k");
            xline(furRadUIAxes,0,"Color","k");
            

            %vypocet Spektra
            % iba jednu stranu spetra
            Xf = fft(xt1);
            Xf = Xf(1:ceil(N/2));
            Xf = abs(Xf)./ceil(N/2);
            % vektor frekvencie
            f = (0:ceil(N/2)-1);
            %vykreslenie jednotlivych ciar
            stem(spektrumUIAxes,f,Xf,"LineWidth",2);
            %limity
            spektrumUIAxes.XLim = [0,i+1];
            spektrumUIAxes.YLim = [0.05,A+2];
            spektrumUIAxes.XTick = array;
       
            spektrumUIAxes.XTickLabels = array+"ω";
            grid(spektrumUIAxes,"on");
        end
        %% plotUpdateStacionarny
        %vykresli a bude udpatovať stacionarny signál
        function plotUpdateStacionarny(app,sliderValue1,sliderValue2)
            cla(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes,"reset")
            
            % vykreslenie signalu
            SignalUIAxes = app.StochSignalyInteraktivnaUkazkaSignalUIAxes;
            Slider3 = round(app.StochSignalySirkaSlider.Value);
            plot(SignalUIAxes,app.stacionarnySignalValues);
            %vykreslenie časových okien;
            %prvy argument je zač pozicia a ak bude prva hodnota slider 101
            %tak zaciatočna hodnota bude 1
            posT1 = [sliderValue1-Slider3,3.5,Slider3,3.5];
            posT2 = [sliderValue2-Slider3,3.5,Slider3,3.5];
            %vykreslenie
            rectangle(SignalUIAxes,"Position",posT1,"FaceColor",[1 1 0.5 0.6],"EdgeColor","none");
            rectangle(SignalUIAxes,"Position",posT2,"FaceColor",[1 0.5 0.5 0.6],"EdgeColor","none");
            %oznacenie T1 a T2
            %T1
            text(SignalUIAxes,posT1(1)+Slider3/2-5,3+4/2,"T1","FontSize",15,"FontWeight","bold");
            %T2
            text(SignalUIAxes,posT2(1)+Slider3/2-5,3+4/2,"T2","FontSize",15,"FontWeight","bold");
            % vykreslenie hustoty rozloženia
            %T1
            valuesT1 = app.stacionarnySignalValues(sliderValue1-Slider3:sliderValue1);
            histo1 = histogram(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes,valuesT1);
            histo1.FaceColor = [1 1 0.5];
            hold(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes,"on");
            %T2
            valuesT2 = app.stacionarnySignalValues(sliderValue2-Slider3:sliderValue2);
            histo2 = histogram(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes,valuesT2);
            histo2.FaceColor = [1 0.5 0.5];
            %histo2(2).Color = [0 0 1];
            legend(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes,"T1","T2")

            % vypocet pre T1
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label.Text = mean(valuesT1)+"";
            app.StochSignalyInteraktivnaUkazkaRozptylT1Label.Text = var(valuesT1)+"";
            app.StochSignalyInteraktivnaUkazkaOdchylkaT1Label.Text = std(valuesT1)+"";
            % vypocet pre T2
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label.Text = mean(valuesT2)+"";
            app.StochSignalyInteraktivnaUkazkaRozptylT2Label.Text = var(valuesT2)+"";
            app.StochSignalyInteraktivnaUkazkaOdchylkaT2Label.Text = std(valuesT2)+"";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes.Title.String="Hustota rozloženia T1 a T2";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes.XLabel.String = "x";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes.YLabel.String = "Px";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes.FontWeight = "bold";
            
        end


        %% plotUpdateNonStacionarny
        %vykresli a bude udpatovať stacionarny signál
        function plotUpdateNonStacionarny(app,sliderValue1,sliderValue2)
            cla(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2,"reset");
            % vykreslenie signalu
            SignalUIAxes = app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2;
            plot(SignalUIAxes,app.nonStacionarnySignalValues);
           
            %vykreslenie časových okien;
            %prvy argument je zač pozicia a ak bude prva hodnota slider 101
            %tak zaciatočna hodnota bude 1
            posT1 = [sliderValue1-100,-20,100,80];
            posT2 = [sliderValue2-100,-20,100,80];
            %vykreslenie
            rectangle(SignalUIAxes,"Position",posT1,"FaceColor",[1 1 0.5 0.6],"EdgeColor","none");
            rectangle(SignalUIAxes,"Position",posT2,"FaceColor",[1 0.5 0.5 0.6],"EdgeColor","none");
            %oznacenie T1 a T2
            %T1
            text(SignalUIAxes,posT1(1)+100/2-5,3+4/2,"T1","FontSize",15,"FontWeight","bold");
            %T2
            text(SignalUIAxes,posT2(1)+100/2-5,3+4/2,"T2","FontSize",15,"FontWeight","bold");
            % vykreslenie hustoty rozloženia
            %T1
            valuesT1 = app.nonStacionarnySignalValues(sliderValue1-100:sliderValue1);
            histo1 = histogram(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2,valuesT1);
            histo1.FaceColor = [1 1 0.5];
            hold(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2,"on");
            %T2
            valuesT2 = app.nonStacionarnySignalValues(sliderValue2-100:sliderValue2);
            histo2 = histogram(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2,valuesT2);
            histo2.FaceColor = [1 0.5 0.5];
            %histo2(2).Color = [0 0 1];
            legend(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2,"T1","T2")

            % vypocet pre T1
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label_2.Text = mean(valuesT1)+"";
            app.StochSignalyInteraktivnaUkazkaRozptylT1Label_2.Text = var(valuesT1)+"";
            app.StochSignalyInteraktivnaUkazkaOdchylkaT1Label_2.Text = std(valuesT1)+"";
            % vypocet pre T2
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label_2.Text = mean(valuesT2)+"";
            app.StochSignalyInteraktivnaUkazkaRozptylT2Label_2.Text = var(valuesT2)+"";
            app.StochSignalyInteraktivnaUkazkaOdchylkaT2Label_2.Text = std(valuesT2)+"";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2.Title.String="Hustota rozloženia T1 a T2";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2.XLabel.String = "x";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2.YLabel.String = "Px";
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2.FontWeight = "bold";
            
        end

        %% Shanon nyquist
        function shanonNyquist(app,znakRovnosti)
            cla(app.RekonstukciaSignaluUIAxes,"reset");
            cla(app.VzorkovanySignalUIAxes,"reset");
            
            % ake dame taky na nasobok základna frekvnecia je 2Hz
            if znakRovnosti == "="
                nasobok = 4; % 4 Hz 
            elseif znakRovnosti == "<"
                nasobok = 1.5; % 3Hz
            else
                nasobok = 5 ; % 5Hz
            end

            % originalný spojitých signál
            F = 2;
            dt = 0.001;
            t = 0:dt:1; 
            x_t = sin(2*pi*F*t);

            % vzorkovany signal
            F_s = nasobok*F; % 1.5 pre menej, 4 pre rovne, 5 pre viac
            T_s = 1/F_s;
            n = F_s;
            n_1 = 0:T_s:n*T_s;
            x_s = sin(2*pi*F*n_1);

            % rekonstruovany signal
            t_1 = linspace(0,max(n_1),max(n_1)/dt);
            x_r = interp1(n_1,x_s,t_1,"spline");
            
            %plot originálneho
            plot(app.VzorkovanySignalUIAxes,t,x_t,"LineWidth",2);
            hold(app.VzorkovanySignalUIAxes,"on")

            %plot vzornovania
            stem(app.VzorkovanySignalUIAxes,n_1,x_s,"LineWidth",2,"Color","k")
            app.VzorkovanySignalUIAxes.Title.String = "Vzorkovaný signál";
            app.VzorkovanySignalUIAxes.XTick = [];
            app.VzorkovanySignalUIAxes.YTick = [];
            app.VzorkovanySignalUIAxes.YLabel.String = "x(t)";
            app.VzorkovanySignalUIAxes.XLabel.String = "t";
            app.VzorkovanySignalUIAxes.FontSize = 14;
            app.VzorkovanySignalUIAxes.YLimitMethod = "padded";
            yline(app.VzorkovanySignalUIAxes,0,"Color","k")
        
            %plot rekonstruovany
            plot(app.RekonstukciaSignaluUIAxes,t_1,x_r,"LineWidth",2);
            app.RekonstukciaSignaluUIAxes.Title.String = "Rekonšturkcia signálu s použitím filtra typu dolný priepusť";
            app.RekonstukciaSignaluUIAxes.YLabel.String =  "x(t)";
            app.RekonstukciaSignaluUIAxes.XLabel.String =  "t";
            app.RekonstukciaSignaluUIAxes.XTick = [];
            app.RekonstukciaSignaluUIAxes.YTick = [];
            app.RekonstukciaSignaluUIAxes.YLimitMethod = "padded";
            yline(app.RekonstukciaSignaluUIAxes,0,"Color","k")
            app.RekonstukciaSignaluUIAxes.FontSize = 14;

            % starý kód
            % % signal v case
            % f = 10; % frekvencia
            % Fs = 20; %vzorkovacia frekv;
            % 
            % if znakRovnosti == "="
            %     Fs = 20;
            % elseif znakRovnosti == "<"
            %     Fs = 7;
            % else
            %     Fs = 40;
            % end
            % 
            % %cas
            % T = 1/f;
            % t = 0:0.001:f*T;
            % xt = sin(2*pi*f*t);
            % %vzorkovanie
            % Ts = 1/Fs;
            % ts = 0:Ts/2:f*T;
            % ys = sin(2*pi*f*ts);
            % rekon = interp1(ts,ys,t,"cubic");
            % plot(app.VzorkovanySignalUIAxes,t,xt,"LineWidth",2);
            % hold(app.VzorkovanySignalUIAxes,"on")
            % for i = 1:length(ys)
            %     stem(app.VzorkovanySignalUIAxes,ts(1:i),ys(1:i),"LineWidth",2,"Color","k");
            %     app.VzorkovanySignalUIAxes.Title.String = "Vzorkovaný signál";
            %     app.VzorkovanySignalUIAxes.YLabel.String =  "x(t)";
            %     app.VzorkovanySignalUIAxes.XLabel.String =  "t";
            %     app.VzorkovanySignalUIAxes.XTick = [];
            %     app.VzorkovanySignalUIAxes.YTick = [];
            %     app.RekonstukciaSignaluUIAxes.YLim = [-1.1,1.1];
            %     pause(0.1)
            % 
            % end
            % % cla(app.VzorkovanySignalUIAxes,"reset");
            % % stem(app.VzorkovanySignalUIAxes,ts,ys,"LineWidth",2,"Color","k");
            % % rekonsturkcia
            % plot(app.RekonstukciaSignaluUIAxes,t,rekon,"LineWidth",2);
            % app.RekonstukciaSignaluUIAxes.Title.String = "Rekonšturkcia signálu s použitím filtra typu dolný priepusť";
            % app.RekonstukciaSignaluUIAxes.YLabel.String =  "x(t)";
            % app.RekonstukciaSignaluUIAxes.XLabel.String =  "t";
            % app.RekonstukciaSignaluUIAxes.XTick = [];
            % app.RekonstukciaSignaluUIAxes.YTick = [];
            % app.RekonstukciaSignaluUIAxes.YLim = [-1.1,1.1];
            % yline(app.RekonstukciaSignaluUIAxes,0,"Color","k")
            % 
        end
        %% helpMethod
        function helpMethod(app)
            %vytvaram novy figure a nastavujem layout a farbu atd
            novyFigure = uifigure('Position',[50 50 800 600]);
            gridMain = uigridlayout(novyFigure);
            gridMain.RowHeight = {"1x"};
            gridMain.ColumnWidth = {"1x"};
            gridMain.Padding = [0,0,0,0];
            gridMain.BackgroundColor = [1,1,1];
            %vytvaram tabGroup
            tabGroupMain = uitabgroup(gridMain);
            %tab Navod
            tabNavod = uitab(tabGroupMain);
            tabNavod.Title = 'Návod';
            tabNavod.BackgroundColor = [1 1 1];
            gridNavod = uigridlayout(tabNavod);
            gridNavod.RowHeight = {"1x"};
            gridNavod.ColumnWidth = {"1x"};
            gridNavod.Padding = [0,0,0,0];
            gridNavod.BackgroundColor = [1,1,1];
            htmlNavod = uihtml(gridNavod);
            htmlNavod.HTMLSource = "kapitoly/Navod.pdf";

            %tab Funkcie
            tabFunkcie = uitab(tabGroupMain);
            tabFunkcie.Title = 'MATLAB príkazy';
            tabFunkcie.BackgroundColor = [1 1 1];
            gridFunkcie = uigridlayout(tabFunkcie);
            gridFunkcie.RowHeight = {"1x"};
            gridFunkcie.ColumnWidth = {"1x"};
            gridFunkcie.Padding = [0,0,0,0];
            gridFunkcie.BackgroundColor = [1,1,1];
            htmlFunckie = uihtml(gridFunkcie);
            htmlFunckie.HTMLSource = "kapitoly/Prikazy.pdf";
        end

        % %% setLatexWriting metoda
        % %nastavi label na ze bude vykreslovať latex kód na rovnice
        % function setLatexWriting(app)
        %     %Komplexne
        %     set(app.Komplexne1OtazkaLabel,"interpreter","latex");
        %     set(app.Komplexne2OtazkaLabel,"interpreter","latex");
        %     set(app.Komplexne3OtazkaLabel,"interpreter","latex");
        %     set(app.Komplexne4OtazkaLabel,"interpreter","latex");
        %     %Fourierove rady
        %     set(app.FurRadOdpovedLabel1A,"interpreter","latex");
        %     set(app.FurRadOdpovedLabel1B,"interpreter","latex");
        %     set(app.FurRadOdpovedLabel1C,"interpreter","latex");
        %     set(app.FurRadOdpovedLabel1D,"interpreter","latex");
        %     set(app.FurRadOdpovedLabel2A,"interpreter","latex");
        %     set(app.FurRadOdpovedLabel2B,"interpreter","latex");
        %     set(app.FurRadOdpovedLabel2C,"interpreter","latex");
        %     set(app.FurRadOdpovedLabel2D,"interpreter","latex");
        %     %FR vysledok
        %     set(app.FurRadAnswerLabel,"interpreter","latex");
        %     %FT
        %     set(app.FurTransOdpovedLabel1A,"interpreter","latex");
        %     set(app.FurTransOdpovedLabel1B,"interpreter","latex");
        %     set(app.FurTransOdpovedLabel1C,"interpreter","latex");
        %     set(app.FurTransOdpovedLabel1D,"interpreter","latex");
        %     set(app.FurTransOdpovedLabel2A,"interpreter","latex");
        %     set(app.FurTransOdpovedLabel2B,"interpreter","latex");
        %     set(app.FurTransOdpovedLabel2C,"interpreter","latex");
        %     set(app.FurTransOdpovedLabel2D,"interpreter","latex");
        %     % FT vysledok
        %     set(app.FurTransAnswerLabel,"interpreter","latex");
        %     %DFT
        %     set(app.DFTOdpovedLabel1A,"interpreter","latex");
        %     set(app.DFTOdpovedLabel1B,"interpreter","latex");
        %     set(app.DFTOdpovedLabel1C,"interpreter","latex");
        %     set(app.DFTOdpovedLabel1D,"interpreter","latex");
        %     set(app.DFTOdpovedLabel2A,"interpreter","latex");
        %     set(app.DFTOdpovedLabel2B,"interpreter","latex");
        %     set(app.DFTOdpovedLabel2C,"interpreter","latex");
        %     set(app.DFTOdpovedLabel2D,"interpreter","latex");
        %     set(app.DFTOdpovedLabel3A,"interpreter","latex");
        %     set(app.DFTOdpovedLabel3B,"interpreter","latex");
        %     set(app.DFTOdpovedLabel3C,"interpreter","latex");
        %     set(app.DFTOdpovedLabel3D,"interpreter","latex");
        %     %konvKor
        %     set(app.KonvKorOdpovedLabel1A,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel1B,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel1C,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel1D,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel2A,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel2B,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel2C,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel2D,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel3A,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel3B,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel3C,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel3D,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel4A,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel4B,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel4D,"interpreter","latex");
        %     set(app.KonvKorOdpovedLabel4C,"interpreter","latex");
        %     %stoch. signaly
        %     set(app.StochSignalOdpovedLabel2A,"interpreter","latex");
        %     set(app.StochSignalOdpovedLabel2B,"interpreter","latex");
        %     set(app.StochSignalOdpovedLabel2C,"interpreter","latex");
        %     set(app.StochSignalOdpovedLabel2D,"interpreter","latex");
        %     set(app.StochSignalOdpovedLabel1A,"interpreter","latex");
        % end
        %% dalsieUlohy method
        function dalsieUlohy(app,htmlSourcePath)
            novyFigure = uifigure("Name","Doplnkový príklad");
            novyFigure.Position = [50 50 800 600];
            grid = uigridlayout(novyFigure);
            grid.RowHeight = {"1x"};
            grid.ColumnWidth = {"1x"};
            grid.BackgroundColor = [1,1,1];
            html = uihtml(grid);
            html.HTMLSource = htmlSourcePath;
        end

        %% setter method
        % nastavi veci ktore sa nedaju nastavit v APP designeri iba
        % "manualne"
        function setter(app)
            %sinusovy signal kapitola: "Rozdelenie Signalov"
            app.SignalyPeriodaSlider.MajorTickLabels = app.SignalyPeriodaSlider.MajorTicks + "π";
            app.SignalyFazaSlider.MajorTickLabels = "+"+app.SignalyFazaSlider.MajorTicks+ "°";
            app.SignalyPeriodaSlider.MajorTickLabels = app.SignalyPeriodaSlider.MajorTicks + "x";
            %app.SignalyCasTrvaniaSlider.MajorTickLabels = app.SignalyCasTrvaniaSlider.MajorTicks + "π[s]";
            %FR kapitola: "FR"
            %slider ticksLabel
            array = 1:2:49;
            app.FurRadInteraktivnaUkazkaSlider.MajorTickLabels = array + "";
        end
        
        %% runLinkoveKody method
        function runLinkoveKody(app,type)
            bitovePole  = app.LinkoveKodyBitovePoleEditField.Value;   
          
            [casovyVektor,hodnoty,titleString,error,dlzka] = linkoveKody(type,bitovePole);
            
            if error
                app.LinkoveKodyChybLabel.Visible = "on";
               
            else
                plot(app.LinkoveKodyUkazkyUIAxes,casovyVektor,hodnoty,"LineWidth",2);
                yline(app.LinkoveKodyUkazkyUIAxes,0,"LineWidth",1.5,"Color","k");
                app.LinkoveKodyUkazkyUIAxes.Title.String = titleString;
                app.LinkoveKodyUkazkyUIAxes.Title.Color = "#0072BD";
                app.LinkoveKodyUkazkyUIAxes.Title.FontSize = 16;
                app.LinkoveKodyChybLabel.Visible = "off";
                grid(app.LinkoveKodyUkazkyUIAxes,"on")
                app.LinkoveKodyUkazkyUIAxes.XTick = 1:1:dlzka;
                app.LinkoveKodyUkazkyUIAxes.YTick = [-1,0,1];
                app.LinkoveKodyUkazkyUIAxes.YTickLabel = ["-A","0","A"];
            end
            
           
        end
        %% PRSGeneratorPhotoSlider method 
        %bude posuvat obrazky do prava resp. do lava
        function PRSGeneratorPhotoSlider(app,doPrava,doLava)
            app.PRSGenUIAxes.Visible = "on";
            
            if doPrava
                %pripocitaj 
                app.PRSpocitadlo = app.PRSpocitadlo+1;
                % kontroluj 
                if app.PRSpocitadlo > 16
                    app.PRSpocitadlo = 0;
                end

            elseif doLava
                %nesmie ist do zapornych a ak odpocitaj
                if app.PRSpocitadlo > 0
                    app.PRSpocitadlo = app.PRSpocitadlo-1;
                end

            end
            %zmenine cestu podla toho co vyjde z if-ov
            app.PRSGenImage.ImageSource = "kapitoly/psuedonahodna_postupnost/img/"+app.PRSpocitadlo+".png";
              
            %vykreslenie 
            if app.PRSpocitadlo < 16
                xt = [1,1,1,1,0,0,0,1,0,0,1,1,0,1,0,0];
                t = 0:length(xt);
                stairs(app.PRSGenUIAxes,t(1:app.PRSpocitadlo+1),xt(1:app.PRSpocitadlo+1),"Color","y","LineWidth",2);
                app.PRSGenUIAxes.XTick = 0:1:length(xt);
                
            end 
        end
        %% testSetter nove 25.1.2024
        %spravi mi z checkboxu radio button že bude moct iba jedno kliknuť
        %relativne komplikovane ale funguje to 
        function testSetter(app,arrayPanelov)
            % na vstup ide pole panelov ktore chceme
            for i = 1:length(arrayPanelov) % lenže tam je este grid takže ešte z neho
               deti = arrayPanelov(i).Children.Children; % ziskavam deti z parenta panel
               %deti= grid.Children; % lenže tam je este grid takže ešte z neho
               
                for j = 1:length(deti)
                    % if is instance of %% to iste % ako v javne
                    if convertCharsToStrings(class(deti(j))) == "matlab.ui.control.CheckBox"
                        deti(j).ValueChangedFcn = @checkBoxToRadioButton;
                    end
                end
            end
            %inner function 
            % vytvaram "CallBack" na tie CheckBoxy takto 
            function checkBoxToRadioButton(checkBoxObj,~) %prve mi dava object a druhe je event
                % zase ziskavam deti lebo sa neviem k nim dostat
                detiLocal = checkBoxObj.Parent.Children;
                for k = 1:length(detiLocal)
                    %nasledne kontrola lebo tam nemam iba CheckBoxy ale aj
                    %Labely,... 
                    if convertCharsToStrings(class(detiLocal(k))) == "matlab.ui.control.CheckBox"
                        detiLocal(k).Value = 0;
                    end
                end
                checkBoxObj.Value = 1;
            end
        end

        %% testPismoResize
         % ked zvascim aby mi zvascilo
         function testPismoResize(app,arrayPanelov,size)
            % na vstup ide pole panelov ktore chceme
            for i = 1:length(arrayPanelov)
                deti  = arrayPanelov(i).Children.Children;
                for k = 1:length(deti)
                    deti(k).FontSize = size;
                     deti(k).FontWeight = "bold";
                    if deti(k).FontColor == [0,0.4471,0.7412]
                        deti(k).FontSize = size+5;
                    end
                end
                
            end
        end
        %% 
         function odpoved = checkAnswerPanel(app,panel)
            najdene = "";
            deti = panel.Children.Children;
            for i = 1:length(deti)
                % hladam ak je instancia CheckBoxu
                instance = convertCharsToStrings(class(deti(i)));
                if  instance == "matlab.ui.control.CheckBox" && deti(i).Value == 1
                    najdene = deti(i).Text; %% to je return
                end
            end
            % kontrola ak nič nezačiarkneš tak pošleš X akože nič alebo že
            % automaticky zle
            if najdene ~= ""
                odpoved = najdene;
            else
                odpoved = "X"; 
            end
        end
        %%

        function CheckTestAnswers(app,polePanelov,spravneOdpovede)
            % toto budeš musieť upraviť ak bude viacej odpoveď
            % a spravne poradie musis davať v tom poradi ako davaš panely
            % na vstup funkcie
            pocitadlo = 0;
            %ziskavam odpovede
            for i = 1:length(polePanelov)
               if checkAnswerPanel(app,polePanelov(i)) == spravneOdpovede(i) % ak sa rovnaju
                   % inkrementujem
                   pocitadlo = pocitadlo + 1;
               end

            end
            % Vypočitavam spravnu odpoveď   
            result = pocitadlo/length(spravneOdpovede)*100;
            uiwait(msgbox("Tvoj výsledok je: "+result+"%","modal"));
        end
        %%
        function disableTestButtons(app,arrayPanelov,state)
            if state
                for i = 1:length(arrayPanelov) % lenže tam je este grid takže ešte z neho
                    deti = arrayPanelov(i).Children.Children; % ziskavam deti z parenta panel
                    for j = 1:length(deti)
                        instance = convertCharsToStrings(class(deti(j)));
                        if  instance == "matlab.ui.control.CheckBox"
                            deti(j).Value = 0;
                            deti(j).Enable = "off";
                        end
                    end
                end
            else
                for i = 1:length(arrayPanelov) % lenže tam je este grid takže ešte z neho
                    deti = arrayPanelov(i).Children.Children; % ziskavam deti z parenta panel
                    for j = 1:length(deti)
                        instance = convertCharsToStrings(class(deti(j)));
                        if  instance == "matlab.ui.control.CheckBox"
                            deti(j).Enable = "on";
                        end
                    end
                end
            end
        end
      
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% test checkbox2RadioButtonGroup
            testSetter(app,[app.KomplexO1Panel,app.KomplexO2Panel,app.KomplexO3Panel,app.KomplexO4Panel]);
            testSetter(app,[app.KomplexO1Panel_2,app.KomplexO2Panel_2,app.KomplexO3Panel_2,app.KomplexO4Panel_2]);
            testSetter(app,[app.KomplexO1Panel_3,app.KomplexO2Panel_3,app.KomplexO3Panel_3,app.KomplexO4Panel_3]);
            testSetter(app,[app.KomplexO1Panel_4,app.KomplexO2Panel_4,app.KomplexO3Panel_4,app.KomplexO4Panel_4]);
            testSetter(app,[app.KomplexO1Panel_5,app.KomplexO2Panel_5,app.KomplexO3Panel_5,app.KomplexO4Panel_5]);
            testSetter(app,[app.KomplexO1Panel_6,app.KomplexO2Panel_6,app.KomplexO3Panel_6,app.KomplexO4Panel_6]);
            testSetter(app,[app.KomplexO1Panel_7,app.KomplexO2Panel_7,app.KomplexO3Panel_7,app.KomplexO4Panel_7]);
            testSetter(app,[app.KomplexO1Panel_8,app.KomplexO2Panel_8,app.KomplexO3Panel_8,app.KomplexO4Panel_8]);
            testSetter(app,[app.KomplexO1Panel_9,app.KomplexO2Panel_9,app.KomplexO3Panel_9,app.KomplexO4Panel_9]);
            testSetter(app,[app.KomplexO1Panel_10,app.KomplexO2Panel_10,app.KomplexO3Panel_10,app.KomplexO4Panel_10]); 
            %% 
            % len inicializacia lebo nejde volať metody v "atributoch"

            app.RootTabGroup.SelectedTab = app.CreditsTab;
            pause(5);
            app.RootTabGroup.SelectedTab = app.MenuMainTab;

            %nacitam hodnoty z suboru %neexistuje konstruktor preto tu 
            app.stacionarnySignalValues = readFromFile(app,"kapitoly/stochasticke_signaly/values/stacionar.txt","%f");
            app.nonStacionarnySignalValues = readFromFile(app,"kapitoly/stochasticke_signaly/values/non_stacionar.txt","%f");

            %loading popup window
            %uialert(app.UIFigure,"Aplikácia potrebuje chvíľu na načítanie, prosím, počkajte niekoľko sekúnd, kým sa aplikácia načíta.","Info","Icon","info");
            % %nastavujem labely na format Textu ->Latex
            %setLatexWriting(app);
            %vykresli pri starte sinusový signál
            plotUpdateSin(app,1,0,1);
            %setter veci ktore defaulte nejdeu nastaviť v GUIDE
            setter(app);
            %FR interktivny
            plotUpdateFR(app,1)
            %FT interktivnky
            plotUpdateFTtimeScaling(app,0.815);
            %stoch. signaly interaktivne
            plotUpdateStacionarny(app,101,301)
            plotUpdateNonStacionarny(app,101,301)
            
            
        end

        % Value changing function: KomplexneSucetEditField
        function KomplexneSucetEditFieldValueChanging(app, event)
            changingValue = event.Value;
            possibleAns = ["-2+2j","-2+2i"];
            checkTextField(app,changingValue,possibleAns,app.KomplexneSucetEditField);
        end

        % Value changing function: KomplexneRozdielEditField
        function KomplexneRozdielEditFieldValueChanging(app, event)
            changingValue = event.Value;
            possibleAns = "-4";
            checkTextField(app,changingValue,possibleAns,app.KomplexneRozdielEditField);
        end

        % Value changing function: KomplexnePodielEditField
        function KomplexnePodielEditFieldValueChanging(app, event)
            changingValue = event.Value;
            possibleAns = ["-1+2j","-1+2i"];
            checkTextField(app,changingValue,possibleAns,app.KomplexnePodielEditField);
        end

        % Value changing function: KomplexneSucinEditField
        function KomplexneSucinEditFieldValueChanging(app, event)
            changingValue = event.Value;
            possibleAns = ["-4-2j","-4-2i"];
            checkTextField(app,changingValue,possibleAns,app.KomplexneSucinEditField);
        end

        % Value changing function: KomplexneGon2AlgVysledok
        function KomplexneGon2AlgVysledokValueChanging(app, event)
            changingValue = event.Value;
            possibleAns = ["-2+2j","-2+2i"];
            checkTextField(app,changingValue,possibleAns,app.KomplexneGon2AlgVysledok);
        end

        % Value changing function: KomplexneMocninaEditField
        function KomplexneMocninaEditFieldValueChanging(app, event)
            changingValue = event.Value;
            possibleAns = ["8j","8i"];
            checkTextField(app,changingValue,possibleAns,app.KomplexneMocninaEditField);
        end

        % Value changing function: KomplexneExp2AlgEditField
        function KomplexneExp2AlgEditFieldValueChanging(app, event)
            changingValue = event.Value;
            possibleAns = ["4j","4i"];
            checkTextField(app,changingValue,possibleAns,app.KomplexneExp2AlgEditField);
        end

        % Selection changed function: KomplexnePrikladyButtonGroup2
        function KomplexnePrikladyButtonGroup2SelectionChanged(app, event)
            oldButtom = event.OldValue; % takto ziskam posledne stlacene
            newButton = app.KomplexnePrikladyButtonGroup2.SelectedObject;
            checkRadioAnswers(app,oldButtom,newButton,"A");
        end

        % Selection changed function: KomplexnePrikladyButtonGroup1
        function KomplexnePrikladyButtonGroup1SelectionChanged(app, event)
            oldButtom = event.OldValue; % takto ziskam posledne stlacene
            newButton = app.KomplexnePrikladyButtonGroup1.SelectedObject;
            checkRadioAnswers(app,oldButtom,newButton,"C");
        end

        % Button pushed function: KomplexneRunCodeButton
        function KomplexneRunCodeButtonPushed(app, event)
            runCodeFromHTML(app,app.KomplexneCodeAreaHTML,app.KomplexneKonzolaTextArea,"app.KomplexneUIAxes");
        end

        % Button pushed function: KomplexneShowAnswerButton
        function KomplexneShowAnswerButtonPushed(app, event)
            app.KomplexneCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_Komplexne_with_answer.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.KomplexneCodeAreaHTML,app.KomplexneKonzolaTextArea,"app.KomplexneUIAxes");
        end

        % Button pushed function: KomplexneRestartPrikladyButton
        function KomplexneRestartPrikladyButtonPushed(app, event)
            app.KomplexneCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            runCode(app,"",app.KomplexneKonzolaTextArea,"app.KomplexneUIAxes");
        end

        % Button pushed function: SignalyRunCodeU1Button
        function SignalyRunCodeU1ButtonPushed(app, event)
            runCodeFromHTML(app,app.SignalyCodeAreaU1HTML,"","");
        end

        % Button pushed function: SignalyRunCodeU2Button
        function SignalyRunCodeU2ButtonPushed(app, event)
            runCodeFromHTML(app,app.SignalyCodeAreaU2HTML,"","");
        end

        % Button pushed function: SignalyRunCodeU3Button
        function SignalyRunCodeU3ButtonPushed(app, event)
            runCodeFromHTML(app,app.SignalyCodeAreaU3HTML,"","");
        end

        % Button pushed function: SignalyRunCodeU4Button
        function SignalyRunCodeU4ButtonPushed(app, event)
            runCodeFromHTML(app,app.SignalyCodeAreaU4HTML,"","");
        end

        % Button pushed function: SignalyShowanswersButton
        function SignalyShowanswersButtonPushed(app, event)
            app.SignalyCodeAreaU1HTML.HTMLSource = "codeArea_HTML/CodeArea_Signaly_w_answer_U1.html";
            app.SignalyCodeAreaU2HTML.HTMLSource = "codeArea_HTML/CodeArea_Signaly_w_answer_U2.html";
            app.SignalyCodeAreaU3HTML.HTMLSource = "codeArea_HTML/CodeArea_Signaly_w_answer_U3.html";
            app.SignalyCodeAreaU4HTML.HTMLSource = "codeArea_HTML/CodeArea_Signaly_w_answer_U4.html";
            %tu nebude asi moc dobre
            %pauza aby nacitalo zdroj HTML matlab potrebuje
            % pause(0.1);
            % runCodeFromHTML(app,app.SignalyCodeAreaU1HTML,"","");
            % runCodeFromHTML(app,app.SignalyCodeAreaU2HTML,"","");
            % runCodeFromHTML(app,app.SignalyCodeAreaU3HTML,"","");
            % runCodeFromHTML(app,app.SignalyCodeAreaU4HTML,"","");
        end

        % Button pushed function: SignalyRestartButton
        function SignalyRestartButtonPushed(app, event)
            close all % zavrem vsetky figure
            app.SignalyCodeAreaU1HTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            app.SignalyCodeAreaU2HTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            app.SignalyCodeAreaU3HTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            app.SignalyCodeAreaU4HTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
        end

        % Button pushed function: MenuMatlabPrikazyButton
        function MenuMatlabPrikazyButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.MATLABprikazyMainTab;
        end

        % Button pushed function: MenuKomplexneButton
        function MenuKomplexneButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.KomplexneMainTab;
        end

        % Button pushed function: MenuSignalyButton
        function MenuSignalyButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.SignalyMainTab;
        end

        % Callback function: SignalyDalsiePrikladyHyperlink
        function SignalyDalsiePrikladyHyperlinkClicked(app, event)
            % novyFigure = uifigure('Position',[50 50 800 600]);
            % grid = uigridlayout(novyFigure);
            % grid.RowHeight = {"1x"};
            % grid.ColumnWidth = {"1x"};
            % grid.BackgroundColor = [1,1,1];
            % html = uihtml(grid);
            % html.HTMLSource ="kapitoly/rozdelenie_signalov/HTML/uloha5_6_7.html";
            dalsieUlohy(app,"kapitoly/rozdelenie_signalov/HTML/uloha5_6_7.html")
        end

        % Button pushed function: FurRadRunCodeRiesenyPrikladButton
        function FurRadRunCodeRiesenyPrikladButtonPushed(app, event)
            runCodeFromHTML(app,app.FurRadRiesenyPrikladCodeAreaHTML,"","app.FurRadRiesenyPrikladUIAxes");
        end

        % Button pushed function: FurRadRunCodeUlohaButton
        function FurRadRunCodeUlohaButtonPushed(app, event)
            runCodeFromHTML(app,app.FurRadUlohaCodeAreaHTML,app.FurRadKonzolaTextArea,"app.FurRadUlohaUIAxes");
        end

        % Button pushed function: FurRadShowAnswerButton
        function FurRadShowAnswerButtonPushed(app, event)
            app.FurRadUlohaCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_FurRad_Uloha_w_answer.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.FurRadUlohaCodeAreaHTML,app.FurRadKonzolaTextArea,"app.FurRadUlohaUIAxes");
            % nastavujem label na viditelny
            app.FurRadAnswerLabel.Visible = "on";
        end

        % Button pushed function: FurRadRestartButton
        function FurRadRestartButtonPushed(app, event)
            app.FurRadUlohaCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            runCode(app,"",app.FurRadKonzolaTextArea,"app.FurRadUlohaUIAxes");
            app.FurRadAnswerLabel.Visible = "off";
        end

        % Button pushed function: FourierovradButton
        function FourierovradButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.FurRadMainTab;
        end

        % Button pushed function: FurTransRunCodeRiesenyPrikladButton
        function FurTransRunCodeRiesenyPrikladButtonPushed(app, event)
            runCodeFromHTML(app,app.FurTransRiesenyPrikladCodeAreaHTML,"","");
        end

        % Button pushed function: FurTransShowAnswerButton
        function FurTransShowAnswerButtonPushed(app, event)
            app.FurTransUlohaCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_FurTrans_Uloha_w_answer.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.FurTransUlohaCodeAreaHTML,app.FurTransKonzolaTextArea,"");
            % nastavujem label na viditelny
            app.FurTransAnswerLabel.Visible = "on";
        end

        % Button pushed function: FurTransRestartButton
        function FurTransRestartButtonPushed(app, event)
            app.FurTransUlohaCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            runCode(app,"",app.FurTransKonzolaTextArea,"");
            app.FurTransAnswerLabel.Visible = "off";
        end

        % Button pushed function: FurTransRunCodeUlohaButton
        function FurTransRunCodeUlohaButtonPushed(app, event)
            runCodeFromHTML(app,app.FurTransUlohaCodeAreaHTML,app.FurTransKonzolaTextArea,"");
        end

        % Button pushed function: FurTrans
        function FurTransButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.FurTransMainTab;
        end

        % Button pushed function: DFTCodeRiesenyPrikladButton
        function DFTCodeRiesenyPrikladButtonPushed(app, event)
            runCodeFromHTML(app,app.DFTRiesenyPrikladCodeAreaHTML,app.DFTRiesenyPrikladKonzolaTextArea,"");
        end

        % Button pushed function: DFTShowAnswerButton
        function DFTShowAnswerButtonPushed(app, event)
            app.DFTUlohaCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_DFT_Uloha_w_answer.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.DFTUlohaCodeAreaHTML,app.DFTKonzolaTextArea,"");
        end

        % Button pushed function: DFTRestartButton
        function DFTRestartButtonPushed(app, event)
            app.DFTUlohaCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            runCode(app,"",app.DFTKonzolaTextArea,"");
        end

        % Button pushed function: DFTRunCodeUlohaButton
        function DFTRunCodeUlohaButtonPushed(app, event)
            runCodeFromHTML(app,app.DFTUlohaCodeAreaHTML,app.DFTKonzolaTextArea,"");
        end

        % Button pushed function: DiskrtnaFourierovtransformciaButton
        function DiskrtnaFourierovtransformciaButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.DFTMainTab;
        end

        % Button pushed function: KonvKorCodeRiesenyPrikladButton
        function KonvKorCodeRiesenyPrikladButtonPushed(app, event)
            runCodeFromHTML(app,app.KonvKorRiesenyPrikladCodeAreaHTML,app.KonvKorRiesenyPrikladKonzolaTextArea,"");
        end

        % Button pushed function: KonvKorShowAnswerButtonKonv
        function KonvKorShowAnswerButtonKonvPushed(app, event)
            app.KonvKorUlohaCodeAreaHTMLKonv.HTMLSource = "codeArea_HTML/CodeArea_KonvKor_w_answer_konv.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.KonvKorUlohaCodeAreaHTMLKonv,app.KonvKorKonzolaTextAreaKonv,"");
        end

        % Button pushed function: KonvKorRestartButtonKonv
        function KonvKorRestartButtonKonvPushed(app, event)
            app.KonvKorUlohaCodeAreaHTMLKonv.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            runCode(app,"",app.KonvKorKonzolaTextAreaKonv,"");
        end

        % Button pushed function: KonvKorRunCodeUlohaButtonKonv
        function KonvKorRunCodeUlohaButtonKonvPushed(app, event)
            runCodeFromHTML(app,app.KonvKorUlohaCodeAreaHTMLKonv,app.KonvKorKonzolaTextAreaKonv,"");
        end

        % Button pushed function: KonvKorShowAnswerButtonKor
        function KonvKorShowAnswerButtonKorPushed(app, event)
            app.KonvKorUlohaCodeAreaHTMLKor.HTMLSource = "codeArea_HTML/CodeArea_KonvKor_w_answer_kor.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.KonvKorUlohaCodeAreaHTMLKor,app.KonvKorKonzolaTextAreaKor,"");
        end

        % Button pushed function: KonvKorRestartButtonKor
        function KonvKorRestartButtonKorPushed(app, event)
            app.KonvKorUlohaCodeAreaHTMLKor.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            runCode(app,"",app.KonvKorKonzolaTextAreaKor,"");
        end

        % Button pushed function: KonvKorRunCodeUlohaButtonKor
        function KonvKorRunCodeUlohaButtonKorPushed(app, event)
            runCodeFromHTML(app,app.KonvKorUlohaCodeAreaHTMLKor,app.KonvKorKonzolaTextAreaKor,"");
        end

        % Button pushed function: KorelaciaAkonvoluciaButton
        function KorelaciaAkonvoluciaButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.KonvKorMainTab;
        end

        % Value changed function: SignalyAmplitudaSlider
        function SignalyAmplitudaSliderValueChanged(app, event)
            %pri zmene ziskavam aktualne hodnoty vsetkeho
            amplitudaValue = app.SignalyAmplitudaSlider.Value;
            periodaValue = app.SignalyPeriodaSlider.Value;
            fazaValue = app.SignalyFazaSlider.Value;
            %casTrvaniaValue = app.SignalyCasTrvaniaSlider.Value;
            plotUpdateSin(app,amplitudaValue,fazaValue,periodaValue);
        end

        % Value changed function: SignalyPeriodaSlider
        function SignalyPeriodaSliderValueChanged(app, event)
            amplitudaValue = app.SignalyAmplitudaSlider.Value; % zmenena
            periodaValue = app.SignalyPeriodaSlider.Value;
            fazaValue = app.SignalyFazaSlider.Value;
            %casTrvaniaValue = app.SignalyCasTrvaniaSlider.Value;
            plotUpdateSin(app,amplitudaValue,fazaValue,periodaValue);
        end

        % Value changed function: SignalyFazaSlider
        function SignalyFazaSliderValueChanged(app, event)
            amplitudaValue = app.SignalyAmplitudaSlider.Value; % zmenena
            periodaValue = app.SignalyPeriodaSlider.Value;
            fazaValue = app.SignalyFazaSlider.Value;
            %casTrvaniaValue = app.SignalyCasTrvaniaSlider.Value;
            plotUpdateSin(app,amplitudaValue,fazaValue,periodaValue);

        end

        % Value changed function: FurRadInteraktivnaUkazkaSlider
        function FurRadInteraktivnaUkazkaSliderValueChanged(app, event)
            pocetHarmonickych = app.FurRadInteraktivnaUkazkaSlider.Value;
            plotUpdateFR(app,pocetHarmonickych);
            %app.FurRadInteraktivnaUkazkaSlider.Value = sliderValue;
        end

        % Value changed function: FurTransInteraktivnaUkazkaSlider
        function FurTransInteraktivnaUkazkaSliderValueChanged(app, event)
            value = app.FurTransInteraktivnaUkazkaSlider.Value;
            plotUpdateFTtimeScaling(app,value);
        end

        % Value changed function: StochSignalyInteraktivnaUkazkaT1Slider
        function StochSignalyInteraktivnaUkazkaT1SliderValueChanged(app, event)
            
            T1Value = app.StochSignalyInteraktivnaUkazkaT1Slider.Value;
            T2Value = app.StochSignalyInteraktivnaUkazkaT2Slider.Value;
            plotUpdateStacionarny(app,round(T1Value),round(T2Value));

        end

        % Value changed function: StochSignalyInteraktivnaUkazkaT2Slider
        function StochSignalyInteraktivnaUkazkaT2SliderValueChanged(app, event)
            
            T1Value = app.StochSignalyInteraktivnaUkazkaT1Slider.Value;
            T2Value = app.StochSignalyInteraktivnaUkazkaT2Slider.Value;
            plotUpdateStacionarny(app,round(T1Value),round(T2Value));
        end

        % Value changed function: StochSignalyInteraktivnaUkazkaT1Slider_2
        function StochSignalyInteraktivnaUkazkaT1Slider_2ValueChanged(app, event)
            
            T1Value = app.StochSignalyInteraktivnaUkazkaT1Slider_2.Value;
            T2Value = app.StochSignalyInteraktivnaUkazkaT2Slider_2.Value;
            plotUpdateNonStacionarny(app,round(T1Value),round(T2Value));
        end

        % Value changed function: StochSignalyInteraktivnaUkazkaT2Slider_2
        function StochSignalyInteraktivnaUkazkaT2Slider_2ValueChanged(app, event)
            T1Value = app.StochSignalyInteraktivnaUkazkaT1Slider_2.Value;
            T2Value = app.StochSignalyInteraktivnaUkazkaT2Slider_2.Value;
            plotUpdateNonStacionarny(app,round(T1Value),round(T2Value));
        end

        % Button pushed function: StochSignalyRiesenyRunCodeUlohaButton
        function StochSignalyRiesenyRunCodeUlohaButtonPushed(app, event)
            runCodeFromHTML(app,app.StochSignalyRiesenyCodeAreaHTML,app.StochSignalyRiesenyKonzolaTextArea,"app.StochSignalyRiesenyUIAxes");
        end

        % Button pushed function: StochSignalyNeriesenyRunCodeUlohaButton
        function StochSignalyNeriesenyRunCodeUlohaButtonPushed(app, event)
             runCodeFromHTML(app,app.StochSignalyNeriesenyCodeAreaHTML,app.StochSignalyNeriesenyKonzolaTextArea,"app.StochSignalyNeriesenyUIAxes");
        end

        % Button pushed function: StochSignalyNeriesenyShowAnswerButton
        function StochSignalyNeriesenyShowAnswerButtonPushed(app, event)
            app.StochSignalyNeriesenyCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_Stoch_w_answer.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.StochSignalyNeriesenyCodeAreaHTML,app.StochSignalyNeriesenyKonzolaTextArea,"app.StochSignalyNeriesenyUIAxes")
        end

        % Button pushed function: StochSignalyNeriesenyRestartButton
        function StochSignalyNeriesenyRestartButtonPushed(app, event)
            app.StochSignalyNeriesenyCodeAreaHTML.HTMLSource = "codeArea_HTML/CodeArea_Blank_for_stoch.html";
            runCode(app,"",app.StochSignalyNeriesenyKonzolaTextArea,"app.StochSignalyNeriesenyUIAxes");
        end

        % Callback function: FurTransDalsiePrikladyHyperlink
        function FurTransDalsiePrikladyHyperlinkClicked(app, event)
            dalsieUlohy(app,"kapitoly/fourierova_transformacia/HTML/dalsiPriklad.html");
        end

        % Callback function: FurRadDalsiePrikladyHyperlink
        function FurRadDalsiePrikladyHyperlinkClicked(app, event)
            dalsieUlohy(app,"kapitoly/fourierove_rady/HTML/dalsiPriklad.html");
        end

        % Callback function: DFTDalsiePrikladyHyperlink
        function DFTDalsiePrikladyHyperlinkClicked(app, event)
            dalsieUlohy(app,"kapitoly/diskretna_fourierova_transformacia/HTML/dalsiPriklad.html");
        end

        % Callback function: KonvKorKonvDalsiePrikladyHyperlink
        function KonvKorKonvDalsiePrikladyHyperlinkClicked(app, event)
            dalsieUlohy(app,"kapitoly/korelacia_konvolucia/HTML/dalsiPrikladKonv.html");
        end

        % Callback function: KonvKorKorDalsiePrikladyHyperlink
        function KonvKorKorDalsiePrikladyHyperlinkClicked(app, event)
            dalsieUlohy(app,"kapitoly/korelacia_konvolucia/HTML/dalsiPrikladKor.html");
        end

        % Button pushed function: StochastickeSignalyButton
        function StochastickeSignalyButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.StochSignalyMainTab;
        end

        % Size changed function: MenuMainTab
        function MenuMainTabSizeChanged(app, event)
            %bude mi resizeovať text v button
            %lebo button to nerobi sam
            position = app.MenuMainTab.Position;
            buttonArray = [app.MenuNavodButton,app.MenuMatlabPrikazyButton,app.MenuKomplexneButton,app.MenuSignalyButton,...
                app.FourierovradButton,app.FurTrans,app.DiskrtnaFourierovtransformciaButton,...
                app.KorelaciaAkonvoluciaButton,app.StochastickeSignalyButton,app.PRSButton,...
                app.ShannonButton,app.LinkoveKodyButton,app.FiltreButton];
        
          %disp(position(3))
            if position(3) >= 1300
                for i = buttonArray
                    i.FontSize = 16;
                end
            else
                 for i = buttonArray
                    i.FontSize = 12;
                end
            end
        end

        % Button pushed function: FTinteraktivnaObldznikButton
        function FTinteraktivnaObldznikButtonPushed(app, event)
            app.rectImpulseBool = true;
            app.gaussImpulseBool = false;
            app.triangleImpulseBool = false;
            hodnota = app.FurTransInteraktivnaUkazkaSlider.Value;
            plotUpdateFTtimeScaling(app,hodnota);

        end

        % Button pushed function: TrojuholnkovimpulzButton
        function TrojuholnkovimpulzButtonPushed(app, event)
            app.rectImpulseBool = false;
            app.gaussImpulseBool = false;
            app.triangleImpulseBool = true;
            hodnota = app.FurTransInteraktivnaUkazkaSlider.Value;
            plotUpdateFTtimeScaling(app,hodnota);
        end

        % Button pushed function: GaussovimpulzButton
        function GaussovimpulzButtonPushed(app, event)
             app.rectImpulseBool = false;
            app.gaussImpulseBool = true;
            app.triangleImpulseBool = false;
            hodnota = app.FurTransInteraktivnaUkazkaSlider.Value;
            plotUpdateFTtimeScaling(app,hodnota);
        end

        % Button pushed function: MenuNavodButton
        function MenuNavodButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.NavodMainTab;
        end

        % Button pushed function: PRSButton
        function PRSButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.PRSMainTab;
        end

        % Button pushed function: PRSKanalRunSimulationButton
        function PRSKanalRunSimulationButtonPushed(app, event)
            % nacitam
            %uialert(app.UIFigure,"Načítanie môže chvíľu trvať, prosím počkajte.","Info","Icon","info");
            % dekonstruktor z stur
            % BERValues = app.simOutBER.ScopeData.signals(1).values(20,1:3);
            % t = app.simOutBER.ScopeData.time;
            % xtVyslana = app.simOutBER.ScopeData.signals(2).values(1,:);
            % xtPrijata = app.simOutBER.ScopeData.signals(3).values(1,:);
            %docasne
            %xtVyslana = [1,1,1,1,0,0,0,1,0,0,1,1,0,1,0,0];
            app.asovpriebehUPNRZlinkovkdLabel_2.Visible = "on";
            xtVyslana = zeros(1,16);
            for i = 1:length(xtVyslana)
                 xtVyslana(i) = randi([0,1],1,1);
            end

            xtPrijata = xtVyslana;
            nahodnyPocet = randi([1,5],1,1);
            array = zeros(1,nahodnyPocet);
            
            for i = 1:nahodnyPocet
                array(i) = randi([1,15],1,1);
            end

            
            t = 1:16;
            for i = 1:nahodnyPocet  
                if xtPrijata(array(i)) == 0
                    xtPrijata(array(i)) = 1;
                else
                    xtPrijata(array(i)) = 0;
                end
            end
            
            pocitadlo = 0;
            for i = 1:16
                if xtVyslana(i) ~= xtPrijata(i)
                    pocitadlo = pocitadlo+1;
                end
            end
            
            % visibility na "on" 
            app.PRSVysKanalUIAxes.Visible = "on";
            app.PRSPrijKanalUIAxes.Visible = "on";
            app.BERLabel.Visible = "on";
            app.NtbLabel.Visible = "on";
            app.NeLabel.Visible = "on";
            app.BERNeNtbLabel.Visible = "on";

            %vypocet hodnot
            % app.NeLabel.Text = "Ne ="+BERValues(2);
            % app.NtbLabel.Text = "Ntb ="+BERValues(3);
            % app.BERLabel.Text = "BER ="+BERValues(1);
            app.NeLabel.Text = "Ne ="+pocitadlo;
            app.NtbLabel.Text = "Ntb = 16";
            app.BERLabel.Text = "BER ="+(pocitadlo/16);

            %plot % tu som to vymenil %xtVyslana <-> xtPrijata
                
            stairs(app.PRSVysKanalUIAxes,t,xtPrijata,"lineWidth",2);
            stairs(app.PRSPrijKanalUIAxes,t,xtVyslana,"lineWidth",2);
            app.PRSPrijKanalUIAxes.XTick = 1:16;
            app.PRSVysKanalUIAxes.XTick = 1:16;

            %app.simOutBER = sim("kapitoly\psuedonahodna_postupnost\simulink\kanal.slx");
            %do simulinku si dal nahodnu hodnotu do BSC %nezabuni to to je
            %ta chybovost kanala
        end

        % Value changed function: StochSignalySirkaSlider
        function StochSignalySirkaSliderValueChanged(app, event)
            T1Value = app.StochSignalyInteraktivnaUkazkaT1Slider.Value;
            T2Value = app.StochSignalyInteraktivnaUkazkaT2Slider.Value;
            plotUpdateStacionarny(app,round(T1Value),round(T2Value));
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            shanonNyquist(app,"<");
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            shanonNyquist(app,"=");
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
            shanonNyquist(app,">");
        end

        % Callback function: Hyperlink2
        function Hyperlink2Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_2
        function Hyperlink2_2Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_3
        function Hyperlink2_3Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_4
        function Hyperlink2_4Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_5
        function Hyperlink2_5Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_6
        function Hyperlink2_6Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_7
        function Hyperlink2_7Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_8
        function Hyperlink2_8Clicked(app, event)
            helpMethod(app);
        end

        % Button pushed function: UP_NRZButton
        function UP_NRZButtonPushed(app, event)
            runLinkoveKody(app,"UP_NRZ");
        end

        % Button pushed function: BP_NRZButton
        function BP_NRZButtonPushed(app, event)
            runLinkoveKody(app,"BP_NRZ");
        end

        % Button pushed function: UP_RZButton
        function UP_RZButtonPushed(app, event)
            runLinkoveKody(app,"UP_RZ");
        end

        % Button pushed function: BP_RZButton
        function BP_RZButtonPushed(app, event)
            runLinkoveKody(app,"BP_RZ");
        end

        % Button pushed function: AMI_NRZButton
        function AMI_NRZButtonPushed(app, event)
            runLinkoveKody(app,"AMI_NRZ");
        end

        % Button pushed function: AMI_RZButton
        function AMI_RZButtonPushed(app, event)
            runLinkoveKody(app,"AMI_RZ");
        end

        % Button pushed function: ManchesterButton
        function ManchesterButtonPushed(app, event)
            runLinkoveKody(app,"Manchester");
        end

        % Button pushed function: PRSGenDoPrava
        function PRSGenDoPravaPushed(app, event)
            PRSGeneratorPhotoSlider(app,true,false);
        end

        % Button pushed function: PRSGenDoLava
        function PRSGenDoLavaPushed(app, event)
            PRSGeneratorPhotoSlider(app,false,true)
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            %resize textu ked sa zmensi/zvasci %matlab nemá automaticke
            %%iba html element to dokaže % lebo html musis nastavit v
            %%css/js
            size = app.UIFigure.Position;
            arrayPanelLocal = [app.KomplexO1Panel,app.KomplexO2Panel,app.KomplexO3Panel,app.KomplexO4Panel,...
                app.KomplexO1Panel_2,app.KomplexO2Panel_2,app.KomplexO3Panel_2,app.KomplexO4Panel_2,...
                app.KomplexO1Panel_3,app.KomplexO2Panel_3,app.KomplexO3Panel_3,app.KomplexO4Panel_3,...
                app.KomplexO1Panel_4,app.KomplexO2Panel_4,app.KomplexO3Panel_4,app.KomplexO4Panel_4,...
                app.KomplexO1Panel_5,app.KomplexO2Panel_5,app.KomplexO3Panel_5,app.KomplexO4Panel_5,...
                app.KomplexO1Panel_6,app.KomplexO2Panel_6,app.KomplexO3Panel_6,app.KomplexO4Panel_6,...
                app.KomplexO1Panel_7,app.KomplexO2Panel_7,app.KomplexO3Panel_7,app.KomplexO4Panel_7...
                app.KomplexO1Panel_8,app.KomplexO2Panel_8,app.KomplexO3Panel_8,app.KomplexO4Panel_8,...
                app.KomplexO1Panel_9,app.KomplexO2Panel_9,app.KomplexO3Panel_9,app.KomplexO4Panel_9,...
                app.KomplexO1Panel_10,app.KomplexO2Panel_10,app.KomplexO3Panel_10,app.KomplexO4Panel_10...
                ];
            if size(3) >= 1400 %beriem velkosť
                testPismoResize(app,arrayPanelLocal,18);
            else
                testPismoResize(app,arrayPanelLocal,12);
            end
            
        end

        % Button pushed function: KomplSubmitButton
        function KomplSubmitButtonPushed(app, event)
            array = [app.KomplexO1Panel,app.KomplexO2Panel,app.KomplexO3Panel,app.KomplexO4Panel];
            CheckTestAnswers(app,array,["B)","B)","A)","C)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton.Visible = "off";
        end

        % Button pushed function: KomplRestartButton
        function KomplRestartButtonPushed(app, event)
            array = [app.KomplexO1Panel,app.KomplexO2Panel,app.KomplexO3Panel,app.KomplexO4Panel];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_2
        function KomplSubmitButton_2Pushed(app, event)
            array = [app.KomplexO1Panel_2,app.KomplexO2Panel_2,app.KomplexO3Panel_2,app.KomplexO4Panel_2];
            CheckTestAnswers(app,array,["C)","B)","C)","C)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_2.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_2
        function KomplRestartButton_2Pushed(app, event)
            array = [app.KomplexO1Panel_2,app.KomplexO2Panel_2,app.KomplexO3Panel_2,app.KomplexO4Panel_2];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_2.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_3
        function KomplSubmitButton_3Pushed(app, event)
            array = [app.KomplexO1Panel_3,app.KomplexO2Panel_3,app.KomplexO3Panel_3,app.KomplexO4Panel_3];
            CheckTestAnswers(app,array,["C)","B)","D)","A)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_3.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_3
        function KomplRestartButton_3Pushed(app, event)
            array = [app.KomplexO1Panel_3,app.KomplexO2Panel_3,app.KomplexO3Panel_3,app.KomplexO4Panel_3];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_3.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_4
        function KomplSubmitButton_4Pushed(app, event)
            array = [app.KomplexO1Panel_4,app.KomplexO2Panel_4,app.KomplexO3Panel_4,app.KomplexO4Panel_4];
            CheckTestAnswers(app,array,["B)","A)","C)","A)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_4.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_4
        function KomplRestartButton_4Pushed(app, event)
            array = [app.KomplexO1Panel_4,app.KomplexO2Panel_4,app.KomplexO3Panel_4,app.KomplexO4Panel_4];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_4.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_5
        function KomplSubmitButton_5Pushed(app, event)
            array = [app.KomplexO1Panel_5,app.KomplexO2Panel_5,app.KomplexO3Panel_5,app.KomplexO4Panel_5];
            CheckTestAnswers(app,array,["A)","D)","B)","B)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_5.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_5
        function KomplRestartButton_5Pushed(app, event)
            array = [app.KomplexO1Panel_5,app.KomplexO2Panel_5,app.KomplexO3Panel_5,app.KomplexO4Panel_5];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_5.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_6
        function KomplSubmitButton_6Pushed(app, event)
            array = [app.KomplexO1Panel_6,app.KomplexO2Panel_6,app.KomplexO3Panel_6,app.KomplexO4Panel_6];
            CheckTestAnswers(app,array,["A)","A)","A)","B)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_6.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_6
        function KomplRestartButton_6Pushed(app, event)
            array = [app.KomplexO1Panel_6,app.KomplexO2Panel_6,app.KomplexO3Panel_6,app.KomplexO4Panel_6];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_6.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_7
        function KomplSubmitButton_7Pushed(app, event)
            array = [app.KomplexO1Panel_7,app.KomplexO2Panel_7,app.KomplexO3Panel_7,app.KomplexO4Panel_7];
            CheckTestAnswers(app,array,["D)","C)","C)","B)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_7.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_7
        function KomplRestartButton_7Pushed(app, event)
            array = [app.KomplexO1Panel_7,app.KomplexO2Panel_7,app.KomplexO3Panel_7,app.KomplexO4Panel_7];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_7.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_8
        function KomplSubmitButton_8Pushed(app, event)
            array = [app.KomplexO1Panel_8,app.KomplexO2Panel_8,app.KomplexO3Panel_8,app.KomplexO4Panel_8];
            CheckTestAnswers(app,array,["D)","A)","A)","D)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_8.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_8
        function KomplRestartButton_8Pushed(app, event)
            array = [app.KomplexO1Panel_8,app.KomplexO2Panel_8,app.KomplexO3Panel_8,app.KomplexO4Panel_8];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_8.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_9
        function KomplSubmitButton_9Pushed(app, event)
            array = [app.KomplexO1Panel_9,app.KomplexO2Panel_9,app.KomplexO3Panel_9,app.KomplexO4Panel_9];
            CheckTestAnswers(app,array,["B)","B)","A)","D)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_9.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_9
        function KomplRestartButton_9Pushed(app, event)
            array = [app.KomplexO1Panel_9,app.KomplexO2Panel_9,app.KomplexO3Panel_9,app.KomplexO4Panel_9];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_9.Visible = "on";
        end

        % Button pushed function: KomplSubmitButton_10
        function KomplSubmitButton_10Pushed(app, event)
            array = [app.KomplexO1Panel_10,app.KomplexO2Panel_10,app.KomplexO3Panel_10,app.KomplexO4Panel_10];
            CheckTestAnswers(app,array,["B)","C)","A)","B)"]);
            disableTestButtons(app,array,true);
            app.KomplSubmitButton_10.Visible = "off";
        end

        % Button pushed function: KomplRestartButton_10
        function KomplRestartButton_10Pushed(app, event)
            array = [app.KomplexO1Panel_10,app.KomplexO2Panel_10,app.KomplexO3Panel_10,app.KomplexO4Panel_10];
            disableTestButtons(app,array,false);%toto mi aj zresetuje odpovede
            app.KomplSubmitButton_10.Visible = "on";
        end

        % Button pushed function: ShannonRunCodeUlohaButtonRiesena
        function ShannonRunCodeUlohaButtonRiesenaPushed(app, event)
            runCodeFromHTML(app,app.ShannonCodeAreaHTMLRieseny,app.ShannonKonzolaTextAreaRieseny,"");
        end

        % Button pushed function: KonvKorRunCodeUlohaButtonKonv_3
        function KonvKorRunCodeUlohaButtonKonv_3Pushed(app, event)
             runCodeFromHTML(app,app.ShannonCodeAreaHTMLNerieseny,app.ShannonKonzolaTextAreaNerieseny,"");
        end

        % Button pushed function: KonvKorRestartButtonKonv_3
        function KonvKorRestartButtonKonv_3Pushed(app, event)
            app.ShannonCodeAreaHTMLNerieseny.HTMLSource = "codeArea_HTML/CodeArea_Blank.html";
            runCode(app,"",app.ShannonKonzolaTextAreaNerieseny,"");
        end

        % Button pushed function: KonvKorShowAnswerButtonKonv_3
        function KonvKorShowAnswerButtonKonv_3Pushed(app, event)
            app.ShannonCodeAreaHTMLNerieseny.HTMLSource = "codeArea_HTML/CodeArea_Shannon_w_answer.html";
            pause(0.1); % je potrebne to pauznut !!! inak to nestihne nacitat
            runCodeFromHTML(app,app.ShannonCodeAreaHTMLNerieseny,app.ShannonKonzolaTextAreaNerieseny,"");
        end

        % Button pushed function: LinkoveKodyButton
        function LinkoveKodyButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.LinkoveKodyMainTab;
        end

        % Button pushed function: ShannonButton
        function ShannonButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.ShanonHartleyMainTab;
        end

        % Callback function: Hyperlink2_9
        function Hyperlink2_9Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_12
        function Hyperlink2_12Clicked(app, event)
            helpMethod(app);
        end

        % Callback function: Hyperlink2_13
        function Hyperlink2_13Clicked(app, event)
            dalsieUlohy(app,"kapitoly/filtre/riesenia.pdf");
        end

        % Button pushed function: KonvKorRunCodeUlohaButtonKonv_4
        function KonvKorRunCodeUlohaButtonKonv_4Pushed(app, event)
            runCodeFromHTML(app,app.FiltreCodeAreaHTML,app.FiltreKonzolaTextArea,"");
        end

        % Callback function: Hyperlink2_10
        function Hyperlink2_10Clicked(app, event)
            helpMethod(app);
        end

        % Button pushed function: FiltreButton
        function FiltreButtonPushed(app, event)
            app.RootTabGroup.SelectedTab = app.FiltreMainTab;
        end

        % Callback function: SignalyDalsiePrikladyHyperlink_2
        function SignalyDalsiePrikladyHyperlink_2Clicked(app, event)
             dalsieUlohy(app,"kapitoly/komplexne_cisla/HTML/prikladDoplnkovy.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayoutMain = uigridlayout(app.UIFigure);
            app.RootTabGroup = uitabgroup(app.GridLayoutMain);
            app.MenuMainTab = uitab(app.RootTabGroup);
            app.MenuMainTab.SizeChangedFcn = createCallbackFcn(app, @MenuMainTabSizeChanged, true);
            app.MenuMainTab.Title = 'Menu';
            app.GridLayoutMenu = uigridlayout(app.MenuMainTab);
            app.StochastickeSignalyButton = uibutton(app.GridLayoutMenu, 'push');
            app.StochastickeSignalyButton.ButtonPushedFcn = createCallbackFcn(app, @StochastickeSignalyButtonPushed, true);
            app.StochastickeSignalyButton.Text = 'Stochastické signály';
            app.MenuKomplexneButton = uibutton(app.GridLayoutMenu, 'push');
            app.MenuKomplexneButton.ButtonPushedFcn = createCallbackFcn(app, @MenuKomplexneButtonPushed, true);
            app.MenuKomplexneButton.Text = 'Komplexné čisla';
            app.MenuNapis = uilabel(app.GridLayoutMenu);
            app.MenuNapis.Text = 'Úvod do signálov a systémov';
            app.MenuSignalyButton = uibutton(app.GridLayoutMenu, 'push');
            app.MenuSignalyButton.ButtonPushedFcn = createCallbackFcn(app, @MenuSignalyButtonPushed, true);
            app.MenuSignalyButton.Text = 'Rozdelenie signálov';
            app.MenuMatlabPrikazyButton = uibutton(app.GridLayoutMenu, 'push');
            app.MenuMatlabPrikazyButton.ButtonPushedFcn = createCallbackFcn(app, @MenuMatlabPrikazyButtonPushed, true);
            app.MenuMatlabPrikazyButton.Text = 'MATLAB príkazy';
            app.KorelaciaAkonvoluciaButton = uibutton(app.GridLayoutMenu, 'push');
            app.KorelaciaAkonvoluciaButton.ButtonPushedFcn = createCallbackFcn(app, @KorelaciaAkonvoluciaButtonPushed, true);
            app.KorelaciaAkonvoluciaButton.Text = 'Korelácia a konvolúcia';
            app.DiskrtnaFourierovtransformciaButton = uibutton(app.GridLayoutMenu, 'push');
            app.DiskrtnaFourierovtransformciaButton.ButtonPushedFcn = createCallbackFcn(app, @DiskrtnaFourierovtransformciaButtonPushed, true);
            app.DiskrtnaFourierovtransformciaButton.Text = 'Diskrétna Fourierová transformácia';
            app.FurTrans = uibutton(app.GridLayoutMenu, 'push');
            app.FurTrans.ButtonPushedFcn = createCallbackFcn(app, @FurTransButtonPushed, true);
            app.FurTrans.Text = 'Fourierová transformacia';
            app.FourierovradButton = uibutton(app.GridLayoutMenu, 'push');
            app.FourierovradButton.ButtonPushedFcn = createCallbackFcn(app, @FourierovradButtonPushed, true);
            app.FourierovradButton.Text = 'Fourierov rad';
            app.Panel = uipanel(app.GridLayoutMenu);
            app.GridLayout24 = uigridlayout(app.Panel);
            app.imgMenuHTML1 = uihtml(app.GridLayout24);
            app.imgMenuHTML1.HTMLSource = 'kapitoly/menu/dirac.html';
            app.imgMenuHTML2 = uihtml(app.GridLayout24);
            app.imgMenuHTML2.HTMLSource = 'kapitoly/menu/heaviside.html';
            app.imgMenuHTML3 = uihtml(app.GridLayout24);
            app.imgMenuHTML3.HTMLSource = 'kapitoly/menu/diskretnySignal.html';
            app.imgMenuHTML4 = uihtml(app.GridLayout24);
            app.imgMenuHTML4.HTMLSource = 'kapitoly/menu/periodickySignal.html';
            app.imgMenuHTML5 = uihtml(app.GridLayout24);
            app.imgMenuHTML5.HTMLSource = 'kapitoly/menu/FR.html';
            app.imgMenuHTML6 = uihtml(app.GridLayout24);
            app.imgMenuHTML6.HTMLSource = 'kapitoly/menu/spektrumPeriodickeho.html';
            app.imgMenuHTML7 = uihtml(app.GridLayout24);
            app.imgMenuHTML7.HTMLSource = 'kapitoly/menu/rectImpulse.html';
            app.imgMenuHTML8 = uihtml(app.GridLayout24);
            app.imgMenuHTML8.HTMLSource = 'kapitoly/menu/sinc.html';
            app.imgMenuHTML9 = uihtml(app.GridLayout24);
            app.imgMenuHTML9.HTMLSource = 'kapitoly/menu/šum.html';
            app.MenuNavodButton = uibutton(app.GridLayoutMenu, 'push');
            app.MenuNavodButton.ButtonPushedFcn = createCallbackFcn(app, @MenuNavodButtonPushed, true);
            app.MenuNavodButton.Text = 'Návod';
            app.PRSButton = uibutton(app.GridLayoutMenu, 'push');
            app.PRSButton.ButtonPushedFcn = createCallbackFcn(app, @PRSButtonPushed, true);
            app.PRSButton.Text = 'Pseudonáhodná postupnosť';
            app.AdamPetrovi2023Label = uilabel(app.GridLayoutMenu);
            app.AdamPetrovi2023Label.Text = '© Adam Petrovič, 2023';
            app.LinkoveKodyButton = uibutton(app.GridLayoutMenu, 'push');
            app.LinkoveKodyButton.ButtonPushedFcn = createCallbackFcn(app, @LinkoveKodyButtonPushed, true);
            app.LinkoveKodyButton.Text = 'Linkové kódy';
            app.ShannonButton = uibutton(app.GridLayoutMenu, 'push');
            app.ShannonButton.ButtonPushedFcn = createCallbackFcn(app, @ShannonButtonPushed, true);
            app.ShannonButton.Text = 'Shannon – Hartley teorém ';
            app.FiltreButton = uibutton(app.GridLayoutMenu, 'push');
            app.FiltreButton.ButtonPushedFcn = createCallbackFcn(app, @FiltreButtonPushed, true);
            app.FiltreButton.Text = 'Doplnkové – frekvenčné filtre ';
            app.CreditsTab = uitab(app.RootTabGroup);
            app.CreditsTab.Title = 'Credits';
            app.GridLayout34 = uigridlayout(app.CreditsTab);
            app.MenuNapis_2 = uilabel(app.GridLayout34);
            app.MenuNapis_2.Text = 'Úvod do signálov a systémov GUI';
            app.Image33 = uiimage(app.GridLayout34);
            app.Image33.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'mainPage', 'FeitLogo.jpg');
            app.Image34 = uiimage(app.GridLayout34);
            app.Image34.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'mainPage', 'KMIKT.png');
            app.AdamPetroviLabel = uilabel(app.GridLayout34);
            app.AdamPetroviLabel.Text = 'Adam Petrovič';
            app.docIngPatrikKamencayPhDLabel = uilabel(app.GridLayout34);
            app.docIngPatrikKamencayPhDLabel.Text = 'doc. Ing. Patrik Kamencay, PhD.';
            app.profIngMilanDadoPhDLabel = uilabel(app.GridLayout34);
            app.profIngMilanDadoPhDLabel.Text = 'prof. Ing. Milan Dado, PhD.';
            app.BakalrskaprcaLabel = uilabel(app.GridLayout34);
            app.BakalrskaprcaLabel.Text = 'Bakalárska práca';
            app.AutorLabel = uilabel(app.GridLayout34);
            app.AutorLabel.Text = 'Autor:';
            app.VedciprceLabel = uilabel(app.GridLayout34);
            app.VedciprceLabel.Text = 'Vedúci práce: ';
            app.KonzultantprceLabel = uilabel(app.GridLayout34);
            app.KonzultantprceLabel.Text = 'Konzultant práce:';
            app.NavodMainTab = uitab(app.RootTabGroup);
            app.NavodMainTab.Title = 'Návod';
            app.GridLayout29 = uigridlayout(app.NavodMainTab);
            app.HTML2 = uihtml(app.GridLayout29);
            app.HTML2.HTMLSource = 'kapitoly/Navod.pdf';
            app.MATLABprikazyMainTab = uitab(app.RootTabGroup);
            app.MATLABprikazyMainTab.Title = 'MATLAB  príkazy';
            app.GridLayout11 = uigridlayout(app.MATLABprikazyMainTab);
            app.ZakladneFunkcieHTML = uihtml(app.GridLayout11);
            app.ZakladneFunkcieHTML.HTMLSource = 'kapitoly/Prikazy.pdf';
            app.KomplexneMainTab = uitab(app.RootTabGroup);
            app.KomplexneMainTab.Title = 'Komplexné čísla';
            app.GridKomCisla = uigridlayout(app.KomplexneMainTab);
            app.KomplexneTabGroup = uitabgroup(app.GridKomCisla);
            app.KomplexneTeoriaTab = uitab(app.KomplexneTabGroup);
            app.KomplexneTeoriaTab.Title = 'Teória';
            app.GridLayoutKomCislaTeoria = uigridlayout(app.KomplexneTeoriaTab);
            app.KomplexneTeoriaHMTL = uihtml(app.GridLayoutKomCislaTeoria);
            app.KomplexneTeoriaHMTL.HTMLSource = 'kapitoly/komplexne_cisla/HTML/teoria.html';
            app.TeoreticktestTab = uitab(app.KomplexneTabGroup);
            app.TeoreticktestTab.Title = 'Teoretický test';
            app.GridLayout38 = uigridlayout(app.TeoreticktestTab);
            app.KomplexO1Panel = uipanel(app.GridLayout38);
            app.KomplexO1Panel.Title = 'Otázka č.1';
            app.GridLayout39 = uigridlayout(app.KomplexO1Panel);
            app.ACheckBox = uicheckbox(app.GridLayout39);
            app.ACheckBox.Text = 'A)';
            app.BCheckBox = uicheckbox(app.GridLayout39);
            app.BCheckBox.Text = 'B)';
            app.CCheckBox = uicheckbox(app.GridLayout39);
            app.CCheckBox.Text = 'C)';
            app.DCheckBox = uicheckbox(app.GridLayout39);
            app.DCheckBox.Text = 'D)';
            app.Komplexnsloi2respj2jerovnLabel = uilabel(app.GridLayout39);
            app.Komplexnsloi2respj2jerovnLabel.Interpreter = 'latex';
            app.Komplexnsloi2respj2jerovnLabel.Text = 'Komplexné číslo $i^2$ resp. $j^2$ je rovné:';
            app.Label_14 = uilabel(app.GridLayout39);
            app.Label_14.Interpreter = 'latex';
            app.Label_14.Text = '0';
            app.Label_15 = uilabel(app.GridLayout39);
            app.Label_15.Interpreter = 'latex';
            app.Label_15.Text = '-1';
            app.jLabel_3 = uilabel(app.GridLayout39);
            app.jLabel_3.Interpreter = 'latex';
            app.jLabel_3.Text = 'j';
            app.Label_16 = uilabel(app.GridLayout39);
            app.Label_16.Interpreter = 'latex';
            app.Label_16.Text = '-2';
            app.KomplexO2Panel = uipanel(app.GridLayout38);
            app.KomplexO2Panel.Title = 'Otázka č.2';
            app.GridLayout39_2 = uigridlayout(app.KomplexO2Panel);
            app.ACheckBox_2 = uicheckbox(app.GridLayout39_2);
            app.ACheckBox_2.Text = 'A)';
            app.BCheckBox_2 = uicheckbox(app.GridLayout39_2);
            app.BCheckBox_2.Text = 'B)';
            app.CCheckBox_2 = uicheckbox(app.GridLayout39_2);
            app.CCheckBox_2.Text = 'C)';
            app.DCheckBox_2 = uicheckbox(app.GridLayout39_2);
            app.DCheckBox_2.Text = 'D)';
            app.Vakomtvarejezapsankomplexnslozsqrt2efracpi4Label = uilabel(app.GridLayout39_2);
            app.Vakomtvarejezapsankomplexnslozsqrt2efracpi4Label.Interpreter = 'latex';
            app.Vakomtvarejezapsankomplexnslozsqrt2efracpi4Label.Text = 'V akom tvare je zapísané komplexné číslo $z=\sqrt{2}e^{\frac{\pi }{4}}$?';
            app.GoniometricktvarLabel_3 = uilabel(app.GridLayout39_2);
            app.GoniometricktvarLabel_3.Interpreter = 'latex';
            app.GoniometricktvarLabel_3.Text = 'Goniometrický tvar';
            app.ExponencilnytvarLabel_3 = uilabel(app.GridLayout39_2);
            app.ExponencilnytvarLabel_3.Interpreter = 'latex';
            app.ExponencilnytvarLabel_3.Text = 'Exponenciálny tvar';
            app.AlgebricktvarLabel_3 = uilabel(app.GridLayout39_2);
            app.AlgebricktvarLabel_3.Interpreter = 'latex';
            app.AlgebricktvarLabel_3.Text = 'Algebrický tvar';
            app.DiskrtnytvarLabel_2 = uilabel(app.GridLayout39_2);
            app.DiskrtnytvarLabel_2.Interpreter = 'latex';
            app.DiskrtnytvarLabel_2.Text = 'Diskrétny tvar';
            app.KomplexO3Panel = uipanel(app.GridLayout38);
            app.KomplexO3Panel.Title = 'Otázka č.3';
            app.GridLayout39_3 = uigridlayout(app.KomplexO3Panel);
            app.ACheckBox_3 = uicheckbox(app.GridLayout39_3);
            app.ACheckBox_3.Text = 'A)';
            app.BCheckBox_3 = uicheckbox(app.GridLayout39_3);
            app.BCheckBox_3.Text = 'B)';
            app.CCheckBox_3 = uicheckbox(app.GridLayout39_3);
            app.CCheckBox_3.Text = 'C)';
            app.DCheckBox_3 = uicheckbox(app.GridLayout39_3);
            app.DCheckBox_3.Text = 'D)';
            app.AkjesprvnyzpisEulerovhovzahuLabel = uilabel(app.GridLayout39_3);
            app.AkjesprvnyzpisEulerovhovzahuLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisEulerovhovzahuLabel.Text = 'Aký je správny zápis Eulerovho vzťahu?';
            app.ejvarphicosleftvarphirightjsinleftvarphirightLabel = uilabel(app.GridLayout39_3);
            app.ejvarphicosleftvarphirightjsinleftvarphirightLabel.Interpreter = 'latex';
            app.ejvarphicosleftvarphirightjsinleftvarphirightLabel.Text = '$$e^{j\varphi } =\cos \left(\varphi \right)+j\sin \left(\varphi \right)$$';
            app.ejvarphicosleftvarphirightsinleftvarphirightLabel = uilabel(app.GridLayout39_3);
            app.ejvarphicosleftvarphirightsinleftvarphirightLabel.Interpreter = 'latex';
            app.ejvarphicosleftvarphirightsinleftvarphirightLabel.Text = '$$e^{j\varphi } =\cos \left(\varphi \right)+\sin \left(\varphi \right)$$';
            app.ejvarphicosleftvarphirightjsinleftvarphirightLabel_2 = uilabel(app.GridLayout39_3);
            app.ejvarphicosleftvarphirightjsinleftvarphirightLabel_2.Interpreter = 'latex';
            app.ejvarphicosleftvarphirightjsinleftvarphirightLabel_2.Text = '$$e^{j\varphi } =\cos \left(\varphi \right)-j\sin \left(\varphi \right)$$';
            app.ejvarphisinleftvarphirightjcosleftvarphirightLabel = uilabel(app.GridLayout39_3);
            app.ejvarphisinleftvarphirightjcosleftvarphirightLabel.Interpreter = 'latex';
            app.ejvarphisinleftvarphirightjcosleftvarphirightLabel.Text = '$$e^{j\varphi } =\sin \left(\varphi \right)+jcos\left(\varphi \right)\;$$';
            app.KomplexO4Panel = uipanel(app.GridLayout38);
            app.KomplexO4Panel.Title = 'Otázka č.4';
            app.GridLayout39_4 = uigridlayout(app.KomplexO4Panel);
            app.ACheckBox_4 = uicheckbox(app.GridLayout39_4);
            app.ACheckBox_4.Text = 'A)';
            app.BCheckBox_4 = uicheckbox(app.GridLayout39_4);
            app.BCheckBox_4.Text = 'B)';
            app.CCheckBox_4 = uicheckbox(app.GridLayout39_4);
            app.CCheckBox_4.Text = 'C)';
            app.DCheckBox_4 = uicheckbox(app.GridLayout39_4);
            app.DCheckBox_4.Text = 'D)';
            app.GoniometrickfunkciasinleftvarphirightjerovnLabel = uilabel(app.GridLayout39_4);
            app.GoniometrickfunkciasinleftvarphirightjerovnLabel.Interpreter = 'latex';
            app.GoniometrickfunkciasinleftvarphirightjerovnLabel.Text = 'Goniometrická funkcia $\sin \left(\varphi \right)$ je rovná:';
            app.sinleftvarphirightfracejvarphiejvarphi2Label = uilabel(app.GridLayout39_4);
            app.sinleftvarphirightfracejvarphiejvarphi2Label.Interpreter = 'latex';
            app.sinleftvarphirightfracejvarphiejvarphi2Label.Text = '$$\sin \left(\varphi \right)=\frac{e^{j\varphi } +e^{-j\varphi } }{2}$$ ';
            app.sinleftvarphirightfracejvarphiejvarphi2jLabel = uilabel(app.GridLayout39_4);
            app.sinleftvarphirightfracejvarphiejvarphi2jLabel.Interpreter = 'latex';
            app.sinleftvarphirightfracejvarphiejvarphi2jLabel.Text = '$$\sin \left(\varphi \right)=\frac{e^{j\varphi } +e^{-j\varphi } }{2j}$$ ';
            app.sinleftvarphirightfracejvarphiejvarphi2jLabel_2 = uilabel(app.GridLayout39_4);
            app.sinleftvarphirightfracejvarphiejvarphi2jLabel_2.Interpreter = 'latex';
            app.sinleftvarphirightfracejvarphiejvarphi2jLabel_2.Text = '$$\sin \left(\varphi \right)=\frac{e^{j\varphi } -e^{-j\varphi } }{2j}$$ ';
            app.sinleftvarphirightfracejvarphiejvarphi2Label_2 = uilabel(app.GridLayout39_4);
            app.sinleftvarphirightfracejvarphiejvarphi2Label_2.Interpreter = 'latex';
            app.sinleftvarphirightfracejvarphiejvarphi2Label_2.Text = '$$\sin \left(\varphi \right)=\frac{e^{-j\varphi } +e^{-j\varphi } }{2}$$ ';
            app.KomplRestartButton = uibutton(app.GridLayout38, 'push');
            app.KomplRestartButton.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButtonPushed, true);
            app.KomplRestartButton.Text = 'Restart';
            app.KomplSubmitButton = uibutton(app.GridLayout38, 'push');
            app.KomplSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButtonPushed, true);
            app.KomplSubmitButton.Text = 'Submit';
            app.KomplexneVyresienePrikladyTab = uitab(app.KomplexneTabGroup);
            app.KomplexneVyresienePrikladyTab.Title = 'Riešené príklady';
            app.GridLayoutKomCislaVysPriklad = uigridlayout(app.KomplexneVyresienePrikladyTab);
            app.KomplexneVyriesenePrikladyHTML = uihtml(app.GridLayoutKomCislaVysPriklad);
            app.KomplexneVyriesenePrikladyHTML.HTMLSource = 'kapitoly/komplexne_cisla/HTML/RiesenePriklady.html';
            app.KomplexnePrikladyTab = uitab(app.KomplexneTabGroup);
            app.KomplexnePrikladyTab.Title = 'Numerické príklady';
            app.GridLayout4 = uigridlayout(app.KomplexnePrikladyTab);
            app.suinEditFieldLabel = uilabel(app.GridLayout4);
            app.suinEditFieldLabel.Text = 'sučin:';
            app.KomplexneSucinEditField = uieditfield(app.GridLayout4, 'text');
            app.KomplexneSucinEditField.ValueChangingFcn = createCallbackFcn(app, @KomplexneSucinEditFieldValueChanging, true);
            app.rozdielEditFieldLabel = uilabel(app.GridLayout4);
            app.rozdielEditFieldLabel.Text = 'rozdiel:';
            app.KomplexneRozdielEditField = uieditfield(app.GridLayout4, 'text');
            app.KomplexneRozdielEditField.ValueChangingFcn = createCallbackFcn(app, @KomplexneRozdielEditFieldValueChanging, true);
            app.podielEditFieldLabel = uilabel(app.GridLayout4);
            app.podielEditFieldLabel.Text = 'podiel:';
            app.KomplexnePodielEditField = uieditfield(app.GridLayout4, 'text');
            app.KomplexnePodielEditField.ValueChangingFcn = createCallbackFcn(app, @KomplexnePodielEditFieldValueChanging, true);
            app.setEditFieldLabel = uilabel(app.GridLayout4);
            app.setEditFieldLabel.Text = 'súčet:';
            app.KomplexneSucetEditField = uieditfield(app.GridLayout4, 'text');
            app.KomplexneSucetEditField.ValueChangingFcn = createCallbackFcn(app, @KomplexneSucetEditFieldValueChanging, true);
            app.Image26_2 = uiimage(app.GridLayout4);
            app.Image26_2.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', '2priklad.jpg');
            app.vsledokLabel = uilabel(app.GridLayout4);
            app.vsledokLabel.Text = 'výsledok:';
            app.KomplexneGon2AlgVysledok = uieditfield(app.GridLayout4, 'text');
            app.KomplexneGon2AlgVysledok.ValueChangingFcn = createCallbackFcn(app, @KomplexneGon2AlgVysledokValueChanging, true);
            app.Image26_3 = uiimage(app.GridLayout4);
            app.Image26_3.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', '3priklad.jpg');
            app.vsledokEditFieldLabel = uilabel(app.GridLayout4);
            app.vsledokEditFieldLabel.Text = 'výsledok:';
            app.KomplexneMocninaEditField = uieditfield(app.GridLayout4, 'text');
            app.KomplexneMocninaEditField.ValueChangingFcn = createCallbackFcn(app, @KomplexneMocninaEditFieldValueChanging, true);
            app.Image26_4 = uiimage(app.GridLayout4);
            app.Image26_4.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', '4priklad.jpg');
            app.vsledokEditField_2Label = uilabel(app.GridLayout4);
            app.vsledokEditField_2Label.Text = 'výsledok:';
            app.KomplexneExp2AlgEditField = uieditfield(app.GridLayout4, 'text');
            app.KomplexneExp2AlgEditField.ValueChangingFcn = createCallbackFcn(app, @KomplexneExp2AlgEditFieldValueChanging, true);
            app.KomplexnePrikladyButtonGroup2 = uibuttongroup(app.GridLayout4);
            app.KomplexnePrikladyButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @KomplexnePrikladyButtonGroup2SelectionChanged, true);
            app.DefaultButton_7 = uiradiobutton(app.KomplexnePrikladyButtonGroup2);
            app.DefaultButton_7.Visible = 'off';
            app.DefaultButton_7.Text = 'Default';
            app.DefaultButton_7.Value = true;
            app.AButton_7 = uiradiobutton(app.KomplexnePrikladyButtonGroup2);
            app.AButton_7.Text = 'A';
            app.Image2_25 = uiimage(app.KomplexnePrikladyButtonGroup2);
            app.Image2_25.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Agon.jpg');
            app.Image2_26 = uiimage(app.KomplexnePrikladyButtonGroup2);
            app.Image2_26.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Bgon.jpg');
            app.Image2_27 = uiimage(app.KomplexnePrikladyButtonGroup2);
            app.Image2_27.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Cgon.jpg');
            app.Image2_28 = uiimage(app.KomplexnePrikladyButtonGroup2);
            app.Image2_28.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Dgon.jpg');
            app.BButton_7 = uiradiobutton(app.KomplexnePrikladyButtonGroup2);
            app.BButton_7.Text = 'B';
            app.CButton_7 = uiradiobutton(app.KomplexnePrikladyButtonGroup2);
            app.CButton_7.Text = 'C';
            app.DButton_13 = uiradiobutton(app.KomplexnePrikladyButtonGroup2);
            app.DButton_13.Text = 'D';
            app.Naptekomplexnslozsqrt3jvgoniometrickomtvareLabel = uilabel(app.KomplexnePrikladyButtonGroup2);
            app.Naptekomplexnslozsqrt3jvgoniometrickomtvareLabel.Interpreter = 'latex';
            app.Naptekomplexnslozsqrt3jvgoniometrickomtvareLabel.Text = 'Napíšte komplexné číslo $z=\sqrt{3}+j$ v goniometrickom tvare.';
            app.KomplexnePrikladyButtonGroup1 = uibuttongroup(app.GridLayout4);
            app.KomplexnePrikladyButtonGroup1.SelectionChangedFcn = createCallbackFcn(app, @KomplexnePrikladyButtonGroup1SelectionChanged, true);
            app.DefaultButton_8 = uiradiobutton(app.KomplexnePrikladyButtonGroup1);
            app.DefaultButton_8.Visible = 'off';
            app.DefaultButton_8.Text = 'Default';
            app.DefaultButton_8.Value = true;
            app.AButton_8 = uiradiobutton(app.KomplexnePrikladyButtonGroup1);
            app.AButton_8.Text = 'A';
            app.Image2_29 = uiimage(app.KomplexnePrikladyButtonGroup1);
            app.Image2_29.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Aodmnocnina.jpg');
            app.Image2_30 = uiimage(app.KomplexnePrikladyButtonGroup1);
            app.Image2_30.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Bodmnocina.jpg');
            app.Image2_31 = uiimage(app.KomplexnePrikladyButtonGroup1);
            app.Image2_31.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Codmnocina.jpg');
            app.Image2_32 = uiimage(app.KomplexnePrikladyButtonGroup1);
            app.Image2_32.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', 'Dodmnocnina.jpg');
            app.BButton_8 = uiradiobutton(app.KomplexnePrikladyButtonGroup1);
            app.BButton_8.Text = 'B';
            app.CButton_8 = uiradiobutton(app.KomplexnePrikladyButtonGroup1);
            app.CButton_8.Text = 'C';
            app.DButton_14 = uiradiobutton(app.KomplexnePrikladyButtonGroup1);
            app.DButton_14.Text = 'D';
            app.Vypotajtezsqrt1sqrt3jLabel = uilabel(app.KomplexnePrikladyButtonGroup1);
            app.Vypotajtezsqrt1sqrt3jLabel.Interpreter = 'latex';
            app.Vypotajtezsqrt1sqrt3jLabel.Text = 'Vypočítajte $z=\sqrt{1}-\sqrt{3j}$.';
            app.Image26_7 = uiimage(app.GridLayout4);
            app.Image26_7.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'komplexne_cisla', 'Priklady_Img', '1priklad.jpg');
            app.Hyperlink = uihyperlink(app.GridLayout4);
            app.Hyperlink.URL = 'https://www.priklady.com/sk/index.php/mnoziny-a-ciselne-obory/komplexne-cisla-komplexne-rovnice';
            app.Hyperlink.Text = 'Ďalšie príklady najtete TU';
            app.PraktickprkladyTab = uitab(app.KomplexneTabGroup);
            app.PraktickprkladyTab.Title = 'Praktické príklady';
            app.GridLayout6 = uigridlayout(app.PraktickprkladyTab);
            app.KomplexneUIAxes = uiaxes(app.GridLayout6);
            title(app.KomplexneUIAxes, 'Title')
            xlabel(app.KomplexneUIAxes, 'X')
            ylabel(app.KomplexneUIAxes, 'Y')
            zlabel(app.KomplexneUIAxes, 'Z')
            app.KomplexnePraktickeUlohyHTML = uihtml(app.GridLayout6);
            app.KomplexnePraktickeUlohyHTML.HTMLSource = 'kapitoly/komplexne_cisla/HTML/ZadaniePrikladov.html';
            app.KomplexneKonzolaPanel = uipanel(app.GridLayout6);
            app.KomplexneKonzolaPanel.Title = 'Konzola';
            app.GridLayout7 = uigridlayout(app.KomplexneKonzolaPanel);
            app.KomplexneKonzolaTextArea = uitextarea(app.GridLayout7);
            app.KomplexneKonzolaTextArea.Editable = 'off';
            app.KomplexneRunCodeButton = uibutton(app.GridLayout6, 'push');
            app.KomplexneRunCodeButton.ButtonPushedFcn = createCallbackFcn(app, @KomplexneRunCodeButtonPushed, true);
            app.KomplexneRunCodeButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.KomplexneRunCodeButton.Text = '';
            app.MATLABCODEEDITORLabel_13 = uilabel(app.GridLayout6);
            app.MATLABCODEEDITORLabel_13.Text = 'MATLAB CODE EDITOR';
            app.KomplexneCodeAreaHTML = uihtml(app.GridLayout6);
            app.KomplexneCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.KomplexneShowAnswerButton = uibutton(app.GridLayout6, 'push');
            app.KomplexneShowAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @KomplexneShowAnswerButtonPushed, true);
            app.KomplexneShowAnswerButton.Text = 'Show answer';
            app.KomplexneRestartPrikladyButton = uibutton(app.GridLayout6, 'push');
            app.KomplexneRestartPrikladyButton.ButtonPushedFcn = createCallbackFcn(app, @KomplexneRestartPrikladyButtonPushed, true);
            app.KomplexneRestartPrikladyButton.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel = uilabel(app.GridLayout6);
            app.CTRLSPACEukepremennafunkcieLabel.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.Hyperlink2 = uihyperlink(app.GridLayout6);
            app.Hyperlink2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2Clicked, true);
            app.Hyperlink2.Text = 'HELP';
            app.SignalyDalsiePrikladyHyperlink_2 = uihyperlink(app.GridLayout6);
            app.SignalyDalsiePrikladyHyperlink_2.HyperlinkClickedFcn = createCallbackFcn(app, @SignalyDalsiePrikladyHyperlink_2Clicked, true);
            app.SignalyDalsiePrikladyHyperlink_2.Text = 'Ďalšie príklad TU';
            app.SignalyMainTab = uitab(app.RootTabGroup);
            app.SignalyMainTab.Title = 'Rozdelenie signálov';
            app.GridLayout8 = uigridlayout(app.SignalyMainTab);
            app.SignalyTabGroup = uitabgroup(app.GridLayout8);
            app.SignalyTeoriaTab = uitab(app.SignalyTabGroup);
            app.SignalyTeoriaTab.Title = 'Teória';
            app.GridLayout9 = uigridlayout(app.SignalyTeoriaTab);
            app.SignalyTeoriaHTML = uihtml(app.GridLayout9);
            app.SignalyTeoriaHTML.HTMLSource = 'kapitoly/rozdelenie_signalov/HTML/teoria.html';
            app.SignalyInteraktivnaUkazkaTab = uitab(app.SignalyTabGroup);
            app.SignalyInteraktivnaUkazkaTab.Title = 'Interaktivna ukážka';
            app.GridLayout10_2 = uigridlayout(app.SignalyInteraktivnaUkazkaTab);
            app.SignalyInterktivnaUkazkaUIAxes = uiaxes(app.GridLayout10_2);
            xlabel(app.SignalyInterktivnaUkazkaUIAxes, 't')
            ylabel(app.SignalyInterktivnaUkazkaUIAxes, 'x(t)')
            zlabel(app.SignalyInterktivnaUkazkaUIAxes, 'Z')
            app.SignalyInterktivnaUkazkaUIAxes.XTick = [];
            app.SignalyInterktivnaUkazkaUIAxes.YTick = [];
            app.AmplitdaSliderLabel = uilabel(app.GridLayout10_2);
            app.AmplitdaSliderLabel.Text = 'Amplitúda';
            app.SignalyAmplitudaSlider = uislider(app.GridLayout10_2);
            app.SignalyAmplitudaSlider.Limits = [1 10];
            app.SignalyAmplitudaSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SignalyAmplitudaSlider.ValueChangedFcn = createCallbackFcn(app, @SignalyAmplitudaSliderValueChanged, true);
            app.SignalyAmplitudaSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SignalyAmplitudaSlider.Value = 1;
            app.FzaSliderLabel = uilabel(app.GridLayout10_2);
            app.FzaSliderLabel.Text = 'Fáza';
            app.SignalyFazaSlider = uislider(app.GridLayout10_2);
            app.SignalyFazaSlider.Limits = [0 360];
            app.SignalyFazaSlider.MajorTicks = [0 30 60 90 120 150 180 210 240 270 300 330 360];
            app.SignalyFazaSlider.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180', '210', '240', '270', '300', '330', '360'};
            app.SignalyFazaSlider.ValueChangedFcn = createCallbackFcn(app, @SignalyFazaSliderValueChanged, true);
            app.SignalyFazaSlider.MinorTicks = [];
            app.Label_9 = uilabel(app.GridLayout10_2);
            app.Label_9.Text = {'Frekvencia'; '(x-krát väčšia)'};
            app.SignalyPeriodaSlider = uislider(app.GridLayout10_2);
            app.SignalyPeriodaSlider.Limits = [1 10];
            app.SignalyPeriodaSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.SignalyPeriodaSlider.ValueChangedFcn = createCallbackFcn(app, @SignalyPeriodaSliderValueChanged, true);
            app.SignalyPeriodaSlider.MinorTicks = [];
            app.SignalyPeriodaSlider.Value = 1;
            app.VlastnostFourierovejtransformcieLabel_2 = uilabel(app.GridLayout10_2);
            app.VlastnostFourierovejtransformcieLabel_2.Text = 'Sinusový signál';
            app.TeoreticktestTab_2 = uitab(app.SignalyTabGroup);
            app.TeoreticktestTab_2.Title = 'Teoretický test';
            app.GridLayout38_2 = uigridlayout(app.TeoreticktestTab_2);
            app.KomplexO1Panel_2 = uipanel(app.GridLayout38_2);
            app.KomplexO1Panel_2.Title = 'Otázka č.1';
            app.GridLayout39_5 = uigridlayout(app.KomplexO1Panel_2);
            app.ACheckBox_5 = uicheckbox(app.GridLayout39_5);
            app.ACheckBox_5.Text = 'A)';
            app.BCheckBox_5 = uicheckbox(app.GridLayout39_5);
            app.BCheckBox_5.Text = 'B)';
            app.CCheckBox_5 = uicheckbox(app.GridLayout39_5);
            app.CCheckBox_5.Text = 'C)';
            app.DCheckBox_5 = uicheckbox(app.GridLayout39_5);
            app.DCheckBox_5.Text = 'D)';
            app.SignlyspojitvasespecificktmeLabel = uilabel(app.GridLayout39_5);
            app.SignlyspojitvasespecificktmeLabel.Interpreter = 'latex';
            app.SignlyspojitvasespecificktmeLabel.Text = 'Signály spojité v čase sú špecifické tým, že:';
            app.svyjadrenkonenoupostupnosouDiracovchimpulzovLabel = uilabel(app.GridLayout39_5);
            app.svyjadrenkonenoupostupnosouDiracovchimpulzovLabel.Interpreter = 'latex';
            app.svyjadrenkonenoupostupnosouDiracovchimpulzovLabel.Text = 'sú vyjadrené konečnou postupnosťou Diracových impulzov';
            app.preichopissavyuvasadatatistickchparametrovLabel = uilabel(app.GridLayout39_5);
            app.preichopissavyuvasadatatistickchparametrovLabel.Interpreter = 'latex';
            app.preichopissavyuvasadatatistickchparametrovLabel.Text = 'pre ich opis sa využíva sada štatistických parametrov';
            app.vasenadobdajnekonenpoethodntLabel_3 = uilabel(app.GridLayout39_5);
            app.vasenadobdajnekonenpoethodntLabel_3.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_3.Text = 'v čase nadobúdajú nekonečný počet hodnôt';
            app.sreprezentovansboromkonenchhodntLabel_2 = uilabel(app.GridLayout39_5);
            app.sreprezentovansboromkonenchhodntLabel_2.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_2.Text = 'sú reprezentované súborom konečných hodnôt ';
            app.KomplexO2Panel_2 = uipanel(app.GridLayout38_2);
            app.KomplexO2Panel_2.Title = 'Otázka č.2';
            app.GridLayout39_6 = uigridlayout(app.KomplexO2Panel_2);
            app.ACheckBox_6 = uicheckbox(app.GridLayout39_6);
            app.ACheckBox_6.Text = 'A)';
            app.BCheckBox_6 = uicheckbox(app.GridLayout39_6);
            app.BCheckBox_6.Text = 'B)';
            app.CCheckBox_6 = uicheckbox(app.GridLayout39_6);
            app.CCheckBox_6.Text = 'C)';
            app.DCheckBox_6 = uicheckbox(app.GridLayout39_6);
            app.DCheckBox_6.Text = 'D)';
            app.FrekvennspektrumperiodickhosignlujeLabel = uilabel(app.GridLayout39_6);
            app.FrekvennspektrumperiodickhosignlujeLabel.Interpreter = 'latex';
            app.FrekvennspektrumperiodickhosignlujeLabel.Text = 'Frekvenčné spektrum periodického signálu je:';
            app.spojitLabel_8 = uilabel(app.GridLayout39_6);
            app.spojitLabel_8.Interpreter = 'latex';
            app.spojitLabel_8.Text = 'spojité';
            app.diskrtneLabel_10 = uilabel(app.GridLayout39_6);
            app.diskrtneLabel_10.Interpreter = 'latex';
            app.diskrtneLabel_10.Text = 'diskrétne';
            app.kontatnanekoneneirokLabel_9 = uilabel(app.GridLayout39_6);
            app.kontatnanekoneneirokLabel_9.Interpreter = 'latex';
            app.kontatnanekoneneirokLabel_9.Text = 'konštatné a nekonečne široké';
            app.neharmonickLabel_3 = uilabel(app.GridLayout39_6);
            app.neharmonickLabel_3.Interpreter = 'latex';
            app.neharmonickLabel_3.Text = 'neharmonické';
            app.KomplexO3Panel_2 = uipanel(app.GridLayout38_2);
            app.KomplexO3Panel_2.Title = 'Otázka č.3';
            app.GridLayout39_7 = uigridlayout(app.KomplexO3Panel_2);
            app.ACheckBox_7 = uicheckbox(app.GridLayout39_7);
            app.ACheckBox_7.Text = 'A)';
            app.BCheckBox_7 = uicheckbox(app.GridLayout39_7);
            app.BCheckBox_7.Text = 'B)';
            app.CCheckBox_7 = uicheckbox(app.GridLayout39_7);
            app.CCheckBox_7.Text = 'C)';
            app.DCheckBox_7 = uicheckbox(app.GridLayout39_7);
            app.DCheckBox_7.Text = 'D)';
            app.FrekvennspektrumDiracovhoimpulzujeLabel = uilabel(app.GridLayout39_7);
            app.FrekvennspektrumDiracovhoimpulzujeLabel.Interpreter = 'latex';
            app.FrekvennspektrumDiracovhoimpulzujeLabel.Text = 'Frekvenčné spektrum Diracovho impulzu je:';
            app.stochastickLabel_2 = uilabel(app.GridLayout39_7);
            app.stochastickLabel_2.Interpreter = 'latex';
            app.stochastickLabel_2.Text = 'stochastické';
            app.diskrtneLabel_9 = uilabel(app.GridLayout39_7);
            app.diskrtneLabel_9.Interpreter = 'latex';
            app.diskrtneLabel_9.Text = 'diskrétne';
            app.kontatnanekoneneirokLabel_8 = uilabel(app.GridLayout39_7);
            app.kontatnanekoneneirokLabel_8.Interpreter = 'latex';
            app.kontatnanekoneneirokLabel_8.Text = 'konštatné a nekonečne široké';
            app.harmonickLabel_8 = uilabel(app.GridLayout39_7);
            app.harmonickLabel_8.Interpreter = 'latex';
            app.harmonickLabel_8.Text = 'harmonické';
            app.KomplexO4Panel_2 = uipanel(app.GridLayout38_2);
            app.KomplexO4Panel_2.Title = 'Otázka č.4';
            app.GridLayout39_8 = uigridlayout(app.KomplexO4Panel_2);
            app.ACheckBox_8 = uicheckbox(app.GridLayout39_8);
            app.ACheckBox_8.Text = 'A)';
            app.BCheckBox_8 = uicheckbox(app.GridLayout39_8);
            app.BCheckBox_8.Text = 'B)';
            app.CCheckBox_8 = uicheckbox(app.GridLayout39_8);
            app.CCheckBox_8.Text = 'C)';
            app.DCheckBox_8 = uicheckbox(app.GridLayout39_8);
            app.DCheckBox_8.Text = 'D)';
            app.PrevpoetspektradiskrtnehosignlusapouvaLabel = uilabel(app.GridLayout39_8);
            app.PrevpoetspektradiskrtnehosignlusapouvaLabel.Interpreter = 'latex';
            app.PrevpoetspektradiskrtnehosignlusapouvaLabel.Text = 'Pre výpočet spektra diskrétneho signálu sa používa:';
            app.neuritintegrlLabel_2 = uilabel(app.GridLayout39_8);
            app.neuritintegrlLabel_2.Interpreter = 'latex';
            app.neuritintegrlLabel_2.Text = 'neurčitý integrál';
            app.FourierovradLabel = uilabel(app.GridLayout39_8);
            app.FourierovradLabel.Interpreter = 'latex';
            app.FourierovradLabel.Text = 'Fourierov rad';
            app.diskrtnaFurierovtransformciaLabel_2 = uilabel(app.GridLayout39_8);
            app.diskrtnaFurierovtransformciaLabel_2.Interpreter = 'latex';
            app.diskrtnaFurierovtransformciaLabel_2.Text = 'diskrétna Furierová transformácia';
            app.vzorkovanieakvantovanieLabel_2 = uilabel(app.GridLayout39_8);
            app.vzorkovanieakvantovanieLabel_2.Interpreter = 'latex';
            app.vzorkovanieakvantovanieLabel_2.Text = 'vzorkovanie a kvantovanie';
            app.KomplRestartButton_2 = uibutton(app.GridLayout38_2, 'push');
            app.KomplRestartButton_2.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_2Pushed, true);
            app.KomplRestartButton_2.Text = 'Restart';
            app.KomplSubmitButton_2 = uibutton(app.GridLayout38_2, 'push');
            app.KomplSubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_2Pushed, true);
            app.KomplSubmitButton_2.Text = 'Submit';
            app.Ulohy1Tab = uitab(app.SignalyTabGroup);
            app.Ulohy1Tab.Title = 'Príklady';
            app.GridLayout10 = uigridlayout(app.Ulohy1Tab);
            app.SignalyCodeAreaU1HTML = uihtml(app.GridLayout10);
            app.SignalyCodeAreaU1HTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.SignalyZadanieU1HTML = uihtml(app.GridLayout10);
            app.SignalyZadanieU1HTML.HTMLSource = 'kapitoly/rozdelenie_signalov/HTML/uloha1.html';
            app.SignalyRunCodeU1Button = uibutton(app.GridLayout10, 'push');
            app.SignalyRunCodeU1Button.ButtonPushedFcn = createCallbackFcn(app, @SignalyRunCodeU1ButtonPushed, true);
            app.SignalyRunCodeU1Button.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.SignalyRunCodeU1Button.Text = '';
            app.SignalyZadanieU2HTML = uihtml(app.GridLayout10);
            app.SignalyZadanieU2HTML.HTMLSource = 'kapitoly/rozdelenie_signalov/HTML/uloha2.html';
            app.SignalyCodeAreaU2HTML = uihtml(app.GridLayout10);
            app.SignalyCodeAreaU2HTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.SignalyZadanieU3HTML = uihtml(app.GridLayout10);
            app.SignalyZadanieU3HTML.HTMLSource = 'kapitoly/rozdelenie_signalov/HTML/uloha3.html';
            app.SignalyZadanieU4HTML = uihtml(app.GridLayout10);
            app.SignalyZadanieU4HTML.HTMLSource = 'kapitoly/rozdelenie_signalov/HTML/uloha4.html';
            app.SignalyCodeAreaU4HTML = uihtml(app.GridLayout10);
            app.SignalyCodeAreaU4HTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.SignalyCodeAreaU3HTML = uihtml(app.GridLayout10);
            app.SignalyCodeAreaU3HTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.SignalyRunCodeU2Button = uibutton(app.GridLayout10, 'push');
            app.SignalyRunCodeU2Button.ButtonPushedFcn = createCallbackFcn(app, @SignalyRunCodeU2ButtonPushed, true);
            app.SignalyRunCodeU2Button.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.SignalyRunCodeU2Button.Text = '';
            app.SignalyRunCodeU3Button = uibutton(app.GridLayout10, 'push');
            app.SignalyRunCodeU3Button.ButtonPushedFcn = createCallbackFcn(app, @SignalyRunCodeU3ButtonPushed, true);
            app.SignalyRunCodeU3Button.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.SignalyRunCodeU3Button.Text = '';
            app.SignalyRunCodeU4Button = uibutton(app.GridLayout10, 'push');
            app.SignalyRunCodeU4Button.ButtonPushedFcn = createCallbackFcn(app, @SignalyRunCodeU4ButtonPushed, true);
            app.SignalyRunCodeU4Button.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.SignalyRunCodeU4Button.Text = '';
            app.SignalyShowanswersButton = uibutton(app.GridLayout10, 'push');
            app.SignalyShowanswersButton.ButtonPushedFcn = createCallbackFcn(app, @SignalyShowanswersButtonPushed, true);
            app.SignalyShowanswersButton.Text = 'Show answers';
            app.SignalyRestartButton = uibutton(app.GridLayout10, 'push');
            app.SignalyRestartButton.ButtonPushedFcn = createCallbackFcn(app, @SignalyRestartButtonPushed, true);
            app.SignalyRestartButton.Text = 'Restart';
            app.SignalyDalsiePrikladyHyperlink = uihyperlink(app.GridLayout10);
            app.SignalyDalsiePrikladyHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @SignalyDalsiePrikladyHyperlinkClicked, true);
            app.SignalyDalsiePrikladyHyperlink.Text = 'Ďalšie príklad TU';
            app.Label_10 = uilabel(app.GridLayout10);
            app.Label_10.Text = '';
            app.TextArea = uitextarea(app.GridLayout10);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'*CTRL+SPACE ukáže premenné a funkcie'};
            app.Hyperlink2_2 = uihyperlink(app.GridLayout10);
            app.Hyperlink2_2.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_2Clicked, true);
            app.Hyperlink2_2.Text = 'HELP';
            app.FurRadMainTab = uitab(app.RootTabGroup);
            app.FurRadMainTab.Title = 'Fourierov rad';
            app.GridLayout12 = uigridlayout(app.FurRadMainTab);
            app.FurRadTabGroup = uitabgroup(app.GridLayout12);
            app.TeoriaTab = uitab(app.FurRadTabGroup);
            app.TeoriaTab.Title = 'Teoria';
            app.GridLayout13 = uigridlayout(app.TeoriaTab);
            app.FurRadTeoriaVideoHMTL = uihtml(app.GridLayout13);
            app.FurRadTeoriaVideoHMTL.HTMLSource = 'kapitoly/fourierove_rady/HTML/teoria.html';
            app.FurRadInteraktivnaUkazkaTab = uitab(app.FurRadTabGroup);
            app.FurRadInteraktivnaUkazkaTab.Title = 'Interaktivna ukážka';
            app.GridLayout10_3 = uigridlayout(app.FurRadInteraktivnaUkazkaTab);
            app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes = uiaxes(app.GridLayout10_3);
            title(app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes, 'Samostatné zložký')
            xlabel(app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes, 't')
            ylabel(app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes, 'f(t)')
            zlabel(app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes, 'Z')
            app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes.XTick = [];
            app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes.XTickLabel = {};
            app.FurRadInteraktivnaUkazkaSamZlozkyUIAxes.YTick = [];
            app.FurRadInteraktivnaUkazkaFurRadUIAxes = uiaxes(app.GridLayout10_3);
            title(app.FurRadInteraktivnaUkazkaFurRadUIAxes, 'Fourierov Rád')
            xlabel(app.FurRadInteraktivnaUkazkaFurRadUIAxes, 't')
            ylabel(app.FurRadInteraktivnaUkazkaFurRadUIAxes, 'x(t)')
            zlabel(app.FurRadInteraktivnaUkazkaFurRadUIAxes, 'Z')
            app.FurRadInteraktivnaUkazkaFurRadUIAxes.XTick = [];
            app.FurRadInteraktivnaUkazkaFurRadUIAxes.YTick = [];
            app.FurRadInteraktivnaUkazkaSpektrumUIAxes = uiaxes(app.GridLayout10_3);
            title(app.FurRadInteraktivnaUkazkaSpektrumUIAxes, 'Spektrum')
            xlabel(app.FurRadInteraktivnaUkazkaSpektrumUIAxes, 'ω')
            ylabel(app.FurRadInteraktivnaUkazkaSpektrumUIAxes, 'X(ω)')
            zlabel(app.FurRadInteraktivnaUkazkaSpektrumUIAxes, 'Z')
            app.FurRadInteraktivnaUkazkaSpektrumUIAxes.XTick = [];
            app.FurRadInteraktivnaUkazkaSpektrumUIAxes.YTick = [];
            app.CatrvaniaLabel_2 = uilabel(app.GridLayout10_3);
            app.CatrvaniaLabel_2.Text = 'Počet harmonických zložiek(n): ';
            app.FurRadInteraktivnaUkazkaSlider = uislider(app.GridLayout10_3);
            app.FurRadInteraktivnaUkazkaSlider.Limits = [1 25];
            app.FurRadInteraktivnaUkazkaSlider.ValueChangedFcn = createCallbackFcn(app, @FurRadInteraktivnaUkazkaSliderValueChanged, true);
            app.FurRadInteraktivnaUkazkaSlider.MinorTicks = [];
            app.FurRadInteraktivnaUkazkaSlider.Value = 1;
            app.TeoreticktestTab_3 = uitab(app.FurRadTabGroup);
            app.TeoreticktestTab_3.Title = 'Teoretický test';
            app.GridLayout38_3 = uigridlayout(app.TeoreticktestTab_3);
            app.KomplexO1Panel_3 = uipanel(app.GridLayout38_3);
            app.KomplexO1Panel_3.Title = 'Otázka č.1';
            app.GridLayout39_9 = uigridlayout(app.KomplexO1Panel_3);
            app.ACheckBox_9 = uicheckbox(app.GridLayout39_9);
            app.ACheckBox_9.Text = 'A)';
            app.BCheckBox_9 = uicheckbox(app.GridLayout39_9);
            app.BCheckBox_9.Text = 'B)';
            app.CCheckBox_9 = uicheckbox(app.GridLayout39_9);
            app.CCheckBox_9.Text = 'C)';
            app.DCheckBox_9 = uicheckbox(app.GridLayout39_9);
            app.DCheckBox_9.Text = 'D)';
            app.AkjesprvnyzpistrigonometrickhoFourierovhoraduLabel = uilabel(app.GridLayout39_9);
            app.AkjesprvnyzpistrigonometrickhoFourierovhoraduLabel.Interpreter = 'latex';
            app.AkjesprvnyzpistrigonometrickhoFourierovhoraduLabel.Text = 'Aký je správny zápis trigonometrického Fourierovho radu?';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_3 = uilabel(app.GridLayout39_9);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_3.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_3.Text = '$$x\left(t\right)=a_0 +\sum_{n=1}^{\infty } a_{n\;} \cos \left(n\omega t\right)+\sum_{n=1}^{\infty } b_{n\;} \;\mathrm{tg}\left(n\omega t\right)$$';
            app.preichopissavyuvasadtatistickchparametrovLabel_3 = uilabel(app.GridLayout39_9);
            app.preichopissavyuvasadtatistickchparametrovLabel_3.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_3.Text = '$$x\left(t\right)=\sum_{n=1}^{\infty } a_{n\;} \cos \left(n\omega t\right)+\sum_{n=1}^{\infty } b_{n\;} \;\sin \left(n\omega t\right)$$';
            app.vasenadobdajnekonenpoethodntLabel_4 = uilabel(app.GridLayout39_9);
            app.vasenadobdajnekonenpoethodntLabel_4.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_4.Text = '$$x\left(t\right)=a_0 +\sum_{n=1}^{\infty } a_{n\;} \cos \left(n\omega t\right)+\sum_{n=1}^{\infty } b_{n\;} \;\sin \left(n\omega t\right)$$';
            app.sreprezentovansboromkonenchhodntLabel_3 = uilabel(app.GridLayout39_9);
            app.sreprezentovansboromkonenchhodntLabel_3.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_3.Text = '$$x\left(t\right)=a_0 +\sum_{n=1}^{\infty } a_{n\;} \cos \left(n\omega t\right)-\sum_{n=1}^{\infty } b_{n\;} \;\sin \left(n\omega t\right)$$';
            app.KomplexO2Panel_3 = uipanel(app.GridLayout38_3);
            app.KomplexO2Panel_3.Title = 'Otázka č.2';
            app.GridLayout39_10 = uigridlayout(app.KomplexO2Panel_3);
            app.ACheckBox_10 = uicheckbox(app.GridLayout39_10);
            app.ACheckBox_10.Text = 'A)';
            app.BCheckBox_10 = uicheckbox(app.GridLayout39_10);
            app.BCheckBox_10.Text = 'B)';
            app.CCheckBox_10 = uicheckbox(app.GridLayout39_10);
            app.CCheckBox_10.Text = 'C)';
            app.DCheckBox_10 = uicheckbox(app.GridLayout39_10);
            app.DCheckBox_10.Text = 'D)';
            app.FrekvennspektrumperiodickhosignlujeLabel_2 = uilabel(app.GridLayout39_10);
            app.FrekvennspektrumperiodickhosignlujeLabel_2.Interpreter = 'latex';
            app.FrekvennspektrumperiodickhosignlujeLabel_2.Text = 'Frekvenčné spektrum periodického signálu je:';
            app.spojitLabel_9 = uilabel(app.GridLayout39_10);
            app.spojitLabel_9.Interpreter = 'latex';
            app.spojitLabel_9.Text = 'spojité';
            app.diskrtneLabel_11 = uilabel(app.GridLayout39_10);
            app.diskrtneLabel_11.Interpreter = 'latex';
            app.diskrtneLabel_11.Text = 'diskrétne';
            app.kontatnanekoneneirokLabel_10 = uilabel(app.GridLayout39_10);
            app.kontatnanekoneneirokLabel_10.Interpreter = 'latex';
            app.kontatnanekoneneirokLabel_10.Text = 'konštatné a nekonečne široké';
            app.neharmonickLabel_4 = uilabel(app.GridLayout39_10);
            app.neharmonickLabel_4.Interpreter = 'latex';
            app.neharmonickLabel_4.Text = 'neharmonické';
            app.KomplexO3Panel_3 = uipanel(app.GridLayout38_3);
            app.KomplexO3Panel_3.Title = 'Otázka č.3';
            app.GridLayout39_11 = uigridlayout(app.KomplexO3Panel_3);
            app.ACheckBox_11 = uicheckbox(app.GridLayout39_11);
            app.ACheckBox_11.Text = 'A)';
            app.BCheckBox_11 = uicheckbox(app.GridLayout39_11);
            app.BCheckBox_11.Text = 'B)';
            app.CCheckBox_11 = uicheckbox(app.GridLayout39_11);
            app.CCheckBox_11.Text = 'C)';
            app.DCheckBox_11 = uicheckbox(app.GridLayout39_11);
            app.DCheckBox_11.Text = 'D)';
            app.SprvnyzpisvzorcaprevpoetjednosmernejzlokyjeLabel = uilabel(app.GridLayout39_11);
            app.SprvnyzpisvzorcaprevpoetjednosmernejzlokyjeLabel.Interpreter = 'latex';
            app.SprvnyzpisvzorcaprevpoetjednosmernejzlokyjeLabel.Text = 'Správny zápis vzorca pre výpočet jednosmernej zložky je:';
            app.frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel = uilabel(app.GridLayout39_11);
            app.frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel.Interpreter = 'latex';
            app.frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel.Text = '$$\frac{2}{T}\int_0^T x\left(t\right)\;\cos \left(n\omega t\right)\;dt$$';
            app.frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel_2 = uilabel(app.GridLayout39_11);
            app.frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel_2.Interpreter = 'latex';
            app.frac2Tint_0TxlefttrightcosleftnomegatrightdtLabel_2.Text = '$$\frac{2}{T}\int_0^T x\left(t\right)\;\cos \left(n\omega t\right)\;dt$$';
            app.frac1Tint_0TxlefttrightsinleftnomegatrightdtLabel = uilabel(app.GridLayout39_11);
            app.frac1Tint_0TxlefttrightsinleftnomegatrightdtLabel.Interpreter = 'latex';
            app.frac1Tint_0TxlefttrightsinleftnomegatrightdtLabel.Text = '$$\frac{1}{T}\int_0^T x\left(t\right)\;\sin \left(n\omega t\right)\;dt$$';
            app.frac1Tint_0TxlefttrightdtLabel = uilabel(app.GridLayout39_11);
            app.frac1Tint_0TxlefttrightdtLabel.Interpreter = 'latex';
            app.frac1Tint_0TxlefttrightdtLabel.Text = '$$\frac{1}{T}\int_0^T x\left(t\right)\;dt$$';
            app.KomplexO4Panel_3 = uipanel(app.GridLayout38_3);
            app.KomplexO4Panel_3.Title = 'Otázka č.4';
            app.GridLayout39_12 = uigridlayout(app.KomplexO4Panel_3);
            app.ACheckBox_12 = uicheckbox(app.GridLayout39_12);
            app.ACheckBox_12.Text = 'A)';
            app.BCheckBox_12 = uicheckbox(app.GridLayout39_12);
            app.BCheckBox_12.Text = 'B)';
            app.CCheckBox_12 = uicheckbox(app.GridLayout39_12);
            app.CCheckBox_12.Text = 'C)';
            app.DCheckBox_12 = uicheckbox(app.GridLayout39_12);
            app.DCheckBox_12.Text = 'D)';
            app.FourierovradpreneprneperiodickfunkcieobsahujelenLabel = uilabel(app.GridLayout39_12);
            app.FourierovradpreneprneperiodickfunkcieobsahujelenLabel.Interpreter = 'latex';
            app.FourierovradpreneprneperiodickfunkcieobsahujelenLabel.Text = 'Fourierov rad pre nepárne periodické funkcie obsahuje len:';
            app.snusovlenyLabel_2 = uilabel(app.GridLayout39_12);
            app.snusovlenyLabel_2.Interpreter = 'latex';
            app.snusovlenyLabel_2.Text = 'sínusové členy';
            app.jednosmernzlokuLabel = uilabel(app.GridLayout39_12);
            app.jednosmernzlokuLabel.Interpreter = 'latex';
            app.jednosmernzlokuLabel.Text = 'jednosmernú zložku';
            app.snusovlenyakosnusovlenyLabel_2 = uilabel(app.GridLayout39_12);
            app.snusovlenyakosnusovlenyLabel_2.Interpreter = 'latex';
            app.snusovlenyakosnusovlenyLabel_2.Text = 'sínusové členy a kosínusové členy';
            app.kosnusovlenyLabel_2 = uilabel(app.GridLayout39_12);
            app.kosnusovlenyLabel_2.Interpreter = 'latex';
            app.kosnusovlenyLabel_2.Text = 'kosínusové členy ';
            app.KomplRestartButton_3 = uibutton(app.GridLayout38_3, 'push');
            app.KomplRestartButton_3.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_3Pushed, true);
            app.KomplRestartButton_3.Text = 'Restart';
            app.KomplSubmitButton_3 = uibutton(app.GridLayout38_3, 'push');
            app.KomplSubmitButton_3.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_3Pushed, true);
            app.KomplSubmitButton_3.Text = 'Submit';
            app.RiesenyprikladTab = uitab(app.FurRadTabGroup);
            app.RiesenyprikladTab.Title = 'Riešený príklad';
            app.GridLayout15 = uigridlayout(app.RiesenyprikladTab);
            app.FurRadRiesenyPrikladUIAxes = uiaxes(app.GridLayout15);
            title(app.FurRadRiesenyPrikladUIAxes, 'Title')
            xlabel(app.FurRadRiesenyPrikladUIAxes, 'X')
            ylabel(app.FurRadRiesenyPrikladUIAxes, 'Y')
            zlabel(app.FurRadRiesenyPrikladUIAxes, 'Z')
            app.FurRadRiesenyPrikaldHTML = uihtml(app.GridLayout15);
            app.FurRadRiesenyPrikaldHTML.HTMLSource = 'kapitoly/fourierove_rady/HTML/RiesenyPriklad.html';
            app.FurRadRiesenyPrikladCodeAreaHTML = uihtml(app.GridLayout15);
            app.FurRadRiesenyPrikladCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_FurRad_Rieseny_Priklad.html';
            app.MATLABCODEEDITORLabel_2 = uilabel(app.GridLayout15);
            app.MATLABCODEEDITORLabel_2.Text = 'MATLAB CODE EDITOR';
            app.FurRadRunCodeRiesenyPrikladButton = uibutton(app.GridLayout15, 'push');
            app.FurRadRunCodeRiesenyPrikladButton.ButtonPushedFcn = createCallbackFcn(app, @FurRadRunCodeRiesenyPrikladButtonPushed, true);
            app.FurRadRunCodeRiesenyPrikladButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.FurRadRunCodeRiesenyPrikladButton.Text = '';
            app.CTRLSPACEukepremennafunkcieLabel_2 = uilabel(app.GridLayout15);
            app.CTRLSPACEukepremennafunkcieLabel_2.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.PrkladTab = uitab(app.FurRadTabGroup);
            app.PrkladTab.Title = 'Príklad';
            app.GridLayout16 = uigridlayout(app.PrkladTab);
            app.FurRadUlohaUIAxes = uiaxes(app.GridLayout16);
            title(app.FurRadUlohaUIAxes, 'Title')
            xlabel(app.FurRadUlohaUIAxes, 'X')
            ylabel(app.FurRadUlohaUIAxes, 'Y')
            zlabel(app.FurRadUlohaUIAxes, 'Z')
            app.FurRadZadanieUlohyHTML = uihtml(app.GridLayout16);
            app.FurRadZadanieUlohyHTML.HTMLSource = 'kapitoly/fourierove_rady/HTML/ZadaniePriklad.html';
            app.FurRadAnswerLabel = uilabel(app.GridLayout16);
            app.FurRadAnswerLabel.Interpreter = 'latex';
            app.FurRadAnswerLabel.Visible = 'off';
            app.FurRadAnswerLabel.Text = {'Výsledok FR: $x\left(t\right)=\frac{4}{\pi }\sum_{n=0}^{\infty } \frac{\sin \left(\overset{}{\frac{\left(2n+1\right)t}{2}} \right)}{2n+1}$'; ''};
            app.FurRadPanelKonzola = uipanel(app.GridLayout16);
            app.FurRadPanelKonzola.Title = 'Konzola';
            app.GridLayout17 = uigridlayout(app.FurRadPanelKonzola);
            app.FurRadKonzolaTextArea = uitextarea(app.GridLayout17);
            app.FurRadKonzolaTextArea.Editable = 'off';
            app.FurRadUlohaCodeAreaHTML = uihtml(app.GridLayout16);
            app.FurRadUlohaCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.MATLABCODEEDITORLabel_3 = uilabel(app.GridLayout16);
            app.MATLABCODEEDITORLabel_3.Text = 'MATLAB CODE EDITOR';
            app.FurRadRunCodeUlohaButton = uibutton(app.GridLayout16, 'push');
            app.FurRadRunCodeUlohaButton.ButtonPushedFcn = createCallbackFcn(app, @FurRadRunCodeUlohaButtonPushed, true);
            app.FurRadRunCodeUlohaButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.FurRadRunCodeUlohaButton.Text = '';
            app.FurRadShowAnswerButton = uibutton(app.GridLayout16, 'push');
            app.FurRadShowAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @FurRadShowAnswerButtonPushed, true);
            app.FurRadShowAnswerButton.Text = 'Show answer';
            app.FurRadRestartButton = uibutton(app.GridLayout16, 'push');
            app.FurRadRestartButton.ButtonPushedFcn = createCallbackFcn(app, @FurRadRestartButtonPushed, true);
            app.FurRadRestartButton.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel_3 = uilabel(app.GridLayout16);
            app.CTRLSPACEukepremennafunkcieLabel_3.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.FurRadDalsiePrikladyHyperlink = uihyperlink(app.GridLayout16);
            app.FurRadDalsiePrikladyHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @FurRadDalsiePrikladyHyperlinkClicked, true);
            app.FurRadDalsiePrikladyHyperlink.Text = 'Ďalší príklad TU';
            app.Hyperlink2_3 = uihyperlink(app.GridLayout16);
            app.Hyperlink2_3.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_3Clicked, true);
            app.Hyperlink2_3.Text = 'HELP';
            app.FurTransMainTab = uitab(app.RootTabGroup);
            app.FurTransMainTab.Title = 'Fourierová transformácia';
            app.GridLayout18 = uigridlayout(app.FurTransMainTab);
            app.FurTransTabGroup = uitabgroup(app.GridLayout18);
            app.FurTransTeoriaTab = uitab(app.FurTransTabGroup);
            app.FurTransTeoriaTab.Title = 'Teoria';
            app.GridLayout19 = uigridlayout(app.FurTransTeoriaTab);
            app.FurTransTeoriaHTML = uihtml(app.GridLayout19);
            app.FurTransTeoriaHTML.HTMLSource = 'kapitoly/fourierova_transformacia/HTML/teoria.html';
            app.FurTransInteraktivnaUkazkaTab = uitab(app.FurTransTabGroup);
            app.FurTransInteraktivnaUkazkaTab.Title = 'Interaktivna ukážka';
            app.GridLayout25 = uigridlayout(app.FurTransInteraktivnaUkazkaTab);
            app.FurTransInteraktivnaUkazkaSpektrumUIAxes = uiaxes(app.GridLayout25);
            title(app.FurTransInteraktivnaUkazkaSpektrumUIAxes, 'Frekvenčná oblasť')
            xlabel(app.FurTransInteraktivnaUkazkaSpektrumUIAxes, 'ω')
            ylabel(app.FurTransInteraktivnaUkazkaSpektrumUIAxes, 'X(ω)')
            zlabel(app.FurTransInteraktivnaUkazkaSpektrumUIAxes, 'Z')
            app.FurTransInteraktivnaUkazkaSignalUIAxes = uiaxes(app.GridLayout25);
            title(app.FurTransInteraktivnaUkazkaSignalUIAxes, 'Časová oblasť')
            xlabel(app.FurTransInteraktivnaUkazkaSignalUIAxes, 't')
            ylabel(app.FurTransInteraktivnaUkazkaSignalUIAxes, 'x(t)')
            zlabel(app.FurTransInteraktivnaUkazkaSignalUIAxes, 'Z')
            app.FurTransInteraktivnaUkazkaSignalUIAxes.XLim = [-5 5];
            app.FurTransInteraktivnaUkazkaSignalUIAxes.YLim = [-0.4 1.1];
            app.FurTransInteraktivnaUkazkaSlider = uislider(app.GridLayout25);
            app.FurTransInteraktivnaUkazkaSlider.Limits = [0.125 1.5];
            app.FurTransInteraktivnaUkazkaSlider.MajorTicks = [];
            app.FurTransInteraktivnaUkazkaSlider.ValueChangedFcn = createCallbackFcn(app, @FurTransInteraktivnaUkazkaSliderValueChanged, true);
            app.FurTransInteraktivnaUkazkaSlider.MinorTicks = [];
            app.FurTransInteraktivnaUkazkaSlider.Value = 0.815;
            app.FurTransInteraktivnaUkazkaLabel = uilabel(app.GridLayout25);
            app.FurTransInteraktivnaUkazkaLabel.Text = 'Šírka signálu v časovej oblasti';
            app.VlastnostFourierovejtransformcieLabel = uilabel(app.GridLayout25);
            app.VlastnostFourierovejtransformcieLabel.Text = 'Vlastnosť Fourierovej transformácie: Zmena časového merítka (time scaling) ';
            app.Label_11 = uilabel(app.GridLayout25);
            app.Label_11.Text = '-';
            app.Label_12 = uilabel(app.GridLayout25);
            app.Label_12.Text = '+';
            app.FTinteraktivnaObldznikButton = uibutton(app.GridLayout25, 'push');
            app.FTinteraktivnaObldznikButton.ButtonPushedFcn = createCallbackFcn(app, @FTinteraktivnaObldznikButtonPushed, true);
            app.FTinteraktivnaObldznikButton.Text = 'Obdlžnikový impulz';
            app.GaussovimpulzButton = uibutton(app.GridLayout25, 'push');
            app.GaussovimpulzButton.ButtonPushedFcn = createCallbackFcn(app, @GaussovimpulzButtonPushed, true);
            app.GaussovimpulzButton.Text = 'Gaussov impulz';
            app.TrojuholnkovimpulzButton = uibutton(app.GridLayout25, 'push');
            app.TrojuholnkovimpulzButton.ButtonPushedFcn = createCallbackFcn(app, @TrojuholnkovimpulzButtonPushed, true);
            app.TrojuholnkovimpulzButton.Text = 'Trojuholníkový impulz';
            app.Label_13 = uilabel(app.GridLayout25);
            app.Label_13.Interpreter = 'latex';
            app.Label_13.Text = '\textbf{Poznámka: }Platí, že: $\Delta f\approx \frac{1}{\Delta t}$. Ak signál v časovej oblasti zväčšíme x-krát, vo frekvenčnej oblasti sa zmenší x-krát a naopak.';
            app.TeoreticktestTab_4 = uitab(app.FurTransTabGroup);
            app.TeoreticktestTab_4.Title = 'Teoretický test';
            app.GridLayout38_4 = uigridlayout(app.TeoreticktestTab_4);
            app.KomplexO1Panel_4 = uipanel(app.GridLayout38_4);
            app.KomplexO1Panel_4.Title = 'Otázka č.1';
            app.GridLayout39_13 = uigridlayout(app.KomplexO1Panel_4);
            app.ACheckBox_13 = uicheckbox(app.GridLayout39_13);
            app.ACheckBox_13.Text = 'A)';
            app.BCheckBox_13 = uicheckbox(app.GridLayout39_13);
            app.BCheckBox_13.Text = 'B)';
            app.CCheckBox_13 = uicheckbox(app.GridLayout39_13);
            app.CCheckBox_13.Text = 'C)';
            app.DCheckBox_13 = uicheckbox(app.GridLayout39_13);
            app.DCheckBox_13.Text = 'D)';
            app.AkjesprvnyzpisvzahuprepriamuFourierovtransformciuLabel = uilabel(app.GridLayout39_13);
            app.AkjesprvnyzpisvzahuprepriamuFourierovtransformciuLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisvzahuprepriamuFourierovtransformciuLabel.Text = 'Aký je správny zápis vzťahu pre priamu Fourierovú transformáciu?';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_4 = uilabel(app.GridLayout39_13);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_4.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_4.Text = '$$\int_{-\infty }^{\infty } f\left(\omega \right)e^{-j\omega t} dt$$';
            app.preichopissavyuvasadtatistickchparametrovLabel_4 = uilabel(app.GridLayout39_13);
            app.preichopissavyuvasadtatistickchparametrovLabel_4.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_4.Text = '$$\int_{-\infty }^{\infty } f\left(t\right)e^{-j\omega t} dt$$';
            app.vasenadobdajnekonenpoethodntLabel_5 = uilabel(app.GridLayout39_13);
            app.vasenadobdajnekonenpoethodntLabel_5.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_5.Text = '$$\frac{1}{2\pi }\int_{-\infty }^{\infty } F\left(\omega \right)e^{j\omega t} d\omega$$';
            app.sreprezentovansboromkonenchhodntLabel_4 = uilabel(app.GridLayout39_13);
            app.sreprezentovansboromkonenchhodntLabel_4.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_4.Text = '$$\frac{2}{T}\int_0^T x\left(t\right)\;\sin \left(n\omega t\right)\;dt$$';
            app.KomplexO2Panel_4 = uipanel(app.GridLayout38_4);
            app.KomplexO2Panel_4.Title = 'Otázka č.2';
            app.GridLayout39_14 = uigridlayout(app.KomplexO2Panel_4);
            app.ACheckBox_14 = uicheckbox(app.GridLayout39_14);
            app.ACheckBox_14.Text = 'A)';
            app.BCheckBox_14 = uicheckbox(app.GridLayout39_14);
            app.BCheckBox_14.Text = 'B)';
            app.CCheckBox_14 = uicheckbox(app.GridLayout39_14);
            app.CCheckBox_14.Text = 'C)';
            app.DCheckBox_14 = uicheckbox(app.GridLayout39_14);
            app.DCheckBox_14.Text = 'D)';
            app.FrekvennspektrumneperiodickchsignlovjeLabel = uilabel(app.GridLayout39_14);
            app.FrekvennspektrumneperiodickchsignlovjeLabel.Interpreter = 'latex';
            app.FrekvennspektrumneperiodickchsignlovjeLabel.Text = 'Frekvenčné spektrum neperiodických signálov je:';
            app.spojitLabel_10 = uilabel(app.GridLayout39_14);
            app.spojitLabel_10.Interpreter = 'latex';
            app.spojitLabel_10.Text = 'spojité';
            app.diskrtneLabel_12 = uilabel(app.GridLayout39_14);
            app.diskrtneLabel_12.Interpreter = 'latex';
            app.diskrtneLabel_12.Text = 'diskrétne';
            app.kontatnanekoneneirokLabel_11 = uilabel(app.GridLayout39_14);
            app.kontatnanekoneneirokLabel_11.Interpreter = 'latex';
            app.kontatnanekoneneirokLabel_11.Text = 'konštatné a nekonečne široké';
            app.neharmonickLabel_5 = uilabel(app.GridLayout39_14);
            app.neharmonickLabel_5.Interpreter = 'latex';
            app.neharmonickLabel_5.Text = 'neharmonické';
            app.KomplexO3Panel_4 = uipanel(app.GridLayout38_4);
            app.KomplexO3Panel_4.Title = 'Otázka č.3';
            app.GridLayout39_15 = uigridlayout(app.KomplexO3Panel_4);
            app.ACheckBox_15 = uicheckbox(app.GridLayout39_15);
            app.ACheckBox_15.Text = 'A)';
            app.BCheckBox_15 = uicheckbox(app.GridLayout39_15);
            app.BCheckBox_15.Text = 'B)';
            app.CCheckBox_15 = uicheckbox(app.GridLayout39_15);
            app.CCheckBox_15.Text = 'C)';
            app.DCheckBox_15 = uicheckbox(app.GridLayout39_15);
            app.DCheckBox_15.Text = 'D)';
            app.AkjesprvnyzpisvzahupreinverznFourierovtransformciuLabel = uilabel(app.GridLayout39_15);
            app.AkjesprvnyzpisvzahupreinverznFourierovtransformciuLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisvzahupreinverznFourierovtransformciuLabel.Text = 'Aký je správny zápis vzťahu pre inverznú Fourierovú transformáciu?';
            app.int_inftyinftyfleftomegarightejomegatdtLabel = uilabel(app.GridLayout39_15);
            app.int_inftyinftyfleftomegarightejomegatdtLabel.Interpreter = 'latex';
            app.int_inftyinftyfleftomegarightejomegatdtLabel.Text = '$$\int_{-\infty }^{\infty } f\left(\omega \right)e^{-j\omega t} dt$$';
            app.int_inftyinftyflefttrightejomegatdtLabel = uilabel(app.GridLayout39_15);
            app.int_inftyinftyflefttrightejomegatdtLabel.Interpreter = 'latex';
            app.int_inftyinftyflefttrightejomegatdtLabel.Text = '$$\int_{-\infty }^{\infty } f\left(t\right)e^{-j\omega t} dt$$';
            app.frac12piint_inftyinftyFleftomegarightejomegatdomegaLabel = uilabel(app.GridLayout39_15);
            app.frac12piint_inftyinftyFleftomegarightejomegatdomegaLabel.Interpreter = 'latex';
            app.frac12piint_inftyinftyFleftomegarightejomegatdomegaLabel.Text = '$$\frac{1}{2\pi }\int_{-\infty }^{\infty } F\left(\omega \right)e^{j\omega t} d\omega$$';
            app.frac2Tint_0TxlefttrightsinleftnomegatrightdtLabel = uilabel(app.GridLayout39_15);
            app.frac2Tint_0TxlefttrightsinleftnomegatrightdtLabel.Interpreter = 'latex';
            app.frac2Tint_0TxlefttrightsinleftnomegatrightdtLabel.Text = '$$\frac{2}{T}\int_0^T x\left(t\right)\;\sin \left(n\omega t\right)\;dt$$';
            app.KomplexO4Panel_4 = uipanel(app.GridLayout38_4);
            app.KomplexO4Panel_4.Title = 'Otázka č.4';
            app.GridLayout39_16 = uigridlayout(app.KomplexO4Panel_4);
            app.ACheckBox_16 = uicheckbox(app.GridLayout39_16);
            app.ACheckBox_16.Text = 'A)';
            app.BCheckBox_16 = uicheckbox(app.GridLayout39_16);
            app.BCheckBox_16.Text = 'B)';
            app.CCheckBox_16 = uicheckbox(app.GridLayout39_16);
            app.CCheckBox_16.Text = 'C)';
            app.DCheckBox_16 = uicheckbox(app.GridLayout39_16);
            app.DCheckBox_16.Text = 'D)';
            app.mjesignlimpulzirvasovejoblastitmjejehospektrumLabel = uilabel(app.GridLayout39_16);
            app.mjesignlimpulzirvasovejoblastitmjejehospektrumLabel.Interpreter = 'latex';
            app.mjesignlimpulzirvasovejoblastitmjejehospektrumLabel.Text = 'Čím je signál (impulz) širší v časovej oblasti, tým je jeho spektrum:';
            app.uieLabel_2 = uilabel(app.GridLayout39_16);
            app.uieLabel_2.Interpreter = 'latex';
            app.uieLabel_2.Text = 'užšie';
            app.irieLabel_2 = uilabel(app.GridLayout39_16);
            app.irieLabel_2.Interpreter = 'latex';
            app.irieLabel_2.Text = 'širšie';
            app.rovnakLabel_2 = uilabel(app.GridLayout39_16);
            app.rovnakLabel_2.Interpreter = 'latex';
            app.rovnakLabel_2.Text = 'rovnaké';
            app.spojitLabel_11 = uilabel(app.GridLayout39_16);
            app.spojitLabel_11.Interpreter = 'latex';
            app.spojitLabel_11.Text = 'spojité';
            app.KomplRestartButton_4 = uibutton(app.GridLayout38_4, 'push');
            app.KomplRestartButton_4.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_4Pushed, true);
            app.KomplRestartButton_4.Text = 'Restart';
            app.KomplSubmitButton_4 = uibutton(app.GridLayout38_4, 'push');
            app.KomplSubmitButton_4.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_4Pushed, true);
            app.KomplSubmitButton_4.Text = 'Submit';
            app.RieenprkladTab = uitab(app.FurTransTabGroup);
            app.RieenprkladTab.Title = 'Riešený príklad';
            app.GridLayout20 = uigridlayout(app.RieenprkladTab);
            app.FurTransRiesenyPrikladHTML = uihtml(app.GridLayout20);
            app.FurTransRiesenyPrikladHTML.HTMLSource = 'kapitoly/fourierova_transformacia/HTML/RiesenyPriklad.html';
            app.FurTransRiesenyPrikladCodeAreaHTML = uihtml(app.GridLayout20);
            app.FurTransRiesenyPrikladCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_FurTrans_Riesen_Priklad.html';
            app.MATLABCODEEDITORLabel_4 = uilabel(app.GridLayout20);
            app.MATLABCODEEDITORLabel_4.Text = 'MATLAB CODE EDITOR';
            app.FurTransRunCodeRiesenyPrikladButton = uibutton(app.GridLayout20, 'push');
            app.FurTransRunCodeRiesenyPrikladButton.ButtonPushedFcn = createCallbackFcn(app, @FurTransRunCodeRiesenyPrikladButtonPushed, true);
            app.FurTransRunCodeRiesenyPrikladButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.FurTransRunCodeRiesenyPrikladButton.Text = '';
            app.CTRLSPACEukepremennafunkcieLabel_4 = uilabel(app.GridLayout20);
            app.CTRLSPACEukepremennafunkcieLabel_4.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.PrkladTab_3 = uitab(app.FurTransTabGroup);
            app.PrkladTab_3.Title = 'Príklad';
            app.GridLayout16_2 = uigridlayout(app.PrkladTab_3);
            app.FurTrransZadanieUlohyHTML = uihtml(app.GridLayout16_2);
            app.FurTrransZadanieUlohyHTML.HTMLSource = 'kapitoly/fourierova_transformacia/HTML/ZadaniePrikladu.html';
            app.FurTransAnswerLabel = uilabel(app.GridLayout16_2);
            app.FurTransAnswerLabel.Interpreter = 'latex';
            app.FurTransAnswerLabel.Visible = 'off';
            app.FurTransAnswerLabel.Text = 'Výsledok FT: $\;\;\;4\pi \cdot \frac{\sin \left(\omega \pi \right)}{\omega \pi }=4\pi \cdot \mathrm{sinC}\left(\omega \pi \right)$';
            app.FurTransPanelKonzola = uipanel(app.GridLayout16_2);
            app.FurTransPanelKonzola.Title = 'Konzola';
            app.GridLayout17_2 = uigridlayout(app.FurTransPanelKonzola);
            app.FurTransKonzolaTextArea = uitextarea(app.GridLayout17_2);
            app.FurTransKonzolaTextArea.Editable = 'off';
            app.FurTransUlohaCodeAreaHTML = uihtml(app.GridLayout16_2);
            app.FurTransUlohaCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.MATLABCODEEDITORLabel_6 = uilabel(app.GridLayout16_2);
            app.MATLABCODEEDITORLabel_6.Text = 'MATLAB CODE EDITOR';
            app.FurTransRunCodeUlohaButton = uibutton(app.GridLayout16_2, 'push');
            app.FurTransRunCodeUlohaButton.ButtonPushedFcn = createCallbackFcn(app, @FurTransRunCodeUlohaButtonPushed, true);
            app.FurTransRunCodeUlohaButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.FurTransRunCodeUlohaButton.Text = '';
            app.FurTransShowAnswerButton = uibutton(app.GridLayout16_2, 'push');
            app.FurTransShowAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @FurTransShowAnswerButtonPushed, true);
            app.FurTransShowAnswerButton.Text = 'Show answer';
            app.FurTransRestartButton = uibutton(app.GridLayout16_2, 'push');
            app.FurTransRestartButton.ButtonPushedFcn = createCallbackFcn(app, @FurTransRestartButtonPushed, true);
            app.FurTransRestartButton.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel_5 = uilabel(app.GridLayout16_2);
            app.CTRLSPACEukepremennafunkcieLabel_5.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.FurTransDalsiePrikladyHyperlink = uihyperlink(app.GridLayout16_2);
            app.FurTransDalsiePrikladyHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @FurTransDalsiePrikladyHyperlinkClicked, true);
            app.FurTransDalsiePrikladyHyperlink.Text = 'Ďalší príklad TU';
            app.Hyperlink2_4 = uihyperlink(app.GridLayout16_2);
            app.Hyperlink2_4.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_4Clicked, true);
            app.Hyperlink2_4.Text = 'HELP';
            app.DFTMainTab = uitab(app.RootTabGroup);
            app.DFTMainTab.Title = 'Diskretná Fourierova transformácia';
            app.GridLayout21 = uigridlayout(app.DFTMainTab);
            app.DFTTabGroup = uitabgroup(app.GridLayout21);
            app.DFTTeoriaTab = uitab(app.DFTTabGroup);
            app.DFTTeoriaTab.Title = 'Teória';
            app.GridLayout22 = uigridlayout(app.DFTTeoriaTab);
            app.DFTTeoriaHTML = uihtml(app.GridLayout22);
            app.DFTTeoriaHTML.HTMLSource = 'kapitoly/diskretna_fourierova_transformacia/HTML/teoria.html';
            app.DFTVideoUkazkaTab_2 = uitab(app.DFTTabGroup);
            app.DFTVideoUkazkaTab_2.Title = 'Video ukážka 1';
            app.GridLayout28_2 = uigridlayout(app.DFTVideoUkazkaTab_2);
            app.HTML_2 = uihtml(app.GridLayout28_2);
            app.HTML_2.HTMLSource = 'kapitoly/diskretna_fourierova_transformacia/HTML/videoSin.html';
            app.DFTVideoUkazkaTab = uitab(app.DFTTabGroup);
            app.DFTVideoUkazkaTab.Title = 'Video ukážka 2';
            app.GridLayout28 = uigridlayout(app.DFTVideoUkazkaTab);
            app.HTML = uihtml(app.GridLayout28);
            app.HTML.HTMLSource = 'kapitoly/diskretna_fourierova_transformacia/HTML/video.html';
            app.InteraktivnaukkaTab = uitab(app.DFTTabGroup);
            app.InteraktivnaukkaTab.Title = 'Interaktivna ukážka';
            app.GridLayout35 = uigridlayout(app.InteraktivnaukkaTab);
            app.RekonstukciaSignaluUIAxes = uiaxes(app.GridLayout35);
            title(app.RekonstukciaSignaluUIAxes, 'Rekonšturkcia signálu')
            xlabel(app.RekonstukciaSignaluUIAxes, 't')
            ylabel(app.RekonstukciaSignaluUIAxes, 'x(t)')
            zlabel(app.RekonstukciaSignaluUIAxes, 'Z')
            app.RekonstukciaSignaluUIAxes.YLimitMethod = 'padded';
            app.RekonstukciaSignaluUIAxes.XTick = [];
            app.RekonstukciaSignaluUIAxes.YTick = [];
            app.VzorkovanySignalUIAxes = uiaxes(app.GridLayout35);
            title(app.VzorkovanySignalUIAxes, 'Vzorkovaný signál')
            xlabel(app.VzorkovanySignalUIAxes, 't')
            ylabel(app.VzorkovanySignalUIAxes, 'x(t)')
            zlabel(app.VzorkovanySignalUIAxes, 'Z')
            app.VzorkovanySignalUIAxes.YLimitMethod = 'padded';
            app.VzorkovanySignalUIAxes.XTick = [];
            app.VzorkovanySignalUIAxes.YTick = [];
            app.ShanonNyquistovpodmienkaLabel = uilabel(app.GridLayout35);
            app.ShanonNyquistovpodmienkaLabel.Text = 'Shanon-Nyquistová podmienka';
            app.RunButton = uibutton(app.GridLayout35, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RunButton_2 = uibutton(app.GridLayout35, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.RunButton_3 = uibutton(app.GridLayout35, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.f_mathrmvz2cdotf_mLabel = uilabel(app.GridLayout35);
            app.f_mathrmvz2cdotf_mLabel.Interpreter = 'latex';
            app.f_mathrmvz2cdotf_mLabel.Text = '$$f_{\mathrm{vz}} <2\cdot f_m$$';
            app.f_mathrmvz2cdotf_mLabel_2 = uilabel(app.GridLayout35);
            app.f_mathrmvz2cdotf_mLabel_2.Interpreter = 'latex';
            app.f_mathrmvz2cdotf_mLabel_2.Text = '$$f_{\mathrm{vz}} =2\cdot f_m$$';
            app.f_mathrmvz2cdotf_mLabel_3 = uilabel(app.GridLayout35);
            app.f_mathrmvz2cdotf_mLabel_3.Interpreter = 'latex';
            app.f_mathrmvz2cdotf_mLabel_3.Text = '$$f_{\mathrm{vz}} >2\cdot f_m$$';
            app.TeoreticktestTab_5 = uitab(app.DFTTabGroup);
            app.TeoreticktestTab_5.Title = 'Teoretický test';
            app.GridLayout38_5 = uigridlayout(app.TeoreticktestTab_5);
            app.KomplexO1Panel_5 = uipanel(app.GridLayout38_5);
            app.KomplexO1Panel_5.Title = 'Otázka č.1';
            app.GridLayout39_17 = uigridlayout(app.KomplexO1Panel_5);
            app.ACheckBox_17 = uicheckbox(app.GridLayout39_17);
            app.ACheckBox_17.Text = 'A)';
            app.BCheckBox_17 = uicheckbox(app.GridLayout39_17);
            app.BCheckBox_17.Text = 'B)';
            app.CCheckBox_17 = uicheckbox(app.GridLayout39_17);
            app.CCheckBox_17.Text = 'C)';
            app.DCheckBox_17 = uicheckbox(app.GridLayout39_17);
            app.DCheckBox_17.Text = 'D)';
            app.AkjesprvnyzpisvzahuprepriamuDFTLabel = uilabel(app.GridLayout39_17);
            app.AkjesprvnyzpisvzahuprepriamuDFTLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisvzahuprepriamuDFTLabel.Text = 'Aký je správny zápis vzťahu pre priamu DFT?';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_5 = uilabel(app.GridLayout39_17);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_5.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_5.Text = '$$\sum_{n\;=\;0}^{N-1} x\left(n\right)e^{-j\frac{2\pi }{N}nk}$$';
            app.preichopissavyuvasadtatistickchparametrovLabel_5 = uilabel(app.GridLayout39_17);
            app.preichopissavyuvasadtatistickchparametrovLabel_5.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_5.Text = '$$\frac{1}{N}\sum_{n\;=\;0}^{N-1} X\left(k\right)e^{j\frac{2\pi }{N}nk}$$';
            app.vasenadobdajnekonenpoethodntLabel_6 = uilabel(app.GridLayout39_17);
            app.vasenadobdajnekonenpoethodntLabel_6.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_6.Text = '$$\frac{1}{N}\sum_{n\;=\;0}^{N-1} X\left(k\right)e^{j\omega N}$$';
            app.sreprezentovansboromkonenchhodntLabel_5 = uilabel(app.GridLayout39_17);
            app.sreprezentovansboromkonenchhodntLabel_5.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_5.Text = '$$\sum_{n\;=\;0}^{N-1} x\left(n\right)e^{-\mathrm{jkn}}$$';
            app.KomplexO2Panel_5 = uipanel(app.GridLayout38_5);
            app.KomplexO2Panel_5.Title = 'Otázka č.2';
            app.GridLayout39_18 = uigridlayout(app.KomplexO2Panel_5);
            app.ACheckBox_18 = uicheckbox(app.GridLayout39_18);
            app.ACheckBox_18.Text = 'A)';
            app.BCheckBox_18 = uicheckbox(app.GridLayout39_18);
            app.BCheckBox_18.Text = 'B)';
            app.CCheckBox_18 = uicheckbox(app.GridLayout39_18);
            app.CCheckBox_18.Text = 'C)';
            app.DCheckBox_18 = uicheckbox(app.GridLayout39_18);
            app.DCheckBox_18.Text = 'D)';
            app.NyquistovpodmienkaprivzorkovanhovoreLabel = uilabel(app.GridLayout39_18);
            app.NyquistovpodmienkaprivzorkovanhovoreLabel.Interpreter = 'latex';
            app.NyquistovpodmienkaprivzorkovanhovoreLabel.Text = 'Nyquistová podmienka pri vzorkovaní hovorí, že:';
            app.f_mathrmvz2f_mLabel = uilabel(app.GridLayout39_18);
            app.f_mathrmvz2f_mLabel.Interpreter = 'latex';
            app.f_mathrmvz2f_mLabel.Text = '$$f_{\mathrm{vz}} =2f_m$$';
            app.f_mathrmvzle2f_mLabel = uilabel(app.GridLayout39_18);
            app.f_mathrmvzle2f_mLabel.Interpreter = 'latex';
            app.f_mathrmvzle2f_mLabel.Text = '$$f_{\mathrm{vz}} \le 2f_m$$';
            app.f_mathrmvzf_mLabel = uilabel(app.GridLayout39_18);
            app.f_mathrmvzf_mLabel.Interpreter = 'latex';
            app.f_mathrmvzf_mLabel.Text = '$$f_{\mathrm{vz}} =f_m$$';
            app.f_mathrmvzge2f_mLabel = uilabel(app.GridLayout39_18);
            app.f_mathrmvzge2f_mLabel.Interpreter = 'latex';
            app.f_mathrmvzge2f_mLabel.Text = '$$f_{\mathrm{vz}} \ge 2f_m$$';
            app.KomplexO3Panel_5 = uipanel(app.GridLayout38_5);
            app.KomplexO3Panel_5.Title = 'Otázka č.3';
            app.GridLayout39_19 = uigridlayout(app.KomplexO3Panel_5);
            app.ACheckBox_19 = uicheckbox(app.GridLayout39_19);
            app.ACheckBox_19.Text = 'A)';
            app.BCheckBox_19 = uicheckbox(app.GridLayout39_19);
            app.BCheckBox_19.Text = 'B)';
            app.CCheckBox_19 = uicheckbox(app.GridLayout39_19);
            app.CCheckBox_19.Text = 'C)';
            app.DCheckBox_19 = uicheckbox(app.GridLayout39_19);
            app.DCheckBox_19.Text = 'D)';
            app.AkjesprvnyzpisvzahupreinverznDFTLabel = uilabel(app.GridLayout39_19);
            app.AkjesprvnyzpisvzahupreinverznDFTLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisvzahupreinverznDFTLabel.Text = 'Aký je správny zápis vzťahu pre inverznú DFT?';
            app.sum_n0N1xleftnrightejfrac2piNnkLabel = uilabel(app.GridLayout39_19);
            app.sum_n0N1xleftnrightejfrac2piNnkLabel.Interpreter = 'latex';
            app.sum_n0N1xleftnrightejfrac2piNnkLabel.Text = '$$\sum_{n\;=\;0}^{N-1} x\left(n\right)e^{-j\frac{2\pi }{N}nk}$$';
            app.frac1Nsum_n0N1Xleftkrightejfrac2piNnkLabel = uilabel(app.GridLayout39_19);
            app.frac1Nsum_n0N1Xleftkrightejfrac2piNnkLabel.Interpreter = 'latex';
            app.frac1Nsum_n0N1Xleftkrightejfrac2piNnkLabel.Text = '$$\frac{1}{N}\sum_{n\;=\;0}^{N-1} X\left(k\right)e^{j\frac{2\pi }{N}nk}$$';
            app.frac1Nsum_n0N1XleftkrightejomegaNLabel = uilabel(app.GridLayout39_19);
            app.frac1Nsum_n0N1XleftkrightejomegaNLabel.Interpreter = 'latex';
            app.frac1Nsum_n0N1XleftkrightejomegaNLabel.Text = '$$\frac{1}{N}\sum_{n\;=\;0}^{N-1} X\left(k\right)e^{j\omega N}$$';
            app.sum_n0N1xleftnrightemathrmjknLabel = uilabel(app.GridLayout39_19);
            app.sum_n0N1xleftnrightemathrmjknLabel.Interpreter = 'latex';
            app.sum_n0N1xleftnrightemathrmjknLabel.Text = '$$\sum_{n\;=\;0}^{N-1} x\left(n\right)e^{-\mathrm{jkn}}$$';
            app.KomplexO4Panel_5 = uipanel(app.GridLayout38_5);
            app.KomplexO4Panel_5.Title = 'Otázka č.4';
            app.GridLayout39_20 = uigridlayout(app.KomplexO4Panel_5);
            app.ACheckBox_20 = uicheckbox(app.GridLayout39_20);
            app.ACheckBox_20.Text = 'A)';
            app.BCheckBox_20 = uicheckbox(app.GridLayout39_20);
            app.BCheckBox_20.Text = 'B)';
            app.CCheckBox_20 = uicheckbox(app.GridLayout39_20);
            app.CCheckBox_20.Text = 'C)';
            app.DCheckBox_20 = uicheckbox(app.GridLayout39_20);
            app.DCheckBox_20.Text = 'D)';
            app.FrekvennspektrumdiskrtnychsignlovjeLabel = uilabel(app.GridLayout39_20);
            app.FrekvennspektrumdiskrtnychsignlovjeLabel.Interpreter = 'latex';
            app.FrekvennspektrumdiskrtnychsignlovjeLabel.Text = 'Frekvenčné spektrum diskrétnych signálov je:';
            app.spojitLabel_14 = uilabel(app.GridLayout39_20);
            app.spojitLabel_14.Interpreter = 'latex';
            app.spojitLabel_14.Text = 'spojité';
            app.diskrtneLabel_13 = uilabel(app.GridLayout39_20);
            app.diskrtneLabel_13.Interpreter = 'latex';
            app.diskrtneLabel_13.Text = 'diskrétne';
            app.frekvenneneohranienLabel_2 = uilabel(app.GridLayout39_20);
            app.frekvenneneohranienLabel_2.Interpreter = 'latex';
            app.frekvenneneohranienLabel_2.Text = 'frekvenčne neohraničené';
            app.kontatnLabel_2 = uilabel(app.GridLayout39_20);
            app.kontatnLabel_2.Interpreter = 'latex';
            app.kontatnLabel_2.Text = 'konštatné';
            app.KomplRestartButton_5 = uibutton(app.GridLayout38_5, 'push');
            app.KomplRestartButton_5.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_5Pushed, true);
            app.KomplRestartButton_5.Text = 'Restart';
            app.KomplSubmitButton_5 = uibutton(app.GridLayout38_5, 'push');
            app.KomplSubmitButton_5.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_5Pushed, true);
            app.KomplSubmitButton_5.Text = 'Submit';
            app.DFTRiesenyPrikladTab = uitab(app.DFTTabGroup);
            app.DFTRiesenyPrikladTab.Title = 'Riešený príklad';
            app.GridLayout20_2 = uigridlayout(app.DFTRiesenyPrikladTab);
            app.DFTRiesenyPrikladHTML = uihtml(app.GridLayout20_2);
            app.DFTRiesenyPrikladHTML.HTMLSource = 'kapitoly/diskretna_fourierova_transformacia/HTML/riesenyPriklad.html';
            app.DFTRiesenyPrikladCodeAreaHTML = uihtml(app.GridLayout20_2);
            app.DFTRiesenyPrikladCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_DFT_Rieseny_Priklad.html';
            app.MATLABCODEEDITORLabel_7 = uilabel(app.GridLayout20_2);
            app.MATLABCODEEDITORLabel_7.Text = 'MATLAB CODE EDITOR';
            app.DFTCodeRiesenyPrikladButton = uibutton(app.GridLayout20_2, 'push');
            app.DFTCodeRiesenyPrikladButton.ButtonPushedFcn = createCallbackFcn(app, @DFTCodeRiesenyPrikladButtonPushed, true);
            app.DFTCodeRiesenyPrikladButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.DFTCodeRiesenyPrikladButton.Text = '';
            app.DFTRiesenyPrikaldPanelKonzola = uipanel(app.GridLayout20_2);
            app.DFTRiesenyPrikaldPanelKonzola.Title = 'Konzola';
            app.GridLayout17_3 = uigridlayout(app.DFTRiesenyPrikaldPanelKonzola);
            app.DFTRiesenyPrikladKonzolaTextArea = uitextarea(app.GridLayout17_3);
            app.DFTRiesenyPrikladKonzolaTextArea.Editable = 'off';
            app.CTRLSPACEukepremennafunkcieLabel_6 = uilabel(app.GridLayout20_2);
            app.CTRLSPACEukepremennafunkcieLabel_6.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.DFTPrikladTab = uitab(app.DFTTabGroup);
            app.DFTPrikladTab.Title = 'Príklad';
            app.GridLayout16_3 = uigridlayout(app.DFTPrikladTab);
            app.DFTZadanieUlohyHTML = uihtml(app.GridLayout16_3);
            app.DFTZadanieUlohyHTML.HTMLSource = 'kapitoly/diskretna_fourierova_transformacia/HTML/zadaniePrikladu.html';
            app.DFTPanelKonzola = uipanel(app.GridLayout16_3);
            app.DFTPanelKonzola.Title = 'Konzola';
            app.GridLayout17_4 = uigridlayout(app.DFTPanelKonzola);
            app.DFTKonzolaTextArea = uitextarea(app.GridLayout17_4);
            app.DFTKonzolaTextArea.Editable = 'off';
            app.DFTUlohaCodeAreaHTML = uihtml(app.GridLayout16_3);
            app.DFTUlohaCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.MATLABCODEEDITORLabel_8 = uilabel(app.GridLayout16_3);
            app.MATLABCODEEDITORLabel_8.Text = 'MATLAB CODE EDITOR';
            app.DFTRunCodeUlohaButton = uibutton(app.GridLayout16_3, 'push');
            app.DFTRunCodeUlohaButton.ButtonPushedFcn = createCallbackFcn(app, @DFTRunCodeUlohaButtonPushed, true);
            app.DFTRunCodeUlohaButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.DFTRunCodeUlohaButton.Text = '';
            app.DFTShowAnswerButton = uibutton(app.GridLayout16_3, 'push');
            app.DFTShowAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @DFTShowAnswerButtonPushed, true);
            app.DFTShowAnswerButton.Text = 'Show answer';
            app.DFTRestartButton = uibutton(app.GridLayout16_3, 'push');
            app.DFTRestartButton.ButtonPushedFcn = createCallbackFcn(app, @DFTRestartButtonPushed, true);
            app.DFTRestartButton.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel_8 = uilabel(app.GridLayout16_3);
            app.CTRLSPACEukepremennafunkcieLabel_8.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.DFTDalsiePrikladyHyperlink = uihyperlink(app.GridLayout16_3);
            app.DFTDalsiePrikladyHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @DFTDalsiePrikladyHyperlinkClicked, true);
            app.DFTDalsiePrikladyHyperlink.Text = 'Ďalší príklad TU';
            app.Hyperlink2_5 = uihyperlink(app.GridLayout16_3);
            app.Hyperlink2_5.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_5Clicked, true);
            app.Hyperlink2_5.Text = 'HELP';
            app.KonvKorMainTab = uitab(app.RootTabGroup);
            app.KonvKorMainTab.Title = 'Konvolúcia a korelácia';
            app.GridLayout21_2 = uigridlayout(app.KonvKorMainTab);
            app.KonvKorTabGroup = uitabgroup(app.GridLayout21_2);
            app.KonvKorTeoriaTab = uitab(app.KonvKorTabGroup);
            app.KonvKorTeoriaTab.Title = 'Teória';
            app.GridLayout22_2 = uigridlayout(app.KonvKorTeoriaTab);
            app.KonvKorTeoriaHTML = uihtml(app.GridLayout22_2);
            app.KonvKorTeoriaHTML.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/teoria.html';
            app.KonvKorVideoTab = uitab(app.KonvKorTabGroup);
            app.KonvKorVideoTab.Title = 'Video ukážka 1';
            app.GridLayout23 = uigridlayout(app.KonvKorVideoTab);
            app.KonvKorVideo2HTML = uihtml(app.GridLayout23);
            app.KonvKorVideo2HTML.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/autokorelacia.html';
            app.KonvKorVideo1HTML = uihtml(app.GridLayout23);
            app.KonvKorVideo1HTML.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/konvoluciaStvorec.html';
            app.KonvKorVideoTab_2 = uitab(app.KonvKorTabGroup);
            app.KonvKorVideoTab_2.Title = 'Video ukážka 2';
            app.GridLayout23_2 = uigridlayout(app.KonvKorVideoTab_2);
            app.KonvKorVideo2HTML_2 = uihtml(app.GridLayout23_2);
            app.KonvKorVideo2HTML_2.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/crosskorelacia.html';
            app.KonvKorVideo1HTML_2 = uihtml(app.GridLayout23_2);
            app.KonvKorVideo1HTML_2.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/konvoluciaStvorecTroj.html';
            app.KonvKorVideoTab_3 = uitab(app.KonvKorTabGroup);
            app.KonvKorVideoTab_3.Title = 'Video ukážka 3';
            app.GridLayout23_3 = uigridlayout(app.KonvKorVideoTab_3);
            app.HTML7 = uihtml(app.GridLayout23_3);
            app.HTML7.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/autokorelaciaGaus.html';
            app.HTML8 = uihtml(app.GridLayout23_3);
            app.HTML8.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/konvoluciaGausPlusGaus.html';
            app.TeoreticktestTab_6 = uitab(app.KonvKorTabGroup);
            app.TeoreticktestTab_6.Title = 'Teoretický test';
            app.GridLayout38_6 = uigridlayout(app.TeoreticktestTab_6);
            app.KomplexO1Panel_6 = uipanel(app.GridLayout38_6);
            app.KomplexO1Panel_6.Title = 'Otázka č.1';
            app.GridLayout39_21 = uigridlayout(app.KomplexO1Panel_6);
            app.ACheckBox_21 = uicheckbox(app.GridLayout39_21);
            app.ACheckBox_21.Text = 'A)';
            app.BCheckBox_21 = uicheckbox(app.GridLayout39_21);
            app.BCheckBox_21.Text = 'B)';
            app.CCheckBox_21 = uicheckbox(app.GridLayout39_21);
            app.CCheckBox_21.Text = 'C)';
            app.DCheckBox_21 = uicheckbox(app.GridLayout39_21);
            app.DCheckBox_21.Text = 'D)';
            app.AkjesprvnyzpisvzahuprediskrtnukonvolciuLabel = uilabel(app.GridLayout39_21);
            app.AkjesprvnyzpisvzahuprediskrtnukonvolciuLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisvzahuprediskrtnukonvolciuLabel.Text = 'Aký je správny zápis vzťahu pre diskrétnu konvolúciu?';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_6 = uilabel(app.GridLayout39_21);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_6.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_6.Text = '$$\sum_{k=0}^{N-1} x\left\lbrack k\right\rbrack \cdot h\left\lbrack n-k\right\rbrack$$';
            app.preichopissavyuvasadtatistickchparametrovLabel_6 = uilabel(app.GridLayout39_21);
            app.preichopissavyuvasadtatistickchparametrovLabel_6.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_6.Text = '$$\sum_{k=0}^{N-1} x\left\lbrack k\right\rbrack \cdot y\left\lbrack k-n\right\rbrack$$';
            app.vasenadobdajnekonenpoethodntLabel_7 = uilabel(app.GridLayout39_21);
            app.vasenadobdajnekonenpoethodntLabel_7.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_7.Text = '$$\sum_{k=0}^{N-1} x\left\lbrack k\right\rbrack \cdot x\left\lbrack k+n\right\rbrack$$';
            app.sreprezentovansboromkonenchhodntLabel_6 = uilabel(app.GridLayout39_21);
            app.sreprezentovansboromkonenchhodntLabel_6.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_6.Text = '$$\int_{-\infty }^{\infty } R_{\mathrm{xx}} \left(\tau \right)e^{-j\omega \tau } \;d\tau$$';
            app.KomplexO2Panel_6 = uipanel(app.GridLayout38_6);
            app.KomplexO2Panel_6.Title = 'Otázka č.2';
            app.GridLayout39_22 = uigridlayout(app.KomplexO2Panel_6);
            app.ACheckBox_22 = uicheckbox(app.GridLayout39_22);
            app.ACheckBox_22.Text = 'A)';
            app.BCheckBox_22 = uicheckbox(app.GridLayout39_22);
            app.BCheckBox_22.Text = 'B)';
            app.CCheckBox_22 = uicheckbox(app.GridLayout39_22);
            app.CCheckBox_22.Text = 'C)';
            app.DCheckBox_22 = uicheckbox(app.GridLayout39_22);
            app.DCheckBox_22.Text = 'D)';
            app.AkjesprvnyzpisvzahuprespojitautokorelanfunkciuLabel = uilabel(app.GridLayout39_22);
            app.AkjesprvnyzpisvzahuprespojitautokorelanfunkciuLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisvzahuprespojitautokorelanfunkciuLabel.Text = 'Aký je správny zápis vzťahu pre spojitú autokorelačnú funkciu?';
            app.int_inftyinftyxlefttrightcdotxleftttaurightdtauLabel = uilabel(app.GridLayout39_22);
            app.int_inftyinftyxlefttrightcdotxleftttaurightdtauLabel.Interpreter = 'latex';
            app.int_inftyinftyxlefttrightcdotxleftttaurightdtauLabel.Text = '$$\int_{-\infty }^{\infty } x\left(t\right)\cdot x\left(t-\tau \right)\;d\tau$$';
            app.int_inftyinftyxlefttrightcdotyleftttaurightdtauLabel = uilabel(app.GridLayout39_22);
            app.int_inftyinftyxlefttrightcdotyleftttaurightdtauLabel.Interpreter = 'latex';
            app.int_inftyinftyxlefttrightcdotyleftttaurightdtauLabel.Text = '$$\int_{-\infty }^{\infty } x\left(t\right)\cdot y\left(t-\tau \right)\;d\tau$$';
            app.int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel = uilabel(app.GridLayout39_22);
            app.int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel.Interpreter = 'latex';
            app.int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel.Text = '$$\int_{-\infty }^{\infty } R_{\mathrm{xx}} \left(\tau \right)e^{-j\omega \tau } \;d\tau$$';
            app.int_inftyinftyxlefttaurightcdotyleftttaurightdtauLabel = uilabel(app.GridLayout39_22);
            app.int_inftyinftyxlefttaurightcdotyleftttaurightdtauLabel.Interpreter = 'latex';
            app.int_inftyinftyxlefttaurightcdotyleftttaurightdtauLabel.Text = '$$\int_{-\infty }^{\infty } x\left(\tau \right)\cdot y\left(t-\tau \right)\;d\tau$$';
            app.KomplexO3Panel_6 = uipanel(app.GridLayout38_6);
            app.KomplexO3Panel_6.Title = 'Otázka č.3';
            app.GridLayout39_23 = uigridlayout(app.KomplexO3Panel_6);
            app.ACheckBox_23 = uicheckbox(app.GridLayout39_23);
            app.ACheckBox_23.Text = 'A)';
            app.BCheckBox_23 = uicheckbox(app.GridLayout39_23);
            app.BCheckBox_23.Text = 'B)';
            app.CCheckBox_23 = uicheckbox(app.GridLayout39_23);
            app.CCheckBox_23.Text = 'C)';
            app.DCheckBox_23 = uicheckbox(app.GridLayout39_23);
            app.DCheckBox_23.Text = 'D)';
            app.VsledndkadiskrtnejkonvolciejedanvzahomLabel = uilabel(app.GridLayout39_23);
            app.VsledndkadiskrtnejkonvolciejedanvzahomLabel.Interpreter = 'latex';
            app.VsledndkadiskrtnejkonvolciejedanvzahomLabel.Text = 'Výsledná dĺžka diskrétnej konvolúcie je daná vzťahom:';
            app.D_yD_xD_h1Label = uilabel(app.GridLayout39_23);
            app.D_yD_xD_h1Label.Interpreter = 'latex';
            app.D_yD_xD_h1Label.Text = '$$D_y =D_x +D_h -1$$';
            app.D_yD_xD_hLabel = uilabel(app.GridLayout39_23);
            app.D_yD_xD_hLabel.Interpreter = 'latex';
            app.D_yD_xD_hLabel.Text = '$$D_y =D_x +D_h$$';
            app.D_yD_xD_h1Label_2 = uilabel(app.GridLayout39_23);
            app.D_yD_xD_h1Label_2.Interpreter = 'latex';
            app.D_yD_xD_h1Label_2.Text = '$$D_y =D_x -D_h +1$$';
            app.D_yD_xD_hLabel_2 = uilabel(app.GridLayout39_23);
            app.D_yD_xD_hLabel_2.Interpreter = 'latex';
            app.D_yD_xD_hLabel_2.Text = '$$D_y =D_x -D_h$$';
            app.KomplexO4Panel_6 = uipanel(app.GridLayout38_6);
            app.KomplexO4Panel_6.Title = 'Otázka č.4';
            app.GridLayout39_24 = uigridlayout(app.KomplexO4Panel_6);

            app.ACheckBox_24 = uicheckbox(app.GridLayout39_24);
            app.ACheckBox_24.Text = 'A)';
            app.BCheckBox_24 = uicheckbox(app.GridLayout39_24);
            app.BCheckBox_24.Text = 'B)';
            app.CCheckBox_24 = uicheckbox(app.GridLayout39_24);
            app.CCheckBox_24.Text = 'C)';
            app.DCheckBox_24 = uicheckbox(app.GridLayout39_24);
            app.DCheckBox_24.Text = 'D)';
            app.AkjesprvnyzpisvzahuprediskrtnukrovkorelciuLabel = uilabel(app.GridLayout39_24);
            app.AkjesprvnyzpisvzahuprediskrtnukrovkorelciuLabel.Interpreter = 'latex';
            app.AkjesprvnyzpisvzahuprediskrtnukrovkorelciuLabel.Text = 'Aký je správny zápis vzťahu pre diskrétnu krížovú koreláciu?';
            app.spojitLabel_15 = uilabel(app.GridLayout39_24);
            app.spojitLabel_15.Interpreter = 'latex';
            app.spojitLabel_15.Text = '$$\sum_{k=0}^{N-1} x\left\lbrack k\right\rbrack \cdot h\left\lbrack n-k\right\rbrack$$';
            app.diskrtneLabel_14 = uilabel(app.GridLayout39_24);
            app.diskrtneLabel_14.Interpreter = 'latex';
            app.diskrtneLabel_14.Text = '$$\sum_{k=0}^{N-1} x\left\lbrack k\right\rbrack \cdot y\left\lbrack k-n\right\rbrack$$';
            app.frekvenneneohranienLabel_3 = uilabel(app.GridLayout39_24);
            app.frekvenneneohranienLabel_3.Interpreter = 'latex';
            app.frekvenneneohranienLabel_3.Text = '$$\sum_{k=0}^{N-1} x\left\lbrack k\right\rbrack \cdot x\left\lbrack k+n\right\rbrack$$';
            app.int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel_2 = uilabel(app.GridLayout39_24);
            app.int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel_2.Interpreter = 'latex';
            app.int_inftyinftyR_mathrmxxlefttaurightejomegataudtauLabel_2.Text = '$$\int_{-\infty }^{\infty } R_{\mathrm{xx}} \left(\tau \right)e^{-j\omega \tau } \;d\tau$$';
            app.KomplRestartButton_6 = uibutton(app.GridLayout38_6, 'push');
            app.KomplRestartButton_6.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_6Pushed, true);
            app.KomplRestartButton_6.Text = 'Restart';
            app.KomplSubmitButton_6 = uibutton(app.GridLayout38_6, 'push');
            app.KomplSubmitButton_6.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_6Pushed, true);
            app.KomplSubmitButton_6.Text = 'Submit';
            app.KonvKorRiesenyPrikladTab = uitab(app.KonvKorTabGroup);
            app.KonvKorRiesenyPrikladTab.Title = 'Riešené príklady';
            app.GridLayout20_3 = uigridlayout(app.KonvKorRiesenyPrikladTab);
            app.KonvKorRiesenyPrikladHTML = uihtml(app.GridLayout20_3);
            app.KonvKorRiesenyPrikladHTML.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/riesenyPriklad.html';
            app.KonvKorRiesenyPrikladCodeAreaHTML = uihtml(app.GridLayout20_3);
            app.KonvKorRiesenyPrikladCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_KonvKor_Rieseny_Priklad.html';
            app.MATLABCODEEDITORLabel_9 = uilabel(app.GridLayout20_3);
            app.MATLABCODEEDITORLabel_9.Text = 'MATLAB CODE EDITOR';
            app.KonvKorCodeRiesenyPrikladButton = uibutton(app.GridLayout20_3, 'push');
            app.KonvKorCodeRiesenyPrikladButton.ButtonPushedFcn = createCallbackFcn(app, @KonvKorCodeRiesenyPrikladButtonPushed, true);
            app.KonvKorCodeRiesenyPrikladButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.KonvKorCodeRiesenyPrikladButton.Text = '';
            app.KonvKorRiesenyPrikaldPanelKonzola = uipanel(app.GridLayout20_3);
            app.KonvKorRiesenyPrikaldPanelKonzola.Title = 'Konzola';
            app.GridLayout17_5 = uigridlayout(app.KonvKorRiesenyPrikaldPanelKonzola);
            app.KonvKorRiesenyPrikladKonzolaTextArea = uitextarea(app.GridLayout17_5);
            app.KonvKorRiesenyPrikladKonzolaTextArea.Editable = 'off';
            app.CTRLSPACEukepremennafunkcieLabel_9 = uilabel(app.GridLayout20_3);
            app.CTRLSPACEukepremennafunkcieLabel_9.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.KonvKorPrikladKovnTab = uitab(app.KonvKorTabGroup);
            app.KonvKorPrikladKovnTab.Title = 'Príklad na konvolúciu';
            app.GridLayout16_4 = uigridlayout(app.KonvKorPrikladKovnTab);
            app.KonvKorZadanieUlohyHTMLKonv = uihtml(app.GridLayout16_4);
            app.KonvKorZadanieUlohyHTMLKonv.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/prikladkonv.html';
            app.KonvKorPanelKonzolaKonv = uipanel(app.GridLayout16_4);
            app.KonvKorPanelKonzolaKonv.Title = 'Konzola';
            app.GridLayout17_6 = uigridlayout(app.KonvKorPanelKonzolaKonv);
            app.KonvKorKonzolaTextAreaKonv = uitextarea(app.GridLayout17_6);
            app.KonvKorKonzolaTextAreaKonv.Editable = 'off';
            app.KonvKorUlohaCodeAreaHTMLKonv = uihtml(app.GridLayout16_4);
            app.KonvKorUlohaCodeAreaHTMLKonv.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.MATLABCODEEDITORLabel_10 = uilabel(app.GridLayout16_4);
            app.MATLABCODEEDITORLabel_10.Text = 'MATLAB CODE EDITOR';
            app.KonvKorRunCodeUlohaButtonKonv = uibutton(app.GridLayout16_4, 'push');
            app.KonvKorRunCodeUlohaButtonKonv.ButtonPushedFcn = createCallbackFcn(app, @KonvKorRunCodeUlohaButtonKonvPushed, true);
            app.KonvKorRunCodeUlohaButtonKonv.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.KonvKorRunCodeUlohaButtonKonv.Text = '';
            app.KonvKorShowAnswerButtonKonv = uibutton(app.GridLayout16_4, 'push');
            app.KonvKorShowAnswerButtonKonv.ButtonPushedFcn = createCallbackFcn(app, @KonvKorShowAnswerButtonKonvPushed, true);
            app.KonvKorShowAnswerButtonKonv.Text = 'Show answer';
            app.KonvKorRestartButtonKonv = uibutton(app.GridLayout16_4, 'push');
            app.KonvKorRestartButtonKonv.ButtonPushedFcn = createCallbackFcn(app, @KonvKorRestartButtonKonvPushed, true);
            app.KonvKorRestartButtonKonv.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel_10 = uilabel(app.GridLayout16_4);
            app.CTRLSPACEukepremennafunkcieLabel_10.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.KonvKorKonvDalsiePrikladyHyperlink = uihyperlink(app.GridLayout16_4);
            app.KonvKorKonvDalsiePrikladyHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @KonvKorKonvDalsiePrikladyHyperlinkClicked, true);
            app.KonvKorKonvDalsiePrikladyHyperlink.Text = 'Ďalší príklad TU';
            app.Hyperlink2_6 = uihyperlink(app.GridLayout16_4);
            app.Hyperlink2_6.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_6Clicked, true);
            app.Hyperlink2_6.Text = 'HELP';
            app.KonvKorPrikladKovnTab_2 = uitab(app.KonvKorTabGroup);
            app.KonvKorPrikladKovnTab_2.Title = 'Príklad na koreláciu';
            app.GridLayout16_6 = uigridlayout(app.KonvKorPrikladKovnTab_2);
            app.KonvKorZadanieUlohyHTMLKor = uihtml(app.GridLayout16_6);
            app.KonvKorZadanieUlohyHTMLKor.HTMLSource = 'kapitoly/korelacia_konvolucia/HTML/prikladkor.html';
            app.KonvKorPanelKonzolaKor = uipanel(app.GridLayout16_6);
            app.KonvKorPanelKonzolaKor.Title = 'Konzola';
            app.GridLayout17_8 = uigridlayout(app.KonvKorPanelKonzolaKor);
            app.KonvKorKonzolaTextAreaKor = uitextarea(app.GridLayout17_8);
            app.KonvKorKonzolaTextAreaKor.Editable = 'off';
            app.KonvKorUlohaCodeAreaHTMLKor = uihtml(app.GridLayout16_6);
            app.KonvKorUlohaCodeAreaHTMLKor.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.MATLABCODEEDITORLabel_12 = uilabel(app.GridLayout16_6);
            app.MATLABCODEEDITORLabel_12.Text = 'MATLAB CODE EDITOR';
            app.KonvKorRunCodeUlohaButtonKor = uibutton(app.GridLayout16_6, 'push');
            app.KonvKorRunCodeUlohaButtonKor.ButtonPushedFcn = createCallbackFcn(app, @KonvKorRunCodeUlohaButtonKorPushed, true);
            app.KonvKorRunCodeUlohaButtonKor.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.KonvKorRunCodeUlohaButtonKor.Text = '';
            app.KonvKorShowAnswerButtonKor = uibutton(app.GridLayout16_6, 'push');
            app.KonvKorShowAnswerButtonKor.ButtonPushedFcn = createCallbackFcn(app, @KonvKorShowAnswerButtonKorPushed, true);
            app.KonvKorShowAnswerButtonKor.Text = 'Show answer';
            app.KonvKorRestartButtonKor = uibutton(app.GridLayout16_6, 'push');
            app.KonvKorRestartButtonKor.ButtonPushedFcn = createCallbackFcn(app, @KonvKorRestartButtonKorPushed, true);
            app.KonvKorRestartButtonKor.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel_11 = uilabel(app.GridLayout16_6);
            app.CTRLSPACEukepremennafunkcieLabel_11.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.KonvKorKorDalsiePrikladyHyperlink = uihyperlink(app.GridLayout16_6);
            app.KonvKorKorDalsiePrikladyHyperlink.HyperlinkClickedFcn = createCallbackFcn(app, @KonvKorKorDalsiePrikladyHyperlinkClicked, true);
            app.KonvKorKorDalsiePrikladyHyperlink.Text = 'Ďalší príklad TU';
            app.Hyperlink2_7 = uihyperlink(app.GridLayout16_6);
            app.Hyperlink2_7.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_7Clicked, true);
            app.Hyperlink2_7.Text = 'HELP';
            app.StochSignalyMainTab = uitab(app.RootTabGroup);
            app.StochSignalyMainTab.Title = 'Stochastické signaly';
            app.GridLayout26 = uigridlayout(app.StochSignalyMainTab);
            app.StochSignalyTabGroup = uitabgroup(app.GridLayout26);
            app.StochSignalyTeoriaTab = uitab(app.StochSignalyTabGroup);
            app.StochSignalyTeoriaTab.Title = 'Teória';
            app.GridLayout27 = uigridlayout(app.StochSignalyTeoriaTab);
            app.StochSignalyTeoriaHTML = uihtml(app.GridLayout27);
            app.StochSignalyTeoriaHTML.HTMLSource = 'kapitoly/stochasticke_signaly/HTML/stochasticke_signaly.html';
            app.StochSignalyInteraktivnaUkazka2Tab = uitab(app.StochSignalyTabGroup);
            app.StochSignalyInteraktivnaUkazka2Tab.Title = 'Interaktivna ukážka nestacionarity';
            app.GridLayout10_5 = uigridlayout(app.StochSignalyInteraktivnaUkazka2Tab);
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2 = uiaxes(app.GridLayout10_5);
            title(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2, 'Hustota rozloženia T1 a T2')
            xlabel(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2, 'x')
            ylabel(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2, 'Px')
            zlabel(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2, 'Z')
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2.XLim = [3.5 7];
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes_2.YLim = [0 25];
            app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2 = uiaxes(app.GridLayout10_5);
            title(app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2, 'Vzorka náhodného signálu')
            xlabel(app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2, 't')
            ylabel(app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2, 'x(t)')
            zlabel(app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2, 'Z')
            app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2.XLim = [0 1001];
            app.StochSignalyInteraktivnaUkazkaSignalUIAxes_2.XTick = [];
            app.T1SliderLabel_2 = uilabel(app.GridLayout10_5);
            app.T1SliderLabel_2.Text = 'T1';
            app.StochSignalyInteraktivnaUkazkaT1Slider_2 = uislider(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaT1Slider_2.Limits = [101 1001];
            app.StochSignalyInteraktivnaUkazkaT1Slider_2.MajorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT1Slider_2.ValueChangedFcn = createCallbackFcn(app, @StochSignalyInteraktivnaUkazkaT1Slider_2ValueChanged, true);
            app.StochSignalyInteraktivnaUkazkaT1Slider_2.MinorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT1Slider_2.Value = 101;
            app.T2SliderLabel_2 = uilabel(app.GridLayout10_5);
            app.T2SliderLabel_2.Text = 'T2';
            app.StochSignalyInteraktivnaUkazkaT2Slider_2 = uislider(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaT2Slider_2.Limits = [101 1001];
            app.StochSignalyInteraktivnaUkazkaT2Slider_2.MajorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT2Slider_2.ValueChangedFcn = createCallbackFcn(app, @StochSignalyInteraktivnaUkazkaT2Slider_2ValueChanged, true);
            app.StochSignalyInteraktivnaUkazkaT2Slider_2.MinorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT2Slider_2.Value = 301;
            app.StrednahodnotaT1Label_2 = uilabel(app.GridLayout10_5);
            app.StrednahodnotaT1Label_2.Text = 'Stredna hodnota T1 =';
            app.RozptylT1Label_2 = uilabel(app.GridLayout10_5);
            app.RozptylT1Label_2.Text = 'Rozptyl T1 =';
            app.Label1_3 = uilabel(app.GridLayout10_5);
            app.Label1_3.Text = 'Štandardná odchylka T1 =';
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label_2 = uilabel(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label_2.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaRozptylT1Label_2 = uilabel(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaRozptylT1Label_2.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaOdchylkaT1Label_2 = uilabel(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaOdchylkaT1Label_2.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label_2 = uilabel(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label_2.Text = '0.58746';
            app.StrednahodnotaT2Label_2 = uilabel(app.GridLayout10_5);
            app.StrednahodnotaT2Label_2.Text = 'Stredna hodnota T2 =';
            app.StochSignalyInteraktivnaUkazkaRozptylT2Label_2 = uilabel(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaRozptylT2Label_2.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaOdchylkaT2Label_2 = uilabel(app.GridLayout10_5);
            app.StochSignalyInteraktivnaUkazkaOdchylkaT2Label_2.Text = '0.58746';
            app.tatistickparametreLabel_2 = uilabel(app.GridLayout10_5);
            app.tatistickparametreLabel_2.Text = 'Štatistické parametre';
            app.RozptylT2Label_2 = uilabel(app.GridLayout10_5);
            app.RozptylT2Label_2.Text = 'Rozptyl T2 =';
            app.Label1_4 = uilabel(app.GridLayout10_5);
            app.Label1_4.Text = 'Štandardná odchylka T2 =';
            app.StochSignalyInteraktivnaUkazkaTab = uitab(app.StochSignalyTabGroup);
            app.StochSignalyInteraktivnaUkazkaTab.Title = 'Interaktivna ukážka stacionarity';
            app.GridLayout10_4 = uigridlayout(app.StochSignalyInteraktivnaUkazkaTab);
            app.StochSignalyInteraktivnaUkazkaHustota1UIAxes = uiaxes(app.GridLayout10_4);
            title(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes, 'Hustota rozloženia T1 a T2')
            xlabel(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes, 'x')
            ylabel(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes, 'Px')
            zlabel(app.StochSignalyInteraktivnaUkazkaHustota1UIAxes, 'Z')
            app.StochSignalyInteraktivnaUkazkaSignalUIAxes = uiaxes(app.GridLayout10_4);
            title(app.StochSignalyInteraktivnaUkazkaSignalUIAxes, 'Vzorka náhodného signálu')
            xlabel(app.StochSignalyInteraktivnaUkazkaSignalUIAxes, 't')
            ylabel(app.StochSignalyInteraktivnaUkazkaSignalUIAxes, 'x(t)')
            zlabel(app.StochSignalyInteraktivnaUkazkaSignalUIAxes, 'Z')
            app.StochSignalyInteraktivnaUkazkaSignalUIAxes.XLim = [0 1001];
            app.StochSignalyInteraktivnaUkazkaSignalUIAxes.YLim = [3.5 7];
            app.StochSignalyInteraktivnaUkazkaSignalUIAxes.XTick = [];
            app.T1SliderLabel = uilabel(app.GridLayout10_4);
            app.T1SliderLabel.Text = 'T1';
            app.StochSignalyInteraktivnaUkazkaT1Slider = uislider(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaT1Slider.Limits = [101 1001];
            app.StochSignalyInteraktivnaUkazkaT1Slider.MajorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT1Slider.ValueChangedFcn = createCallbackFcn(app, @StochSignalyInteraktivnaUkazkaT1SliderValueChanged, true);
            app.StochSignalyInteraktivnaUkazkaT1Slider.MinorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT1Slider.Value = 101;
            app.T2SliderLabel = uilabel(app.GridLayout10_4);
            app.T2SliderLabel.Text = 'T2';
            app.StochSignalyInteraktivnaUkazkaT2Slider = uislider(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaT2Slider.Limits = [101 1001];
            app.StochSignalyInteraktivnaUkazkaT2Slider.MajorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT2Slider.ValueChangedFcn = createCallbackFcn(app, @StochSignalyInteraktivnaUkazkaT2SliderValueChanged, true);
            app.StochSignalyInteraktivnaUkazkaT2Slider.MinorTicks = [];
            app.StochSignalyInteraktivnaUkazkaT2Slider.Value = 301;
            app.StrednahodnotaT1Label = uilabel(app.GridLayout10_4);
            app.StrednahodnotaT1Label.Text = 'Stredna hodnota T1 =';
            app.RozptylT1Label = uilabel(app.GridLayout10_4);
            app.RozptylT1Label.Text = 'Rozptyl T1 =';
            app.Label1 = uilabel(app.GridLayout10_4);
            app.Label1.Text = 'Štandardná odchylka T1 =';
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label = uilabel(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT1Label.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaRozptylT1Label = uilabel(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaRozptylT1Label.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaOdchylkaT1Label = uilabel(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaOdchylkaT1Label.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label = uilabel(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaStrednaHodnotaT2Label.Text = '0.58746';
            app.StrednahodnotaT2Label = uilabel(app.GridLayout10_4);
            app.StrednahodnotaT2Label.Text = 'Stredna hodnota T2 =';
            app.StochSignalyInteraktivnaUkazkaRozptylT2Label = uilabel(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaRozptylT2Label.Text = '0.58746';
            app.StochSignalyInteraktivnaUkazkaOdchylkaT2Label = uilabel(app.GridLayout10_4);
            app.StochSignalyInteraktivnaUkazkaOdchylkaT2Label.Text = '0.58746';
            app.tatistickparametreLabel = uilabel(app.GridLayout10_4);
            app.tatistickparametreLabel.Text = 'Štatistické parametre';
            app.RozptylT2Label = uilabel(app.GridLayout10_4);
            app.RozptylT2Label.Text = 'Rozptyl T2 =';
            app.Label1_2 = uilabel(app.GridLayout10_4);
            app.Label1_2.Text = 'Štandardná odchylka T2 =';
            app.irkaT12Label = uilabel(app.GridLayout10_4);
            app.irkaT12Label.Text = 'Širka T';
            app.StochSignalySirkaSlider = uislider(app.GridLayout10_4);
            app.StochSignalySirkaSlider.Limits = [5 100];
            app.StochSignalySirkaSlider.MajorTicks = [];
            app.StochSignalySirkaSlider.ValueChangedFcn = createCallbackFcn(app, @StochSignalySirkaSliderValueChanged, true);
            app.StochSignalySirkaSlider.MinorTicks = [];
            app.StochSignalySirkaSlider.Value = 100;
            app.KedzmenmeTnestacionrnyLabel = uilabel(app.GridLayout10_4);
            app.KedzmenmeTnestacionrnyLabel.Text = 'Ked zmenšíme T => nestacionárny';
            app.TeoreticktestTab_7 = uitab(app.StochSignalyTabGroup);
            app.TeoreticktestTab_7.Title = 'Teoretický test';
            app.GridLayout38_7 = uigridlayout(app.TeoreticktestTab_7);
            app.KomplexO1Panel_7 = uipanel(app.GridLayout38_7);
            app.KomplexO1Panel_7.Title = 'Otázka č.1';
            app.GridLayout39_25 = uigridlayout(app.KomplexO1Panel_7);
            app.ACheckBox_25 = uicheckbox(app.GridLayout39_25);
            app.ACheckBox_25.Text = 'A)';
            app.BCheckBox_25 = uicheckbox(app.GridLayout39_25);
            app.BCheckBox_25.Text = 'B)';
            app.CCheckBox_25 = uicheckbox(app.GridLayout39_25);
            app.CCheckBox_25.Text = 'C)';
            app.DCheckBox_25 = uicheckbox(app.GridLayout39_25);
            app.DCheckBox_25.Text = 'D)';
            app.ojepravdaostochastickchsignlochLabel = uilabel(app.GridLayout39_25);
            app.ojepravdaostochastickchsignlochLabel.Interpreter = 'latex';
            app.ojepravdaostochastickchsignlochLabel.Text = 'Čo je pravda o stochastických signáloch? ';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_7 = uilabel(app.GridLayout39_25);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_7.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_7.Text = 'môžeme ich opísať rovnicou $A\sin \left(\omega t+\varphi \right)$';
            app.preichopissavyuvasadtatistickchparametrovLabel_7 = uilabel(app.GridLayout39_25);
            app.preichopissavyuvasadtatistickchparametrovLabel_7.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_7.Text = 'vieme vopred určiť ich hodnotu v čase';
            app.vasenadobdajnekonenpoethodntLabel_8 = uilabel(app.GridLayout39_25);
            app.vasenadobdajnekonenpoethodntLabel_8.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_8.Text = 'sú periodické ';
            app.sreprezentovansboromkonenchhodntLabel_7 = uilabel(app.GridLayout39_25);
            app.sreprezentovansboromkonenchhodntLabel_7.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_7.Text = 'pre opis sa využíva súbor štatistických parametrov';
            app.KomplexO2Panel_7 = uipanel(app.GridLayout38_7);
            app.KomplexO2Panel_7.Title = 'Otázka č.2';
            app.GridLayout39_26 = uigridlayout(app.KomplexO2Panel_7);
            app.ACheckBox_26 = uicheckbox(app.GridLayout39_26);
            app.ACheckBox_26.Text = 'A)';
            app.BCheckBox_26 = uicheckbox(app.GridLayout39_26);
            app.BCheckBox_26.Text = 'B)';
            app.CCheckBox_26 = uicheckbox(app.GridLayout39_26);
            app.CCheckBox_26.Text = 'C)';
            app.DCheckBox_26 = uicheckbox(app.GridLayout39_26);
            app.DCheckBox_26.Text = 'D)';
            app.StacionrnestochasticksignlyspecificktmeLabel = uilabel(app.GridLayout39_26);
            app.StacionrnestochasticksignlyspecificktmeLabel.Interpreter = 'latex';
            app.StacionrnestochasticksignlyspecificktmeLabel.Text = 'Stacionárne stochastické signály sú špecifické tým, že: ';
            app.majurittendenciurozvojavaseLabel = uilabel(app.GridLayout39_26);
            app.majurittendenciurozvojavaseLabel.Interpreter = 'latex';
            app.majurittendenciurozvojavaseLabel.Text = 'majú určitú tendenciu rozvoja v čase ';
            app.tatistickparametresanemeniasasomLabel = uilabel(app.GridLayout39_26);
            app.tatistickparametresanemeniasasomLabel.Interpreter = 'latex';
            app.tatistickparametresanemeniasasomLabel.Text = 'štatistické parametre sa nemenia s časom';
            app.tatistickparametresameniasasomLabel = uilabel(app.GridLayout39_26);
            app.tatistickparametresameniasasomLabel.Interpreter = 'latex';
            app.tatistickparametresameniasasomLabel.Text = 'štatistické parametre sa menia s časom';
            app.preopissanevyuvasbortatistickchparametrovLabel = uilabel(app.GridLayout39_26);
            app.preopissanevyuvasbortatistickchparametrovLabel.Interpreter = 'latex';
            app.preopissanevyuvasbortatistickchparametrovLabel.Text = 'pre opis sa nevyužíva súbor štatistických parametrov';
            app.KomplexO3Panel_7 = uipanel(app.GridLayout38_7);
            app.KomplexO3Panel_7.Title = 'Otázka č.3';
            app.GridLayout39_27 = uigridlayout(app.KomplexO3Panel_7);
            app.ACheckBox_27 = uicheckbox(app.GridLayout39_27);
            app.ACheckBox_27.Text = 'A)';
            app.BCheckBox_27 = uicheckbox(app.GridLayout39_27);
            app.BCheckBox_27.Text = 'B)';
            app.CCheckBox_27 = uicheckbox(app.GridLayout39_27);
            app.CCheckBox_27.Text = 'C)';
            app.DCheckBox_27 = uicheckbox(app.GridLayout39_27);
            app.DCheckBox_27.Text = 'D)';
            app.asovstrednhodnotajedanvzahomLabel = uilabel(app.GridLayout39_27);
            app.asovstrednhodnotajedanvzahomLabel.Interpreter = 'latex';
            app.asovstrednhodnotajedanvzahomLabel.Text = 'Časová stredná hodnota je daná vzťahom:';
            app.lim_Ttoinftyfrac12Tint_TTsleftttaurightdtLabel = uilabel(app.GridLayout39_27);
            app.lim_Ttoinftyfrac12Tint_TTsleftttaurightdtLabel.Interpreter = 'latex';
            app.lim_Ttoinftyfrac12Tint_TTsleftttaurightdtLabel.Text = '$$\lim_{T\to \infty \;} \;\frac{1}{2T}\int_{-T}^{+T} s\left(t+\tau \right)dt$$';
            app.lim_Ttoinftyfrac12Tint_TTleftoversetslefttrightright2dtLabel = uilabel(app.GridLayout39_27);
            app.lim_Ttoinftyfrac12Tint_TTleftoversetslefttrightright2dtLabel.Interpreter = 'latex';
            app.lim_Ttoinftyfrac12Tint_TTleftoversetslefttrightright2dtLabel.Text = '$$\lim_{T\to \infty \;} \;\frac{1}{2T}\int_{-T}^{+T} {\left(\overset{}{s\left(t\right)} \right)}^2 dt$$';
            app.lim_Ttoinftyfrac12Tint_TTslefttrightdtLabel = uilabel(app.GridLayout39_27);
            app.lim_Ttoinftyfrac12Tint_TTslefttrightdtLabel.Interpreter = 'latex';
            app.lim_Ttoinftyfrac12Tint_TTslefttrightdtLabel.Text = '$$\lim_{T\to \infty \;} \;\frac{1}{2T}\int_{-T}^{+T} s\left(t\right)dt$$';
            app.sum_n0N1xleftnrightemathrmjknLabel_2 = uilabel(app.GridLayout39_27);
            app.sum_n0N1xleftnrightemathrmjknLabel_2.Interpreter = 'latex';
            app.sum_n0N1xleftnrightemathrmjknLabel_2.Text = '$$\lim_{T\to \infty \;} \;\frac{1}{2T}\int_{-T}^{+T} {\left(\overset{}{s\left(t\right)-\;} \bar{s} \right)}^2 dt$$';
            app.KomplexO4Panel_7 = uipanel(app.GridLayout38_7);
            app.KomplexO4Panel_7.Title = 'Otázka č.4';
            app.GridLayout39_28 = uigridlayout(app.KomplexO4Panel_7);
            app.ACheckBox_28 = uicheckbox(app.GridLayout39_28);
            app.ACheckBox_28.Text = 'A)';
            app.BCheckBox_28 = uicheckbox(app.GridLayout39_28);
            app.BCheckBox_28.Text = 'B)';
            app.CCheckBox_28 = uicheckbox(app.GridLayout39_28);
            app.CCheckBox_28.Text = 'C)';
            app.DCheckBox_28 = uicheckbox(app.GridLayout39_28);
            app.DCheckBox_28.Text = 'D)';
            app.ojepravdaovkonovomspektrebielehoumuLabel = uilabel(app.GridLayout39_28);
            app.ojepravdaovkonovomspektrebielehoumuLabel.Interpreter = 'latex';
            app.ojepravdaovkonovomspektrebielehoumuLabel.Text = 'Čo je pravda o výkonovom spektre bieleho šumu? ';
            app.pozostvazkrtkychimpulzovLabel = uilabel(app.GridLayout39_28);
            app.pozostvazkrtkychimpulzovLabel.Interpreter = 'latex';
            app.pozostvazkrtkychimpulzovLabel.Text = 'pozostáva z krátkych impulzov';
            app.jeplocharovnomernerozloenvcelomspektreLabel = uilabel(app.GridLayout39_28);
            app.jeplocharovnomernerozloenvcelomspektreLabel.Interpreter = 'latex';
            app.jeplocharovnomernerozloenvcelomspektreLabel.Text = 'je ploché a rovnomerne rozložené v celom spektre';
            app.niejeplochLabel = uilabel(app.GridLayout39_28);
            app.niejeplochLabel.Interpreter = 'latex';
            app.niejeplochLabel.Text = 'nie je ploché';
            app.vznikkolziamipohybujcichsanbojovLabel = uilabel(app.GridLayout39_28);
            app.vznikkolziamipohybujcichsanbojovLabel.Interpreter = 'latex';
            app.vznikkolziamipohybujcichsanbojovLabel.Text = 'vzniká kolíziami pohybujúcich sa nábojov';
            app.KomplRestartButton_7 = uibutton(app.GridLayout38_7, 'push');
            app.KomplRestartButton_7.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_7Pushed, true);
            app.KomplRestartButton_7.Text = 'Restart';
            app.KomplSubmitButton_7 = uibutton(app.GridLayout38_7, 'push');
            app.KomplSubmitButton_7.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_7Pushed, true);
            app.KomplSubmitButton_7.Text = 'Submit';
            app.RieenprkladTab_2 = uitab(app.StochSignalyTabGroup);
            app.RieenprkladTab_2.Title = 'Riešený príklad';
            app.GridLayout16_7 = uigridlayout(app.RieenprkladTab_2);
            app.StochSignalyRiesenyUIAxes = uiaxes(app.GridLayout16_7);
            title(app.StochSignalyRiesenyUIAxes, 'Title')
            xlabel(app.StochSignalyRiesenyUIAxes, 'X')
            ylabel(app.StochSignalyRiesenyUIAxes, 'Y')
            zlabel(app.StochSignalyRiesenyUIAxes, 'Z')
            app.StochSignalyRiesenyZadanieUlohyHTML = uihtml(app.GridLayout16_7);
            app.StochSignalyRiesenyZadanieUlohyHTML.HTMLSource = 'kapitoly/stochasticke_signaly/HTML/RiesenyPriklad.html';
            app.StochSignalyRiesenyPanelKonzola = uipanel(app.GridLayout16_7);
            app.StochSignalyRiesenyPanelKonzola.Title = 'Konzola';
            app.GridLayout17_9 = uigridlayout(app.StochSignalyRiesenyPanelKonzola);
            app.StochSignalyRiesenyKonzolaTextArea = uitextarea(app.GridLayout17_9);
            app.StochSignalyRiesenyKonzolaTextArea.Editable = 'off';
            app.StochSignalyRiesenyCodeAreaHTML = uihtml(app.GridLayout16_7);
            app.StochSignalyRiesenyCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_Stoch_Rieseny_Priklad.html';
            app.MATLABCODEEDITORLabel_14 = uilabel(app.GridLayout16_7);
            app.MATLABCODEEDITORLabel_14.Text = 'MATLAB CODE EDITOR';
            app.StochSignalyRiesenyRunCodeUlohaButton = uibutton(app.GridLayout16_7, 'push');
            app.StochSignalyRiesenyRunCodeUlohaButton.ButtonPushedFcn = createCallbackFcn(app, @StochSignalyRiesenyRunCodeUlohaButtonPushed, true);
            app.StochSignalyRiesenyRunCodeUlohaButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.StochSignalyRiesenyRunCodeUlohaButton.Text = '';
            app.CTRLSPACEukepremennafunkcieLabel_12 = uilabel(app.GridLayout16_7);
            app.CTRLSPACEukepremennafunkcieLabel_12.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.PrkladTab_5 = uitab(app.StochSignalyTabGroup);
            app.PrkladTab_5.Title = 'Príklad';
            app.GridLayout16_8 = uigridlayout(app.PrkladTab_5);
            app.StochSignalyNeriesenyUIAxes = uiaxes(app.GridLayout16_8);
            title(app.StochSignalyNeriesenyUIAxes, 'Title')
            xlabel(app.StochSignalyNeriesenyUIAxes, 'X')
            ylabel(app.StochSignalyNeriesenyUIAxes, 'Y')
            zlabel(app.StochSignalyNeriesenyUIAxes, 'Z')
            app.StochSignalyNeriesenyZadanieUlohyHTML = uihtml(app.GridLayout16_8);
            app.StochSignalyNeriesenyZadanieUlohyHTML.HTMLSource = 'kapitoly/stochasticke_signaly/HTML/RiesenyPriklad.html';
            app.StochSignalyNeriesenyPanelKonzola = uipanel(app.GridLayout16_8);
            app.StochSignalyNeriesenyPanelKonzola.Title = 'Konzola';
            app.GridLayout17_10 = uigridlayout(app.StochSignalyNeriesenyPanelKonzola);
            app.StochSignalyNeriesenyKonzolaTextArea = uitextarea(app.GridLayout17_10);
            app.StochSignalyNeriesenyKonzolaTextArea.Editable = 'off';
            app.StochSignalyNeriesenyCodeAreaHTML = uihtml(app.GridLayout16_8);
            app.StochSignalyNeriesenyCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank_for_stoch.html';
            app.MATLABCODEEDITORLabel_15 = uilabel(app.GridLayout16_8);
            app.MATLABCODEEDITORLabel_15.Text = 'MATLAB CODE EDITOR';
            app.StochSignalyNeriesenyRunCodeUlohaButton = uibutton(app.GridLayout16_8, 'push');
            app.StochSignalyNeriesenyRunCodeUlohaButton.ButtonPushedFcn = createCallbackFcn(app, @StochSignalyNeriesenyRunCodeUlohaButtonPushed, true);
            app.StochSignalyNeriesenyRunCodeUlohaButton.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.StochSignalyNeriesenyRunCodeUlohaButton.Text = '';
            app.StochSignalyNeriesenyShowAnswerButton = uibutton(app.GridLayout16_8, 'push');
            app.StochSignalyNeriesenyShowAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @StochSignalyNeriesenyShowAnswerButtonPushed, true);
            app.StochSignalyNeriesenyShowAnswerButton.Text = 'Show answer';
            app.StochSignalyNeriesenyRestartButton = uibutton(app.GridLayout16_8, 'push');
            app.StochSignalyNeriesenyRestartButton.ButtonPushedFcn = createCallbackFcn(app, @StochSignalyNeriesenyRestartButtonPushed, true);
            app.StochSignalyNeriesenyRestartButton.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel_13 = uilabel(app.GridLayout16_8);
            app.CTRLSPACEukepremennafunkcieLabel_13.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.Hyperlink2_8 = uihyperlink(app.GridLayout16_8);
            app.Hyperlink2_8.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_8Clicked, true);
            app.Hyperlink2_8.Text = 'HELP';
            app.PRSMainTab = uitab(app.RootTabGroup);
            app.PRSMainTab.Title = 'Pseudonáhodná postupnosť';
            app.GridLayout30 = uigridlayout(app.PRSMainTab);
            app.PRSTabGroup = uitabgroup(app.GridLayout30);
            app.PRSTeoria = uitab(app.PRSTabGroup);
            app.PRSTeoria.Title = 'Teória';
            app.GridLayout31 = uigridlayout(app.PRSTeoria);
            app.HTML6 = uihtml(app.GridLayout31);
            app.HTML6.HTMLSource = 'kapitoly/psuedonahodna_postupnost/html/teoria.html';
            app.PRSUkazka = uitab(app.PRSTabGroup);
            app.PRSUkazka.Title = 'Ukážka generovania pseudonáhodnej postupnosti';
            app.GridLayout32 = uigridlayout(app.PRSUkazka);
            app.PRSGenUIAxes = uiaxes(app.GridLayout32);
            xlabel(app.PRSGenUIAxes, 't')
            ylabel(app.PRSGenUIAxes, 'x(t)')
            zlabel(app.PRSGenUIAxes, 'Z')
            app.PRSGenUIAxes.YLim = [-0.5 1.5];
            app.PRSGenUIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.PRSGenUIAxes.Visible = 'off';
            app.PRSGenUIImage = uiimage(app.GridLayout32);
            app.PRSGenUIImage.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'psuedonahodna_postupnost', 'img', 'SimulinkPseudoSchema.png');
            app.GenerovaniepseudonhodnejpostupnostiPanel = uipanel(app.GridLayout32);
            app.GenerovaniepseudonhodnejpostupnostiPanel.Title = 'Generovanie pseudonáhodnej postupnosti';
            app.GridLayout37 = uigridlayout(app.GenerovaniepseudonhodnejpostupnostiPanel);
            app.PRSGenImage = uiimage(app.GridLayout37);
            app.PRSGenImage.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'psuedonahodna_postupnost', 'img', '1.png');
            app.PRSGenDoLava = uibutton(app.GridLayout37, 'push');
            app.PRSGenDoLava.ButtonPushedFcn = createCallbackFcn(app, @PRSGenDoLavaPushed, true);
            app.PRSGenDoLava.Text = '<';
            app.PRSGenDoPrava = uibutton(app.GridLayout37, 'push');
            app.PRSGenDoPrava.ButtonPushedFcn = createCallbackFcn(app, @PRSGenDoPravaPushed, true);
            app.PRSGenDoPrava.Text = '>';
            app.asovpriebehUPNRZlinkovkdLabel = uilabel(app.GridLayout32);
            app.asovpriebehUPNRZlinkovkdLabel.Text = 'Časový priebeh (UP NRZ linkový kód) ';
            app.UkkameraniachybovostikanlaTab = uitab(app.PRSTabGroup);
            app.UkkameraniachybovostikanlaTab.Title = 'Ukážka merania chybovosti kanála';
            app.GridLayout33 = uigridlayout(app.UkkameraniachybovostikanlaTab);
            app.PRSPrijKanalUIAxes = uiaxes(app.GridLayout33);
            title(app.PRSPrijKanalUIAxes, 'Vyslaná postupnosť')
            xlabel(app.PRSPrijKanalUIAxes, 't')
            ylabel(app.PRSPrijKanalUIAxes, 'x(t)')
            zlabel(app.PRSPrijKanalUIAxes, 'Z')
            app.PRSPrijKanalUIAxes.XLim = [1 16];
            app.PRSPrijKanalUIAxes.YLim = [-0.5 1.5];
            app.PRSPrijKanalUIAxes.Visible = 'off';
            app.PRSVysKanalUIAxes = uiaxes(app.GridLayout33);
            title(app.PRSVysKanalUIAxes, 'Prijatá postupnosť')
            xlabel(app.PRSVysKanalUIAxes, 't')
            ylabel(app.PRSVysKanalUIAxes, 'x(t)')
            zlabel(app.PRSVysKanalUIAxes, 'Z')
            app.PRSVysKanalUIAxes.XLim = [1 16];
            app.PRSVysKanalUIAxes.YLim = [-0.5 1.5];
            app.PRSVysKanalUIAxes.Visible = 'off';
            app.PRSKanalImage = uiimage(app.GridLayout33);
            app.PRSKanalImage.ImageSource = fullfile(pathToMLAPP, 'kapitoly', 'psuedonahodna_postupnost', 'img', 'Kanal.png');
            app.PRSKanalRunSimulationButton = uibutton(app.GridLayout33, 'push');
            app.PRSKanalRunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @PRSKanalRunSimulationButtonPushed, true);
            app.PRSKanalRunSimulationButton.Text = 'Run Simulation';
            app.NeLabel = uilabel(app.GridLayout33);
            app.NeLabel.Visible = 'off';
            app.NeLabel.Text = 'Ne = ';
            app.NtbLabel = uilabel(app.GridLayout33);
            app.NtbLabel.Visible = 'off';
            app.NtbLabel.Text = 'Ntb = ';
            app.BERNeNtbLabel = uilabel(app.GridLayout33);
            app.BERNeNtbLabel.Visible = 'off';
            app.BERNeNtbLabel.Text = 'BER = Ne/Ntb';
            app.BERLabel = uilabel(app.GridLayout33);
            app.BERLabel.Visible = 'off';
            app.BERLabel.Text = 'BER = ';
            app.asovpriebehUPNRZlinkovkdLabel_2 = uilabel(app.GridLayout33);
            app.asovpriebehUPNRZlinkovkdLabel_2.Visible = 'off';
            app.asovpriebehUPNRZlinkovkdLabel_2.Text = 'Časový priebeh (UP NRZ linkový kód) ';
            app.TeoreticktestTab_8 = uitab(app.PRSTabGroup);
            app.TeoreticktestTab_8.Title = 'Teoretický test';
            app.GridLayout38_8 = uigridlayout(app.TeoreticktestTab_8);
            app.KomplexO1Panel_8 = uipanel(app.GridLayout38_8);
            app.KomplexO1Panel_8.Title = 'Otázka č.1';
            app.GridLayout39_29 = uigridlayout(app.KomplexO1Panel_8);
            app.ACheckBox_29 = uicheckbox(app.GridLayout39_29);
            app.ACheckBox_29.Text = 'A)';
            app.BCheckBox_29 = uicheckbox(app.GridLayout39_29);
            app.BCheckBox_29.Text = 'B)';
            app.CCheckBox_29 = uicheckbox(app.GridLayout39_29);
            app.CCheckBox_29.Text = 'C)';
            app.DCheckBox_29 = uicheckbox(app.GridLayout39_29);
            app.DCheckBox_29.Text = 'D)';
            app.MaximlnadkapseudonhodnejpostupnostijedanvzahomLabel = uilabel(app.GridLayout39_29);
            app.MaximlnadkapseudonhodnejpostupnostijedanvzahomLabel.Interpreter = 'latex';
            app.MaximlnadkapseudonhodnejpostupnostijedanvzahomLabel.Text = 'Maximálna dĺžka pseudonáhodnej postupnosti je daná vzťahom: ';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_8 = uilabel(app.GridLayout39_29);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_8.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_8.Text = '$$T=\left(2^n -2\right)\cdot T_T$$';
            app.preichopissavyuvasadtatistickchparametrovLabel_8 = uilabel(app.GridLayout39_29);
            app.preichopissavyuvasadtatistickchparametrovLabel_8.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_8.Text = '$$T=\left(2^n -1\right)$$';
            app.vasenadobdajnekonenpoethodntLabel_9 = uilabel(app.GridLayout39_29);
            app.vasenadobdajnekonenpoethodntLabel_9.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_9.Text = '$$T=\left(2^n +1\right)\cdot T_T$$';
            app.sreprezentovansboromkonenchhodntLabel_8 = uilabel(app.GridLayout39_29);
            app.sreprezentovansboromkonenchhodntLabel_8.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_8.Text = '$$T=\left(2^n -1\right)\cdot T_T$$';
            app.KomplexO2Panel_8 = uipanel(app.GridLayout38_8);
            app.KomplexO2Panel_8.Title = 'Otázka č.2';
            app.GridLayout39_30 = uigridlayout(app.KomplexO2Panel_8);
            app.ACheckBox_30 = uicheckbox(app.GridLayout39_30);
            app.ACheckBox_30.Text = 'A)';
            app.BCheckBox_30 = uicheckbox(app.GridLayout39_30);
            app.BCheckBox_30.Text = 'B)';
            app.CCheckBox_30 = uicheckbox(app.GridLayout39_30);
            app.CCheckBox_30.Text = 'C)';
            app.DCheckBox_30 = uicheckbox(app.GridLayout39_30);
            app.DCheckBox_30.Text = 'D)';
            app.AktvarmautokorelanfunkciapseudonhodnejpostupnostiLabel = uilabel(app.GridLayout39_30);
            app.AktvarmautokorelanfunkciapseudonhodnejpostupnostiLabel.Interpreter = 'latex';
            app.AktvarmautokorelanfunkciapseudonhodnejpostupnostiLabel.Text = 'Aký tvar má autokorelačná funkcia pseudonáhodnej postupnosti? ';
            app.trojuholnkovLabel_3 = uilabel(app.GridLayout39_30);
            app.trojuholnkovLabel_3.Interpreter = 'latex';
            app.trojuholnkovLabel_3.Text = 'trojuholníkový';
            app.obdnikovLabel_3 = uilabel(app.GridLayout39_30);
            app.obdnikovLabel_3.Interpreter = 'latex';
            app.obdnikovLabel_3.Text = 'obdĺžnikový';
            app.tvorcovLabel_3 = uilabel(app.GridLayout39_30);
            app.tvorcovLabel_3.Interpreter = 'tex';
            app.tvorcovLabel_3.Text = 'štvorcový';
            app.exponencilnyLabel_3 = uilabel(app.GridLayout39_30);
            app.exponencilnyLabel_3.Interpreter = 'latex';
            app.exponencilnyLabel_3.Text = 'exponenciálny';
            app.KomplexO3Panel_8 = uipanel(app.GridLayout38_8);
            app.KomplexO3Panel_8.Title = 'Otázka č.3';
            app.GridLayout39_31 = uigridlayout(app.KomplexO3Panel_8);
            app.ACheckBox_31 = uicheckbox(app.GridLayout39_31);
            app.ACheckBox_31.Text = 'A)';
            app.BCheckBox_31 = uicheckbox(app.GridLayout39_31);
            app.BCheckBox_31.Text = 'B)';
            app.CCheckBox_31 = uicheckbox(app.GridLayout39_31);
            app.CCheckBox_31.Text = 'C)';
            app.DCheckBox_31 = uicheckbox(app.GridLayout39_31);
            app.DCheckBox_31.Text = 'D)';
            app.BERBitErrorRatejemonvypotaakoLabel = uilabel(app.GridLayout39_31);
            app.BERBitErrorRatejemonvypotaakoLabel.Interpreter = 'latex';
            app.BERBitErrorRatejemonvypotaakoLabel.Text = 'BER (Bit Error Rate) je možné vypočítať ako:';
            app.fracpoetchybneprijatchbitovpoetcelkovoprijatchbitovLabel = uilabel(app.GridLayout39_31);
            app.fracpoetchybneprijatchbitovpoetcelkovoprijatchbitovLabel.Interpreter = 'latex';
            app.fracpoetchybneprijatchbitovpoetcelkovoprijatchbitovLabel.Text = '$$\frac{počet\;chybne\;prijatých\;bitov}{počet\;celkovo\;prijatých\;bitov}$$';
            app.fracpoetcelkovoprijatchbitovpoetchybneprijatchbitovLabel = uilabel(app.GridLayout39_31);
            app.fracpoetcelkovoprijatchbitovpoetchybneprijatchbitovLabel.Interpreter = 'latex';
            app.fracpoetcelkovoprijatchbitovpoetchybneprijatchbitovLabel.Text = '$$\frac{počet\;celkovo\;prijatých\;bitov}{počet\;chybne\;prijatých\;bitov}$$';
            app.fracdkapseudonhodnejpostupnostipoetchybneprijatchbitovLabel = uilabel(app.GridLayout39_31);
            app.fracdkapseudonhodnejpostupnostipoetchybneprijatchbitovLabel.Interpreter = 'latex';
            app.fracdkapseudonhodnejpostupnostipoetchybneprijatchbitovLabel.Text = '$$\frac{dĺžka\;pseudonáhodnej\;postupnosti}{počet\;chybne\;prijatých\;bitov}$$';
            app.sum_n0N1xleftnrightemathrmjknLabel_3 = uilabel(app.GridLayout39_31);
            app.sum_n0N1xleftnrightemathrmjknLabel_3.Interpreter = 'latex';
            app.sum_n0N1xleftnrightemathrmjknLabel_3.Text = '$$\frac{počet\;celkovo\;prijatých\;bitov}{dĺžka\;pseudonáhodnej\;postupnosti}$$';
            app.KomplexO4Panel_8 = uipanel(app.GridLayout38_8);
            app.KomplexO4Panel_8.Title = 'Otázka č.4';
            app.GridLayout39_32 = uigridlayout(app.KomplexO4Panel_8);
            app.ACheckBox_32 = uicheckbox(app.GridLayout39_32);
            app.ACheckBox_32.Text = 'A)';
            app.BCheckBox_32 = uicheckbox(app.GridLayout39_32);
            app.BCheckBox_32.Text = 'B)';
            app.CCheckBox_32 = uicheckbox(app.GridLayout39_32);
            app.CCheckBox_32.Text = 'C)';
            app.DCheckBox_32 = uicheckbox(app.GridLayout39_32);
            app.DCheckBox_32.Text = 'D)';
            app.SptnovzobnfunkciagenertoraPNpostupnostijetvorenLabel = uilabel(app.GridLayout39_32);
            app.SptnovzobnfunkciagenertoraPNpostupnostijetvorenLabel.Interpreter = 'latex';
            app.SptnovzobnfunkciagenertoraPNpostupnostijetvorenLabel.Text = 'Spätnoväzobná funkcia generátora PN postupnosti je tvorená:';
            app.operciouANDLabel_3 = uilabel(app.GridLayout39_32);
            app.operciouANDLabel_3.Interpreter = 'latex';
            app.operciouANDLabel_3.Text = 'operáciou AND';
            app.operciouORLabel_3 = uilabel(app.GridLayout39_32);
            app.operciouORLabel_3.Interpreter = 'latex';
            app.operciouORLabel_3.Text = 'operáciou OR';
            app.operciouNOTLabel_3 = uilabel(app.GridLayout39_32);
            app.operciouNOTLabel_3.Interpreter = 'latex';
            app.operciouNOTLabel_3.Text = 'operáciou NOT';
            app.operciouXORLabel_3 = uilabel(app.GridLayout39_32);
            app.operciouXORLabel_3.Interpreter = 'latex';
            app.operciouXORLabel_3.Text = 'operáciou XOR';
            app.KomplRestartButton_8 = uibutton(app.GridLayout38_8, 'push');
            app.KomplRestartButton_8.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_8Pushed, true);
            app.KomplRestartButton_8.Text = 'Restart';
            app.KomplSubmitButton_8 = uibutton(app.GridLayout38_8, 'push');
            app.KomplSubmitButton_8.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_8Pushed, true);
            app.KomplSubmitButton_8.Text = 'Submit';
            app.LinkoveKodyMainTab = uitab(app.RootTabGroup);
            app.LinkoveKodyMainTab.Title = 'Linkové kódy';
            app.GridLayout30_2 = uigridlayout(app.LinkoveKodyMainTab);
            app.PRSTabGroup_2 = uitabgroup(app.GridLayout30_2);
            app.PRSTeoria_2 = uitab(app.PRSTabGroup_2);
            app.PRSTeoria_2.Title = 'Teória';
            app.GridLayout31_2 = uigridlayout(app.PRSTeoria_2);
            app.HTML6_2 = uihtml(app.GridLayout31_2);
            app.HTML6_2.HTMLSource = 'kapitoly/linkove_kody/html/teoria.html';
            app.UkkyLinkovchkdovTab = uitab(app.PRSTabGroup_2);
            app.UkkyLinkovchkdovTab.Title = 'Ukážky Linkových kódov';
            app.GridLayout36 = uigridlayout(app.UkkyLinkovchkdovTab);
            app.LinkoveKodyUkazkyUIAxes = uiaxes(app.GridLayout36);
            title(app.LinkoveKodyUkazkyUIAxes, 'Title')
            xlabel(app.LinkoveKodyUkazkyUIAxes, 't')
            ylabel(app.LinkoveKodyUkazkyUIAxes, 'A')
            zlabel(app.LinkoveKodyUkazkyUIAxes, 'Z')
            app.LinkoveKodyUkazkyUIAxes.YLimitMethod = 'padded';
            app.ZadajtebitovpostupnosEditFieldLabel = uilabel(app.GridLayout36);
            app.ZadajtebitovpostupnosEditFieldLabel.Text = 'Zadajte bitovú postupnosť: ';
            app.LinkoveKodyBitovePoleEditField = uieditfield(app.GridLayout36, 'text');
            app.LinkoveKodyBitovePoleEditField.CharacterLimits = [0 20];
            app.LinkoveKodyBitovePoleEditField.InputType = 'digits';
            app.LinkoveKodyBitovePoleEditField.Value = '101101010111';
            app.UP_NRZButton = uibutton(app.GridLayout36, 'push');
            app.UP_NRZButton.ButtonPushedFcn = createCallbackFcn(app, @UP_NRZButtonPushed, true);
            app.UP_NRZButton.Text = 'UP NRZ';
            app.BP_NRZButton = uibutton(app.GridLayout36, 'push');
            app.BP_NRZButton.ButtonPushedFcn = createCallbackFcn(app, @BP_NRZButtonPushed, true);
            app.BP_NRZButton.Text = 'BP NRZ';
            app.UP_RZButton = uibutton(app.GridLayout36, 'push');
            app.UP_RZButton.ButtonPushedFcn = createCallbackFcn(app, @UP_RZButtonPushed, true);
            app.UP_RZButton.Text = 'UP RZ';
            app.BP_RZButton = uibutton(app.GridLayout36, 'push');
            app.BP_RZButton.ButtonPushedFcn = createCallbackFcn(app, @BP_RZButtonPushed, true);
            app.BP_RZButton.Text = 'BP RZ';
            app.AMI_NRZButton = uibutton(app.GridLayout36, 'push');
            app.AMI_NRZButton.ButtonPushedFcn = createCallbackFcn(app, @AMI_NRZButtonPushed, true);
            app.AMI_NRZButton.Text = 'AMI NRZ';
            app.AMI_RZButton = uibutton(app.GridLayout36, 'push');
            app.AMI_RZButton.ButtonPushedFcn = createCallbackFcn(app, @AMI_RZButtonPushed, true);
            app.AMI_RZButton.Text = 'AMI RZ';
            app.ManchesterButton = uibutton(app.GridLayout36, 'push');
            app.ManchesterButton.ButtonPushedFcn = createCallbackFcn(app, @ManchesterButtonPushed, true);
            app.ManchesterButton.Text = 'Manchester';
            app.LinkoveKodyChybLabel = uilabel(app.GridLayout36);
            app.LinkoveKodyChybLabel.Visible = 'off';
            app.LinkoveKodyChybLabel.Text = 'Chyba zle zadadná postupnosť';
            app.TeoreticktestTab_9 = uitab(app.PRSTabGroup_2);
            app.TeoreticktestTab_9.Title = 'Teoretický test';
            app.GridLayout38_9 = uigridlayout(app.TeoreticktestTab_9);
            app.KomplexO1Panel_9 = uipanel(app.GridLayout38_9);
            app.KomplexO1Panel_9.Title = 'Otázka č.1';
            app.GridLayout39_33 = uigridlayout(app.KomplexO1Panel_9);
            app.ACheckBox_33 = uicheckbox(app.GridLayout39_33);
            app.ACheckBox_33.Text = 'A)';
            app.BCheckBox_33 = uicheckbox(app.GridLayout39_33);
            app.BCheckBox_33.Text = 'B)';
            app.CCheckBox_33 = uicheckbox(app.GridLayout39_33);
            app.CCheckBox_33.Text = 'C)';
            app.DCheckBox_33 = uicheckbox(app.GridLayout39_33);
            app.DCheckBox_33.Text = 'D)';
            app.SkratkaNRZcharakterizujelinkovkdyLabel = uilabel(app.GridLayout39_33);
            app.SkratkaNRZcharakterizujelinkovkdyLabel.Interpreter = 'latex';
            app.SkratkaNRZcharakterizujelinkovkdyLabel.Text = 'Skratka NRZ charakterizuje linkové kódy:';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_9 = uilabel(app.GridLayout39_33);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_9.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_9.Text = 's návratom k nule';
            app.preichopissavyuvasadtatistickchparametrovLabel_9 = uilabel(app.GridLayout39_33);
            app.preichopissavyuvasadtatistickchparametrovLabel_9.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_9.Text = 'bez návratu k nule';
            app.vasenadobdajnekonenpoethodntLabel_10 = uilabel(app.GridLayout39_33);
            app.vasenadobdajnekonenpoethodntLabel_10.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_10.Text = 'úrovne všetkých signálových prvkov majú rovnakú polaritu';
            app.sreprezentovansboromkonenchhodntLabel_9 = uilabel(app.GridLayout39_33);
            app.sreprezentovansboromkonenchhodntLabel_9.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_9.Text = 'úrovne signálových prvkov majú odlišnú polaritu';
            app.KomplexO2Panel_9 = uipanel(app.GridLayout38_9);
            app.KomplexO2Panel_9.Title = 'Otázka č.2';
            app.GridLayout39_34 = uigridlayout(app.KomplexO2Panel_9);
            app.ACheckBox_34 = uicheckbox(app.GridLayout39_34);
            app.ACheckBox_34.Text = 'A)';
            app.BCheckBox_34 = uicheckbox(app.GridLayout39_34);
            app.BCheckBox_34.Text = 'B)';
            app.CCheckBox_34 = uicheckbox(app.GridLayout39_34);
            app.CCheckBox_34.Text = 'C)';
            app.DCheckBox_34 = uicheckbox(app.GridLayout39_34);
            app.DCheckBox_34.Text = 'D)';
            app.ojepravdaounipolrnchlinkovchkdochLabel = uilabel(app.GridLayout39_34);
            app.ojepravdaounipolrnchlinkovchkdochLabel.Interpreter = 'latex';
            app.ojepravdaounipolrnchlinkovchkdochLabel.Text = 'Čo je pravda o unipolárných linkových kódoch?';
            app.rovnevetkchsignlovchprvkovmajrovnakpolarituLabel = uilabel(app.GridLayout39_34);
            app.rovnevetkchsignlovchprvkovmajrovnakpolarituLabel.Interpreter = 'latex';
            app.rovnevetkchsignlovchprvkovmajrovnakpolarituLabel.Text = 'úrovne všetkých signálových prvkov majú rovnakú polaritu';
            app.rovnesignlovchprvkovmajodlinpolarituLabel = uilabel(app.GridLayout39_34);
            app.rovnesignlovchprvkovmajodlinpolarituLabel.Interpreter = 'latex';
            app.rovnesignlovchprvkovmajodlinpolarituLabel.Text = 'úrovne signálových prvkov majú odlišnú polaritu';
            app.priraujdvojkovmsymbolomtrojkovsignlLabel = uilabel(app.GridLayout39_34);
            app.priraujdvojkovmsymbolomtrojkovsignlLabel.Interpreter = 'tex';
            app.priraujdvojkovmsymbolomtrojkovsignlLabel.Text = 'priraďujú dvojkovým symbolom trojkový signál';
            app.rovnevetkchsignlovchprvkovnemajrovnakpolarituLabel = uilabel(app.GridLayout39_34);
            app.rovnevetkchsignlovchprvkovnemajrovnakpolarituLabel.Interpreter = 'latex';
            app.rovnevetkchsignlovchprvkovnemajrovnakpolarituLabel.Text = 'úrovne všetkých signálových prvkov nemajú rovnakú polaritu';
            app.KomplexO3Panel_9 = uipanel(app.GridLayout38_9);
            app.KomplexO3Panel_9.Title = 'Otázka č.3';
            app.GridLayout39_35 = uigridlayout(app.KomplexO3Panel_9);
            app.ACheckBox_35 = uicheckbox(app.GridLayout39_35);
            app.ACheckBox_35.Text = 'A)';
            app.BCheckBox_35 = uicheckbox(app.GridLayout39_35);
            app.BCheckBox_35.Text = 'B)';
            app.CCheckBox_35 = uicheckbox(app.GridLayout39_35);
            app.CCheckBox_35.Text = 'C)';
            app.DCheckBox_35 = uicheckbox(app.GridLayout39_35);
            app.DCheckBox_35.Text = 'D)';
            app.PodporasynchronizcievlinkovchkdochznamenLabel = uilabel(app.GridLayout39_35);
            app.PodporasynchronizcievlinkovchkdochznamenLabel.Interpreter = 'latex';
            app.PodporasynchronizcievlinkovchkdochznamenLabel.Text = 'Podpora synchronizácie v linkových kódoch znamená:';
            app.monosodvodisynchronizansignlpriamozlinkovhokduLabel = uilabel(app.GridLayout39_35);
            app.monosodvodisynchronizansignlpriamozlinkovhokduLabel.Interpreter = 'latex';
            app.monosodvodisynchronizansignlpriamozlinkovhokduLabel.Text = 'možnosť odvodiť synchronizačný signál priamo z linkového kódu';
            app.monosodvodisynchronizansignlzfrekvennhospektraLabel = uilabel(app.GridLayout39_35);
            app.monosodvodisynchronizansignlzfrekvennhospektraLabel.Interpreter = 'latex';
            app.monosodvodisynchronizansignlzfrekvennhospektraLabel.Text = 'možnosť odvodiť synchronizačný signál z frekvenčného spektra';
            app.niejemonodvodisynchronizansignlLabel = uilabel(app.GridLayout39_35);
            app.niejemonodvodisynchronizansignlLabel.Interpreter = 'latex';
            app.niejemonodvodisynchronizansignlLabel.Text = 'nie je možné odvodiť synchronizačný signál';
            app.sum_n0N1xleftnrightemathrmjknLabel_4 = uilabel(app.GridLayout39_35);
            app.sum_n0N1xleftnrightemathrmjknLabel_4.Interpreter = 'latex';
            app.sum_n0N1xleftnrightemathrmjknLabel_4.Text = 'možnosť odvodiť synchronizačný signál z polarity signálu';
            app.KomplexO4Panel_9 = uipanel(app.GridLayout38_9);
            app.KomplexO4Panel_9.Title = 'Otázka č.4';
            app.GridLayout39_36 = uigridlayout(app.KomplexO4Panel_9);
            app.ACheckBox_36 = uicheckbox(app.GridLayout39_36);
            app.ACheckBox_36.Text = 'A)';
            app.BCheckBox_36 = uicheckbox(app.GridLayout39_36);
            app.BCheckBox_36.Text = 'B)';
            app.CCheckBox_36 = uicheckbox(app.GridLayout39_36);
            app.CCheckBox_36.Text = 'C)';
            app.DCheckBox_36 = uicheckbox(app.GridLayout39_36);
            app.DCheckBox_36.Text = 'D)';
            app.VetkyvariantyfzovhokdovaniasavyznaujtmeLabel = uilabel(app.GridLayout39_36);
            app.VetkyvariantyfzovhokdovaniasavyznaujtmeLabel.Interpreter = 'latex';
            app.VetkyvariantyfzovhokdovaniasavyznaujtmeLabel.Text = 'Všetky varianty fázového kódovania sa vyznačujú tým, že:';
            app.zmenarovnevdynastvanakoncibitovhointervaluLabel = uilabel(app.GridLayout39_36);
            app.zmenarovnevdynastvanakoncibitovhointervaluLabel.Interpreter = 'latex';
            app.zmenarovnevdynastvanakoncibitovhointervaluLabel.Text = 'zmena úrovne vždy nastáva na konci bitového intervalu';
            app.nedochdzakzmenerovneLabel = uilabel(app.GridLayout39_36);
            app.nedochdzakzmenerovneLabel.Interpreter = 'latex';
            app.nedochdzakzmenerovneLabel.Text = 'nedochádza k zmene úrovne';
            app.zmenarovnevdynastvaprizmenefzysignluLabel = uilabel(app.GridLayout39_36);
            app.zmenarovnevdynastvaprizmenefzysignluLabel.Interpreter = 'latex';
            app.zmenarovnevdynastvaprizmenefzysignluLabel.Text = 'zmena úrovne vždy nastáva pri zmene fázy signálu';
            app.zmenarovnevdynastvanazaiatkubitovhointervaluLabel = uilabel(app.GridLayout39_36);
            app.zmenarovnevdynastvanazaiatkubitovhointervaluLabel.Interpreter = 'latex';
            app.zmenarovnevdynastvanazaiatkubitovhointervaluLabel.Text = 'zmena úrovne vždy nastáva na začiatku bitového intervalu';
            app.KomplRestartButton_9 = uibutton(app.GridLayout38_9, 'push');
            app.KomplRestartButton_9.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_9Pushed, true);
            app.KomplRestartButton_9.Text = 'Restart';
            app.KomplSubmitButton_9 = uibutton(app.GridLayout38_9, 'push');
            app.KomplSubmitButton_9.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_9Pushed, true);
            app.KomplSubmitButton_9.Text = 'Submit';
            app.ShanonHartleyMainTab = uitab(app.RootTabGroup);
            app.ShanonHartleyMainTab.Title = 'Shannon–Hartley teorém ';
            app.GridLayout30_3 = uigridlayout(app.ShanonHartleyMainTab);
            app.PRSTabGroup_3 = uitabgroup(app.GridLayout30_3);
            app.PRSTeoria_3 = uitab(app.PRSTabGroup_3);
            app.PRSTeoria_3.Title = 'Teória';
            app.GridLayout31_3 = uigridlayout(app.PRSTeoria_3);
            app.HTML6_3 = uihtml(app.GridLayout31_3);
            app.HTML6_3.HTMLSource = 'kapitoly/shannon/html/teoria.html';
            app.TeoreticktestTab_10 = uitab(app.PRSTabGroup_3);
            app.TeoreticktestTab_10.Title = 'Teoretický test';
            app.GridLayout38_10 = uigridlayout(app.TeoreticktestTab_10);
            app.KomplexO1Panel_10 = uipanel(app.GridLayout38_10);
            app.KomplexO1Panel_10.Title = 'Otázka č.1';
            app.GridLayout39_37 = uigridlayout(app.KomplexO1Panel_10);
            app.ACheckBox_37 = uicheckbox(app.GridLayout39_37);
            app.ACheckBox_37.Text = 'A)';
            app.BCheckBox_37 = uicheckbox(app.GridLayout39_37);
            app.BCheckBox_37.Text = 'B)';
            app.CCheckBox_37 = uicheckbox(app.GridLayout39_37);
            app.CCheckBox_37.Text = 'C)';
            app.DCheckBox_37 = uicheckbox(app.GridLayout39_37);
            app.DCheckBox_37.Text = 'D)';
            app.AkjesprvnyzapisvzahuprevpoetkapacitykanlaLabel = uilabel(app.GridLayout39_37);
            app.AkjesprvnyzapisvzahuprevpoetkapacitykanlaLabel.Interpreter = 'latex';
            app.AkjesprvnyzapisvzahuprevpoetkapacitykanlaLabel.Text = 'Aký je správny zapis vzťahu pre výpočet kapacity kanála?';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_10 = uilabel(app.GridLayout39_37);
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_10.Interpreter = 'latex';
            app.jevyjadrenkonenovpostupnosDiracovhoimpulzuLabel_10.Text = '$$C=2B\cdot \log_2 \left(1+\mathrm{SNR}\right)$$';
            app.preichopissavyuvasadtatistickchparametrovLabel_10 = uilabel(app.GridLayout39_37);
            app.preichopissavyuvasadtatistickchparametrovLabel_10.Interpreter = 'latex';
            app.preichopissavyuvasadtatistickchparametrovLabel_10.Text = '$$C=B\cdot \log_2 \left(1+\mathrm{SNR}\right)$$';
            app.vasenadobdajnekonenpoethodntLabel_11 = uilabel(app.GridLayout39_37);
            app.vasenadobdajnekonenpoethodntLabel_11.Interpreter = 'latex';
            app.vasenadobdajnekonenpoethodntLabel_11.Text = '$$C=B\cdot \log_{10} \left(1+\mathrm{SNR}\right)$$';
            app.sreprezentovansboromkonenchhodntLabel_10 = uilabel(app.GridLayout39_37);
            app.sreprezentovansboromkonenchhodntLabel_10.Interpreter = 'latex';
            app.sreprezentovansboromkonenchhodntLabel_10.Text = '$$C={SNR\cdot \log }_{10} \left(1+B\right)$$';
            app.KomplexO2Panel_10 = uipanel(app.GridLayout38_10);
            app.KomplexO2Panel_10.Title = 'Otázka č.2';
            app.GridLayout39_38 = uigridlayout(app.KomplexO2Panel_10);
            app.ACheckBox_38 = uicheckbox(app.GridLayout39_38);
            app.ACheckBox_38.Text = 'A)';
            app.BCheckBox_38 = uicheckbox(app.GridLayout39_38);
            app.BCheckBox_38.Text = 'B)';
            app.CCheckBox_38 = uicheckbox(app.GridLayout39_38);
            app.CCheckBox_38.Text = 'C)';
            app.DCheckBox_38 = uicheckbox(app.GridLayout39_38);
            app.DCheckBox_38.Text = 'D)';
            app.AkbudeSNRvdecibelochakvkonsignalje30dBmavkonumuje40dBmLabel = uilabel(app.GridLayout39_38);
            app.AkbudeSNRvdecibelochakvkonsignalje30dBmavkonumuje40dBmLabel.Interpreter = 'latex';
            app.AkbudeSNRvdecibelochakvkonsignalje30dBmavkonumuje40dBmLabel.Text = 'Aké bude SNR (v decibeloch) ak výkon signalú je 30dBm a výkon šumu je -40dBm?';
            app.dBLabel = uilabel(app.GridLayout39_38);
            app.dBLabel.Interpreter = 'latex';
            app.dBLabel.Text = '-70dB';
            app.dBmLabel = uilabel(app.GridLayout39_38);
            app.dBmLabel.Interpreter = 'latex';
            app.dBmLabel.Text = '70dBm';
            app.dBLabel_2 = uilabel(app.GridLayout39_38);
            app.dBLabel_2.Interpreter = 'tex';
            app.dBLabel_2.Text = '70dB';
            app.dBLabel_3 = uilabel(app.GridLayout39_38);
            app.dBLabel_3.Interpreter = 'latex';
            app.dBLabel_3.Text = '-10dB';
            app.KomplexO3Panel_10 = uipanel(app.GridLayout38_10);
            app.KomplexO3Panel_10.Title = 'Otázka č.3';
            app.GridLayout39_39 = uigridlayout(app.KomplexO3Panel_10);
            app.ACheckBox_39 = uicheckbox(app.GridLayout39_39);
            app.ACheckBox_39.Text = 'A)';
            app.BCheckBox_39 = uicheckbox(app.GridLayout39_39);
            app.BCheckBox_39.Text = 'B)';
            app.CCheckBox_39 = uicheckbox(app.GridLayout39_39);
            app.CCheckBox_39.Text = 'C)';
            app.DCheckBox_39 = uicheckbox(app.GridLayout39_39);
            app.DCheckBox_39.Text = 'D)';
            app.AkbudekapacitakanlaakSNR30dBaB50kHzLabel = uilabel(app.GridLayout39_39);
            app.AkbudekapacitakanlaakSNR30dBaB50kHzLabel.Interpreter = 'latex';
            app.AkbudekapacitakanlaakSNR30dBaB50kHzLabel.Text = 'Aká bude kapacita kanála ak SNR=30dB a B=50kHz? ';
            app.bitsLabel = uilabel(app.GridLayout39_39);
            app.bitsLabel.Interpreter = 'latex';
            app.bitsLabel.Text = '498 361 bit/s';
            app.bitsLabel_2 = uilabel(app.GridLayout39_39);
            app.bitsLabel_2.Interpreter = 'latex';
            app.bitsLabel_2.Text = '332 910 bit/s';
            app.bitsLabel_3 = uilabel(app.GridLayout39_39);
            app.bitsLabel_3.Interpreter = 'latex';
            app.bitsLabel_3.Text = '49 836 bit/s';
            app.sum_n0N1xleftnrightemathrmjknLabel_5 = uilabel(app.GridLayout39_39);
            app.sum_n0N1xleftnrightemathrmjknLabel_5.Interpreter = 'latex';
            app.sum_n0N1xleftnrightemathrmjknLabel_5.Text = '33 291 bit/s';
            app.KomplexO4Panel_10 = uipanel(app.GridLayout38_10);
            app.KomplexO4Panel_10.Title = 'Otázka č.4';
            app.GridLayout39_40 = uigridlayout(app.KomplexO4Panel_10);
            app.ACheckBox_40 = uicheckbox(app.GridLayout39_40);
            app.ACheckBox_40.Text = 'A)';
            app.BCheckBox_40 = uicheckbox(app.GridLayout39_40);
            app.BCheckBox_40.Text = 'B)';
            app.CCheckBox_40 = uicheckbox(app.GridLayout39_40);
            app.CCheckBox_40.Text = 'C)';
            app.DCheckBox_40 = uicheckbox(app.GridLayout39_40);
            app.DCheckBox_40.Text = 'D)';
            app.Akbudekapacitakanlaakvkonsignluje0dBWvkonumuje10dBmaB10kHzLabel = uilabel(app.GridLayout39_40);
            app.Akbudekapacitakanlaakvkonsignluje0dBWvkonumuje10dBmaB10kHzLabel.Interpreter = 'latex';
            app.Akbudekapacitakanlaakvkonsignluje0dBWvkonumuje10dBmaB10kHzLabel.Text = 'Aká bude kapacita kanála ak výkon signálu je 0dBW, výkon šumu je -10dBm a B=10kHz?';
            app.bitsLabel_4 = uilabel(app.GridLayout39_40);
            app.bitsLabel_4.Interpreter = 'latex';
            app.bitsLabel_4.Text = '13 287 bit/s';
            app.bitsLabel_5 = uilabel(app.GridLayout39_40);
            app.bitsLabel_5.Interpreter = 'latex';
            app.bitsLabel_5.Text = '132 878 bit/s';
            app.bitsLabel_6 = uilabel(app.GridLayout39_40);
            app.bitsLabel_6.Interpreter = 'latex';
            app.bitsLabel_6.Text = '996 578 bit/s';
            app.bitsLabel_7 = uilabel(app.GridLayout39_40);
            app.bitsLabel_7.Interpreter = 'latex';
            app.bitsLabel_7.Text = '99 657 bit/s';
            app.KomplRestartButton_10 = uibutton(app.GridLayout38_10, 'push');
            app.KomplRestartButton_10.ButtonPushedFcn = createCallbackFcn(app, @KomplRestartButton_10Pushed, true);
            app.KomplRestartButton_10.Text = 'Restart';
            app.KomplSubmitButton_10 = uibutton(app.GridLayout38_10, 'push');
            app.KomplSubmitButton_10.ButtonPushedFcn = createCallbackFcn(app, @KomplSubmitButton_10Pushed, true);
            app.KomplSubmitButton_10.Text = 'Submit';
            app.ShannonPrikladTab = uitab(app.PRSTabGroup_3);
            app.ShannonPrikladTab.Title = 'Riešený príklad';
            app.GridLayout16_9 = uigridlayout(app.ShannonPrikladTab);
            app.KonvKorZadanieUlohyHTMLKonv_2 = uihtml(app.GridLayout16_9);
            app.KonvKorZadanieUlohyHTMLKonv_2.HTMLSource = 'kapitoly/shannon/html/Rieseny.html';
            app.ShannonPanelKonzolaRieseny = uipanel(app.GridLayout16_9);
            app.ShannonPanelKonzolaRieseny.Title = 'Konzola';
            app.GridLayout17_11 = uigridlayout(app.ShannonPanelKonzolaRieseny);
            app.ShannonKonzolaTextAreaRieseny = uitextarea(app.GridLayout17_11);
            app.ShannonKonzolaTextAreaRieseny.Editable = 'off';
            app.ShannonCodeAreaHTMLRieseny = uihtml(app.GridLayout16_9);
            app.ShannonCodeAreaHTMLRieseny.HTMLSource = 'codeArea_HTML/CodeArea_Shannon_Rieseny.html';
            app.MATLABCODEEDITORLabel_16 = uilabel(app.GridLayout16_9);
            app.MATLABCODEEDITORLabel_16.Text = 'MATLAB CODE EDITOR';
            app.ShannonRunCodeUlohaButtonRiesena = uibutton(app.GridLayout16_9, 'push');
            app.ShannonRunCodeUlohaButtonRiesena.ButtonPushedFcn = createCallbackFcn(app, @ShannonRunCodeUlohaButtonRiesenaPushed, true);
            app.ShannonRunCodeUlohaButtonRiesena.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.ShannonRunCodeUlohaButtonRiesena.Text = '';
            app.CTRLSPACEukepremennafunkcieLabel_14 = uilabel(app.GridLayout16_9);
            app.CTRLSPACEukepremennafunkcieLabel_14.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.Hyperlink2_9 = uihyperlink(app.GridLayout16_9);
            app.Hyperlink2_9.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_9Clicked, true);
            app.Hyperlink2_9.Text = 'HELP';
            app.KonvKorPrikladKovnTab_4 = uitab(app.PRSTabGroup_3);
            app.KonvKorPrikladKovnTab_4.Title = 'Príklad';
            app.GridLayout16_10 = uigridlayout(app.KonvKorPrikladKovnTab_4);
            app.KonvKorZadanieUlohyHTMLKonv_3 = uihtml(app.GridLayout16_10);
            app.KonvKorZadanieUlohyHTMLKonv_3.HTMLSource = 'kapitoly/shannon/html/Nerieseny.html';
            app.KonvKorPanelKonzolaKonv_3 = uipanel(app.GridLayout16_10);
            app.KonvKorPanelKonzolaKonv_3.Title = 'Konzola';
            app.GridLayout17_12 = uigridlayout(app.KonvKorPanelKonzolaKonv_3);
            app.ShannonKonzolaTextAreaNerieseny = uitextarea(app.GridLayout17_12);
            app.ShannonKonzolaTextAreaNerieseny.Editable = 'off';
            app.ShannonCodeAreaHTMLNerieseny = uihtml(app.GridLayout16_10);
            app.ShannonCodeAreaHTMLNerieseny.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.MATLABCODEEDITORLabel_17 = uilabel(app.GridLayout16_10);
            app.MATLABCODEEDITORLabel_17.Text = 'MATLAB CODE EDITOR';
            app.KonvKorRunCodeUlohaButtonKonv_3 = uibutton(app.GridLayout16_10, 'push');
            app.KonvKorRunCodeUlohaButtonKonv_3.ButtonPushedFcn = createCallbackFcn(app, @KonvKorRunCodeUlohaButtonKonv_3Pushed, true);
            app.KonvKorRunCodeUlohaButtonKonv_3.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.KonvKorRunCodeUlohaButtonKonv_3.Text = '';
            app.KonvKorShowAnswerButtonKonv_3 = uibutton(app.GridLayout16_10, 'push');
            app.KonvKorShowAnswerButtonKonv_3.ButtonPushedFcn = createCallbackFcn(app, @KonvKorShowAnswerButtonKonv_3Pushed, true);
            app.KonvKorShowAnswerButtonKonv_3.Text = 'Show answer';
            app.KonvKorRestartButtonKonv_3 = uibutton(app.GridLayout16_10, 'push');
            app.KonvKorRestartButtonKonv_3.ButtonPushedFcn = createCallbackFcn(app, @KonvKorRestartButtonKonv_3Pushed, true);
            app.KonvKorRestartButtonKonv_3.Text = 'Restart';
            app.CTRLSPACEukepremennafunkcieLabel_15 = uilabel(app.GridLayout16_10);
            app.CTRLSPACEukepremennafunkcieLabel_15.Text = '*CTRL+SPACE ukáže premenné a funkcie';
            app.Hyperlink2_10 = uihyperlink(app.GridLayout16_10);
            app.Hyperlink2_10.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_10Clicked, true);
            app.Hyperlink2_10.Text = 'HELP';
            app.FiltreMainTab = uitab(app.RootTabGroup);
            app.FiltreMainTab.Title = 'Doplnkové štúdium–frekvenčné filtre';
            app.GridLayout30_4 = uigridlayout(app.FiltreMainTab);
            app.PRSTabGroup_4 = uitabgroup(app.GridLayout30_4);
            app.PRSTeoria_4 = uitab(app.PRSTabGroup_4);
            app.PRSTeoria_4.Title = 'Teória';
            app.GridLayout31_4 = uigridlayout(app.PRSTeoria_4);
            app.HTML6_4 = uihtml(app.GridLayout31_4);
            app.HTML6_4.HTMLSource = 'kapitoly/filtre/teoria.pdf';
            app.KonvKorPrikladKovnTab_5 = uitab(app.PRSTabGroup_4);
            app.KonvKorPrikladKovnTab_5.Title = 'Úlohy';
            app.GridLayout16_12 = uigridlayout(app.KonvKorPrikladKovnTab_5);
            app.KonvKorZadanieUlohyHTMLKonv_5 = uihtml(app.GridLayout16_12);
            app.KonvKorZadanieUlohyHTMLKonv_5.HTMLSource = 'kapitoly/filtre/Zadania.htm';
            app.KonvKorPanelKonzolaKonv_4 = uipanel(app.GridLayout16_12);
            app.KonvKorPanelKonzolaKonv_4.Title = 'Konzola';
            app.GridLayout17_14 = uigridlayout(app.KonvKorPanelKonzolaKonv_4);
            app.FiltreKonzolaTextArea = uitextarea(app.GridLayout17_14);
            app.FiltreKonzolaTextArea.Editable = 'off';
            app.FiltreCodeAreaHTML = uihtml(app.GridLayout16_12);
            app.FiltreCodeAreaHTML.HTMLSource = 'codeArea_HTML/CodeArea_Blank.html';
            app.MATLABCODEEDITORLabel_19 = uilabel(app.GridLayout16_12);
            app.MATLABCODEEDITORLabel_19.Text = 'MATLAB CODE EDITOR';
            app.KonvKorRunCodeUlohaButtonKonv_4 = uibutton(app.GridLayout16_12, 'push');
            app.KonvKorRunCodeUlohaButtonKonv_4.ButtonPushedFcn = createCallbackFcn(app, @KonvKorRunCodeUlohaButtonKonv_4Pushed, true);
            app.KonvKorRunCodeUlohaButtonKonv_4.Icon = fullfile(pathToMLAPP, 'kapitoly', 'RunButton.png');
            app.KonvKorRunCodeUlohaButtonKonv_4.Text = '';
            app.Hyperlink2_12 = uihyperlink(app.GridLayout16_12);
            app.Hyperlink2_12.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_12Clicked, true);
            app.Hyperlink2_12.Text = 'HELP';
            app.Hyperlink2_13 = uihyperlink(app.GridLayout16_12);
            app.Hyperlink2_13.HyperlinkClickedFcn = createCallbackFcn(app, @Hyperlink2_13Clicked, true);
            app.Hyperlink2_13.Text = 'RIEŠENIA ÚLOH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = USS_aplikacia

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end